classdef TheGreatUniter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GreatUniterUIFigure     matlab.ui.Figure
        StatusUITable           matlab.ui.control.Table
        testButton              matlab.ui.control.StateButton
        PlotPanel               matlab.ui.container.Panel
        PlotinnewwindowButton   matlab.ui.control.Button
        YDropDown               matlab.ui.control.DropDown
        YDropDownLabel          matlab.ui.control.Label
        XDropDown               matlab.ui.control.DropDown
        XDropDownLabel          matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        SelectDevicePanel       matlab.ui.container.Panel
        addButton               matlab.ui.control.Button
        LabelDevice             matlab.ui.control.Label
        DevicesTree             matlab.ui.container.Tree
        OPMRubinNode            matlab.ui.container.TreeNode
        OSAYokogawaNode         matlab.ui.container.TreeNode
        FPGANode                matlab.ui.container.TreeNode
        ATTENUATORnimyDAQNode   matlab.ui.container.TreeNode
        SWITCHOSAEDFANode       matlab.ui.container.TreeNode
        TheGreatUniterLabel     matlab.ui.control.Label
        OutputWindowPanel       matlab.ui.container.Panel
        savelogButton           matlab.ui.control.Button
        PercentLabel            matlab.ui.control.Label
        ProgressLabel           matlab.ui.control.Label
        WARNINGLaserinuseLabel  matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        clearButton             matlab.ui.control.Button
        OutputWindowLabel       matlab.ui.control.Label
        OutputTextArea          matlab.ui.control.TextArea
        SelectActionPanel       matlab.ui.container.Panel
        LabelTest               matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        Tree                    matlab.ui.container.Tree
        TestFPGAcomponentsNode  matlab.ui.container.TreeNode
        PhotodiodeNode          matlab.ui.container.TreeNode
        AttenuatorNode          matlab.ui.container.TreeNode
        TransceiverNode         matlab.ui.container.TreeNode
        ParameterNode           matlab.ui.container.TreeNode
        LoaddriverNode          matlab.ui.container.TreeNode
        LaserdiodeNode          matlab.ui.container.TreeNode
        TestEDFANode            matlab.ui.container.TreeNode
        CalibrateEDFANode       matlab.ui.container.TreeNode
        CustomscriptNode        matlab.ui.container.TreeNode
        SelectFolderPanel       matlab.ui.container.Panel
        SelectfolderforsavingfilesTextArea  matlab.ui.control.TextArea
        Label                   matlab.ui.control.Label
        SelectFolderButton      matlab.ui.control.Button
        UIAxesProgress2         matlab.ui.control.UIAxes
        UIAxesProgress          matlab.ui.control.UIAxes
    end

    properties
        folder
        %userText
        x%
        y%
    end
    properties
        ERROR_COLOR = [1.00,0.77,0.77];
        CUSTOM_COLOR = [0.96,0.96,1.00];
        DEFAULT_COLOR = [1.00,1.00,1.00];
    end
    properties %devices
        AddDevice_OPMrubin
        structDevice_OPMrubin
        AddDevice_OSAyokogawa
        AddDevice_FPGA
        AddDevice_SWITCHosaEdfa
        AddDevice_ATTENniMyDaq
    end
    properties %functions
        TestFPGA_Attenuator
        calledAppTestTransceiver
        calledAppTestPhotodiode
        calledAppTestLaserDiode
        calledAppTestLoadDriver
        calledAppTestOtherParameter
        calledAppAutoTestEDFA
        calledAppAutoCalibEDFA
        calledTestVD_minMeanMax
        calledAppTestCoeff
        calledAppCustomScript
    end
    methods (Access = public)
        function appendStructDevice_OPMrubin(app,appDevice,deviceName)
            app.structDevice_OPMrubin.(deviceName) = appDevice;
        end
        function removeStructDevice_OPMrubin(app,deviceName)
            if isfield(app.structDevice_OPMrubin,deviceName)
                app.structDevice_OPMrubin = rmfield(app.structDevice_OPMrubin,deviceName);
            end
        end
    end
    methods (Access = public)
        function clearOutput(app)
            app.OutputTextArea.Value = '';
        end
        function addStrOutput(app,str)
            oldOutput = app.OutputTextArea.Value;
            newOutput = strcat(datestr(now, 'HH:MM:SS'),': ',str);
            app.OutputTextArea.Value = [oldOutput; newOutput];
        end
%         function clearMainTable(app)
%             app.StatusUITable.Data = [];
%         end
%         function appendMainTable(app,newRow)
%             app.StatusUITable.Data = [app.StatusUITable.Data; newRow];
%         end
%         function setLabelsProgressAxes(app,title,xLabel,yLabel)
%             %title(app.UIAxesProgress,title);
%             xlabel(app.UIAxesProgress,xLabel);
%             ylabel(app.UIAxesProgress,yLabel);
% 
%         end
%         function addPointProgressAxes(app,x,y,color)
%             hold(app.UIAxesProgress,'on')
%             plot(app.UIAxesProgress,x,y,'.','MarkerSize',10,'Color',color);
%         end
%         function clearProgressAxes(app)
%             cla(app.UIAxesProgress);
%         end
%         function setLabelsProgressAxes2(app,title,xLabel,yLabel)
%             %title(app.UIAxesProgress2,title);
%             xlabel(app.UIAxesProgress2,xLabel);
%             ylabel(app.UIAxesProgress2,yLabel);
%         end
%         function addPointProgressAxes2(app,x,y,color)
%             hold(app.UIAxesProgress2,'on')
%             plot(app.UIAxesProgress2,x,y,'.','MarkerSize',10,'Color',color);
%         end
%         function clearProgressAxes2(app)
%             cla(app.UIAxesProgress2);
%         end


        %         function setLegendProgressAxes(app,legend)
        %             legend(app.UIAxesProgress,legend);
        %         end

%         function addPointProgressAxes(app,x,y,color)
%             hold(app.UIAxesProgress,'on')
%             plot(app.UIAxesProgress,x,y,'.','MarkerSize',10,'Color',color);
%         end

        function plotAxes(app, x, y)
            plot(app.UIAxes,x,y);
        end
        function holdAxes(app, mode)
            hold(app.UIAxes,mode);
        end
        function setLimsAxes(app,xLim,yLim)
            app.UIAxes.XLim = xLim;
            app.UIAxes.YLim = yLim;
        end
        function setLabelsAxes(app,title,xLabel,yLabel)
            app.UIAxes.Title.String = title;
            app.UIAxes.XLabel.String = xLabel;
            app.UIAxes.YLabel.String = yLabel;
        end
        function setLegend(app,cellArray)
            legend(app.UIAxes,cellArray);
        end
        function clearAxes(app)
            cla(app.UIAxes);
        end
        function enableWarningLaser(app)
            app.WARNINGLaserinuseLabel.FontColor = [1, 1, 1];
            app.WARNINGLaserinuseLabel.BackgroundColor = [1, 0, 0];
            app.Lamp.Color = [1, 0, 0];
        end
        function disableWarningLaser(app)
            app.WARNINGLaserinuseLabel.FontColor = [0.80, 0.80, 0.80];
            app.WARNINGLaserinuseLabel.BackgroundColor = 'none';
            app.Lamp.Color = [0.94,0.94,0.94];
        end
        function refreshProgress(app,newProgress)
            app.ProgressLabel.Text = num2str(newProgress);
        end
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','TheGreatUniter');
            app.SelectfolderforsavingfilesTextArea.Value = TheGreatUniter.FolderTextArea;
        end
        function saveFromAppToMemFile(app)
            TheGreatUniter.FolderTextArea = app.SelectfolderforsavingfilesTextArea.Value{1};
            save('TheGreatUniterMemory.mat','TheGreatUniter','-append');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.structDevice_OPMrubin = struct;
            app.AddDevice_FPGA = [];
            app.AddDevice_OSAyokogawa = [];
            app.AddDevice_ATTENniMyDaq = [];
            app.AddDevice_SWITCHosaEdfa = [];
            app.AddDevice_OPMrubin = [];
            loadFromMemFileToApp(app);
            app.folder = app.SelectfolderforsavingfilesTextArea.Value{1};
            run("startup.m");
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            dir = uigetdir(app.SelectfolderforsavingfilesTextArea.Value{1},'Select folder');
            if isequal(dir,0)
                output = '';
                app.SelectDevicePanel.Enable = 'off';
            else
                output = dir;
                app.SelectDevicePanel.Enable = 'on';
            end
            app.folder = output;
            app.SelectfolderforsavingfilesTextArea.Value = output;
            figure(app.GreatUniterUIFigure);
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            value = app.DevicesTree.SelectedNodes;
            if value == app.OPMRubinNode
                app.AddDevice_OPMrubin = appAddDevice_OPMrubin(app);
            elseif value == app.OSAYokogawaNode
                app.AddDevice_OSAyokogawa = appAddDevice_OSAyokogawa(app);
            elseif value == app.FPGANode
                app.AddDevice_FPGA = appAddDevice_FPGA(app);
            elseif value == app.ATTENUATORnimyDAQNode
                app.AddDevice_ATTENniMyDaq = appAddDevice_ATTENniMyDaq(app);
            elseif value == app.SWITCHOSAEDFANode
                app.AddDevice_SWITCHosaEdfa = appAddDevice_SWITCHosaEdfa(app);
            end
            app.SelectActionPanel.Enable = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            value = app.Tree.SelectedNodes;
            if value == app.AttenuatorNode
                app.TestFPGA_Attenuator = appTestFPGA_Attenuator(app);
            elseif value == app.TransceiverNode
                app.TestFPGA_Transceiver = appTestFPGA_Transceiver(app);
            elseif value == app.PhotodiodeNode
                app.TestFPGA_Photodiode = TestFPGA_Photodiode(app);
            elseif value == app.LaserdiodeNode
                app.calledAppTestLaserDiode = appTestLaserDiode(app);
            elseif value == app.LoaddriverNode
                app.calledAppTestLoadDriver = appTestLoadDriver(app);
            elseif value == app.ParameterNode
                app.calledAppTestOtherParameter = appTestOtherParameter(app);
            elseif value == app.CustomscriptNode
                app.calledAppCustomScript = appCustomScript(app);
            elseif value == app.AutotestEDFANode
                app.calledAppAutoTestEDFA = appAutoTestEDFA(app);
            elseif value == app.CalibrateEDFANode
                app.calledAppAutoCalibEDFA = appAutoCalibEDFA(app);
            elseif value == app.TestVDminmeanmaxNode
                app.calledTestVD_minMeanMax = TestVD_minMeanMax(app);
            elseif value == app.TestcoeffNode
                app.calledAppTestCoeff = appTestCoeff(app);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            clc;
            clearOutput(app);
            %clearMainTable(app);
            %clearProgressAxes(app);
            %clearProgressAxes2(app);
            clearAxes(app);
        end

        % Value changed function: testButton
        function testButtonValueChanged(app, event)
%             value = app.testButton.Value;
%             if value
%                 enableWarningLaser(app);
%                 newProgress = 50;
%                 refreshProgress(app,newProgress);
% str = 'test1';
%                 addStrOutput(app,str);
%             else
%                 disableWarningLaser(app);
%                 newProgress = 100;
%                 refreshProgress(app,newProgress);
% str = 'test2';
% addStrOutput(app,str);
%             end
% run('testRunScript.m')
% run('appTestEDFA.mlapp')
        end

        % Button pushed function: savelogButton
        function savelogButtonPushed(app, event)
            textForSave = app.OutputTextArea.Value;
            strForSave = [app.folder,filesep,createFilename(now,'log'),'.txt'];
            fid = fopen(strForSave,'w');
            for k = 1:length(textForSave)
                fprintf(fid, [textForSave{k},'\r\n']);
            end
            fclose(fid);
        end

        % Close request function: GreatUniterUIFigure
        function GreatUniterUIFigureCloseRequest(app, event)
            saveFromAppToMemFile(app);
            closeRequest(app.AddDevice_FPGA);
            closeRequest(app.AddDevice_OSAyokogawa);
            closeRequest(app.AddDevice_ATTENniMyDaq);
            closeRequest(app.AddDevice_SWITCHosaEdfa);
            closeRequest(app.AddDevice_OPMrubin);
            cellDevices = fieldnames(app.structDevice_OPMrubin);
            for i = 1:length(cellDevices)
                closeRequest(app.structDevice_OPMrubin.(cellDevices{i}))
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GreatUniterUIFigure = uifigure('Visible', 'off');
            app.GreatUniterUIFigure.Name = 'The Great Uniter';
            app.GreatUniterUIFigure.Icon = 'gu_mainicon.png';
            app.GreatUniterUIFigure.CloseRequestFcn = createCallbackFcn(app, @GreatUniterUIFigureCloseRequest, true);
            app.UIAxesProgress = uiaxes(app.GreatUniterUIFigure);
            title(app.UIAxesProgress, 'Title')
            xlabel(app.UIAxesProgress, 'X')
            ylabel(app.UIAxesProgress, 'Y')
            zlabel(app.UIAxesProgress, 'Z')
            app.UIAxesProgress2 = uiaxes(app.GreatUniterUIFigure);
            title(app.UIAxesProgress2, 'Title')
            xlabel(app.UIAxesProgress2, 'X')
            ylabel(app.UIAxesProgress2, 'Y')
            zlabel(app.UIAxesProgress2, 'Z')
            app.SelectFolderPanel = uipanel(app.GreatUniterUIFigure);
            app.SelectFolderButton = uibutton(app.SelectFolderPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Icon = 'gu_folder.png';
            app.SelectFolderButton.Tooltip = {'select folder'};
            app.SelectFolderButton.Text = '';
            app.Label = uilabel(app.SelectFolderPanel);
            app.Label.Text = 'Select folder for saving files';
            app.SelectfolderforsavingfilesTextArea = uitextarea(app.SelectFolderPanel);
            app.SelectfolderforsavingfilesTextArea.Editable = 'off';
            app.SelectActionPanel = uipanel(app.GreatUniterUIFigure);
            app.SelectActionPanel.Enable = 'off';
            app.Tree = uitree(app.SelectActionPanel);
            app.TestFPGAcomponentsNode = uitreenode(app.Tree);
            app.TestFPGAcomponentsNode.Text = 'Test FPGA components';
            app.PhotodiodeNode = uitreenode(app.TestFPGAcomponentsNode);
            app.PhotodiodeNode.Text = 'Photodiode';
            app.AttenuatorNode = uitreenode(app.TestFPGAcomponentsNode);
            app.AttenuatorNode.Text = 'Attenuator';
            app.TransceiverNode = uitreenode(app.TestFPGAcomponentsNode);
            app.TransceiverNode.Text = 'Transceiver';
            app.ParameterNode = uitreenode(app.TestFPGAcomponentsNode);
            app.ParameterNode.Text = 'Parameter';
            app.LoaddriverNode = uitreenode(app.TestFPGAcomponentsNode);
            app.LoaddriverNode.Text = 'Load driver';
            app.LaserdiodeNode = uitreenode(app.TestFPGAcomponentsNode);
            app.LaserdiodeNode.Text = 'Laser diode';
            app.TestEDFANode = uitreenode(app.Tree);
            app.TestEDFANode.Text = 'Test EDFA';
            app.CalibrateEDFANode = uitreenode(app.Tree);
            app.CalibrateEDFANode.Text = 'Calibrate EDFA';
            app.CustomscriptNode = uitreenode(app.Tree);
            app.CustomscriptNode.Text = 'Custom script';
            app.RunButton = uibutton(app.SelectActionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'gu_plus-math.png';
            app.RunButton.Tooltip = {'select action'};
            app.RunButton.Text = '';
            app.LabelTest = uilabel(app.SelectActionPanel);
            app.LabelTest.Text = 'Select action and press button';
            app.OutputWindowPanel = uipanel(app.GreatUniterUIFigure);
            app.OutputTextArea = uitextarea(app.OutputWindowPanel);
            app.OutputTextArea.Editable = 'off';
            app.OutputWindowLabel = uilabel(app.OutputWindowPanel);
            app.OutputWindowLabel.Text = 'Output Window';
            app.clearButton = uibutton(app.OutputWindowPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.Lamp = uilamp(app.OutputWindowPanel);
            app.WARNINGLaserinuseLabel = uilabel(app.OutputWindowPanel);
            app.WARNINGLaserinuseLabel.Text = {'WARNING'; 'Laser in use'};
            app.ProgressLabel = uilabel(app.OutputWindowPanel);
            app.ProgressLabel.Text = '0';
            app.PercentLabel = uilabel(app.OutputWindowPanel);
            app.PercentLabel.Text = '%';
            app.savelogButton = uibutton(app.OutputWindowPanel, 'push');
            app.savelogButton.ButtonPushedFcn = createCallbackFcn(app, @savelogButtonPushed, true);
            app.savelogButton.Text = 'save log';
            app.TheGreatUniterLabel = uilabel(app.GreatUniterUIFigure);
            app.TheGreatUniterLabel.Text = 'The Great Uniter';
            app.SelectDevicePanel = uipanel(app.GreatUniterUIFigure);
            app.SelectDevicePanel.Enable = 'off';
            app.DevicesTree = uitree(app.SelectDevicePanel);
            app.OPMRubinNode = uitreenode(app.DevicesTree);
            app.OPMRubinNode.Text = 'OPM | Rubin';
            app.OSAYokogawaNode = uitreenode(app.DevicesTree);
            app.OSAYokogawaNode.Text = 'OSA | Yokogawa';
            app.FPGANode = uitreenode(app.DevicesTree);
            app.FPGANode.Text = 'FPGA';
            app.ATTENUATORnimyDAQNode = uitreenode(app.DevicesTree);
            app.ATTENUATORnimyDAQNode.Text = 'ATTENUATOR | ni myDAQ';
            app.SWITCHOSAEDFANode = uitreenode(app.DevicesTree);
            app.SWITCHOSAEDFANode.Text = 'SWITCH | OSA/EDFA';
            app.LabelDevice = uilabel(app.SelectDevicePanel);
            app.LabelDevice.Text = 'Select device and press button';
            app.addButton = uibutton(app.SelectDevicePanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Icon = 'gu_plus-math.png';
            app.addButton.Tooltip = {'select device'};
            app.addButton.Text = '';
            app.PlotPanel = uipanel(app.GreatUniterUIFigure);
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XDropDownLabel = uilabel(app.PlotPanel);
            app.XDropDownLabel.Enable = 'off';
            app.XDropDownLabel.Visible = 'off';
            app.XDropDownLabel.Text = 'X ';
            app.XDropDown = uidropdown(app.PlotPanel);
            app.XDropDown.Enable = 'off';
            app.XDropDown.Visible = 'off';
            app.YDropDownLabel = uilabel(app.PlotPanel);
            app.YDropDownLabel.Enable = 'off';
            app.YDropDownLabel.Visible = 'off';
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.PlotPanel);
            app.YDropDown.Enable = 'off';
            app.YDropDown.Visible = 'off';
            app.PlotinnewwindowButton = uibutton(app.PlotPanel, 'push');
            app.PlotinnewwindowButton.Enable = 'off';
            app.PlotinnewwindowButton.Visible = 'off';
            app.PlotinnewwindowButton.Text = 'Plot in new window';
            app.testButton = uibutton(app.GreatUniterUIFigure, 'state');
            app.testButton.ValueChangedFcn = createCallbackFcn(app, @testButtonValueChanged, true);
            app.testButton.Visible = 'off';
            app.testButton.Text = 'test';
            app.StatusUITable = uitable(app.GreatUniterUIFigure);
            app.StatusUITable.ColumnName = {'Name'; 'Value'};
            app.StatusUITable.RowName = {};
            app.StatusUITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.GreatUniterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheGreatUniter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GreatUniterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.GreatUniterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GreatUniterUIFigure)
        end
    end
end