classdef TestVD_minMeanMax < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        applyButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        measurementsforeachVDEditField  matlab.ui.control.NumericEditField
        measurementsforeachVDEditFieldLabel  matlab.ui.control.Label
        getmoredataButton               matlab.ui.control.Button
        saveresultButton                matlab.ui.control.Button
        savealldataButton               matlab.ui.control.Button
        AddtablewithVDnamesTextArea     matlab.ui.control.TextArea
        AddtablewithVDnamesTextAreaLabel  matlab.ui.control.Label
        addButton                       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TestVDminmeanmaxLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp
        fpga
        folder
        tableVd
        tableVdPath
        oldNumberMeasurements
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.fpga = MainApp.calledAppFPGA.fpga;
            app.tableVdPath = 'tableVd_forMinMeanMax.xlsx';
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableVd_forMinMeanMax file','tableVd_forMinMeanMax.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.AddtablewithVDnamesTextArea.Value = output;
            app.tableVdPath = output;
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            app.applyButton.Enable = 'off';
            app.addButton.Enable = 'off';
            app.Panel.Enable = 'on';
            app.oldNumberMeasurements = 2;
            app.tableVd = readtable(app.tableVdPath,'ReadRowNames',false,'ReadVariableNames',false);
            
            %disp(app.UITable.Data)
            app.UITable.Data = app.tableVd;
            app.UITable.ColumnName = {'VD_name','min','mean','max'};
        end

        % Button pushed function: getmoredataButton
        function getmoredataButtonPushed(app, event)
            allMeasure = app.measurementsforeachVDEditField.Value;
            for numberMeasurment = app.oldNumberMeasurements:(app.oldNumberMeasurements+allMeasure-1)
                for numberVD = 1:height(app.tableVd)
                    name = app.tableVd{numberVD,1};
                    name=name{1};
                    app.fpga = readDataFromFPGA(app.fpga,name);
                    app.tableVd{numberVD,numberMeasurment} = str2num(app.fpga.lastRead);
                    %
                    %app.tableVd{numberVD,numberMeasurment} = 123456;
                end
            end
            app.oldNumberMeasurements = app.oldNumberMeasurements + allMeasure;

            for numberVD = 1:height(app.tableVd)
                %name = app.tableVd{numberVD,'VD_name'};
                %allMeasureForVD = app.tableVd{numberVD,app.oldNumberMeasurements:(app.oldNumberMeasurements+allMeasure-1)};
                %midmin = min(allMeasureForVD);
                
                allMeasureForVD = app.tableVd{numberVD,2:end};
                app.UITable.Data{numberVD,'min'} = min(allMeasureForVD);
                app.UITable.Data{numberVD,'mean'} = mean(allMeasureForVD);
                app.UITable.Data{numberVD,'max'} = max(allMeasureForVD);
            end
        end

        % Button pushed function: savealldataButton
        function savealldataButtonPushed(app, event)
            %tableForSave = app.UITable.Data;
            name = createFilename('allData',now);
            name = [name '.xlsx'];
            writetable(app.tableVd,name);
            addStrOutput(app.callingApp,'AllDataTable was saved');
        end

        % Button pushed function: saveresultButton
        function saveresultButtonPushed(app, event)
            
            name = createFilename('resultTable',now);
            name = [name '.xlsx'];
writetable(app.UITable.Data,name);
            addStrOutput(app.callingApp,'ResultTable was saved');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestVDminmeanmaxLabel = uilabel(app.UIFigure);
            app.TestVDminmeanmaxLabel.Text = 'Test VD (min,mean,max)';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'VD_name'; 'min'; 'mean'; 'max'};
            app.UITable.RowName = {};
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.AddtablewithVDnamesTextAreaLabel = uilabel(app.UIFigure);
            app.AddtablewithVDnamesTextAreaLabel.Text = 'Add table with VD names';
            app.AddtablewithVDnamesTextArea = uitextarea(app.UIFigure);
            app.AddtablewithVDnamesTextArea.Editable = 'off';
            app.AddtablewithVDnamesTextArea.Value = {'tableVd_forMinMeanMax.xlsx'};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Enable = 'off';
            app.savealldataButton = uibutton(app.Panel, 'push');
            app.savealldataButton.ButtonPushedFcn = createCallbackFcn(app, @savealldataButtonPushed, true);
            app.savealldataButton.Text = 'save all data';
            app.saveresultButton = uibutton(app.Panel, 'push');
            app.saveresultButton.ButtonPushedFcn = createCallbackFcn(app, @saveresultButtonPushed, true);
            app.saveresultButton.Text = 'save result';
            app.getmoredataButton = uibutton(app.Panel, 'push');
            app.getmoredataButton.ButtonPushedFcn = createCallbackFcn(app, @getmoredataButtonPushed, true);
            app.getmoredataButton.Text = 'get more data';
            app.measurementsforeachVDEditFieldLabel = uilabel(app.Panel);
            app.measurementsforeachVDEditFieldLabel.Text = 'measurements for each VD';
            app.measurementsforeachVDEditField = uieditfield(app.Panel, 'numeric');
            app.measurementsforeachVDEditField.ValueDisplayFormat = '%.0f';
            app.measurementsforeachVDEditField.Value = 3;
            app.applyButton = uibutton(app.UIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestVD_minMeanMax(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end