classdef appOPMthorlabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        ReadpowerdBmNode                matlab.ui.container.TreeNode
        RunButton                       matlab.ui.control.Button
        ChoosefunctionLabel             matlab.ui.control.Label
        WavelengthCheckBox              matlab.ui.control.CheckBox
        WavelengthEditField             matlab.ui.control.EditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        AveragingCheckBox               matlab.ui.control.CheckBox
        AveragingEditField              matlab.ui.control.EditField
        AveragingEditFieldLabel         matlab.ui.control.Label
        LossCheckBox                    matlab.ui.control.CheckBox
        LossEditField                   matlab.ui.control.EditField
        LossEditFieldLabel              matlab.ui.control.Label
        FilterCheckBox                  matlab.ui.control.CheckBox
        FilterDropDown                  matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        ApplysettingsButton             matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        OpticalPowerMeterThorlabsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingApp
        folder
        userText
        opmT
    end
    
    methods (Access = public)
        function output = connect(app)
            try
                app.opmT = [];
                app.opmT = OpticalPowerMeterThorlabs(app);
                app.Panel.Enable = 'on';
                output = "OPM Thorlabs connected";
            catch ME
                fig = uifigure;
                output = "Connection failed";
                uialert(fig,ME.message,output);
                app.Switch.Value = "Disconnect";
            end
        end
        function output = disconnect(app)
            if ~isequal(class(app.opmT),'double')
                app.opmT = deleteVirtualObjectArr(app.opmT);
                output = "OPM Thorlabs disconnected";
                app.Panel.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            
            app.folder = MainApp.folder;
            app.userText = MainApp.userText;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: ApplysettingsButton
        function ApplysettingsButtonPushed(app, event)
            app.opmT = applySettings(app.opmT,app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            value = app.Tree.SelectedNodes;
            if value == app.ReadpowerdBmNode
                app.opmT = readPowerdBm(app.opmT);
                output = ['Мощность на измерителе Thorlabs ',...
                    num2strForPrint(app.opmT.lastReadPower) ' дБм'];
                addStrOutput(app.callingApp,output);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpticalPowerMeterThorlabsLabel = uilabel(app.UIFigure);
            app.OpticalPowerMeterThorlabsLabel.Text = 'Optical Power Meter (Thorlabs)';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            app.Panel = uipanel(app.UIFigure);
            app.ApplysettingsButton = uibutton(app.Panel, 'push');
            app.ApplysettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplysettingsButtonPushed, true);
            app.ApplysettingsButton.Text = 'Apply settings';
            app.FilterDropDownLabel = uilabel(app.Panel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.Panel);
            app.FilterDropDown.Items = {'on', 'off'};
            app.FilterDropDown.Value = 'on';
            app.FilterCheckBox = uicheckbox(app.Panel);
            app.FilterCheckBox.Text = '';
            app.LossEditFieldLabel = uilabel(app.Panel);
            app.LossEditFieldLabel.Text = 'Loss';
            app.LossEditField = uieditfield(app.Panel, 'text');
            app.LossEditField.Value = '0';
            app.LossCheckBox = uicheckbox(app.Panel);
            app.LossCheckBox.Text = '';
            app.AveragingEditFieldLabel = uilabel(app.Panel);
            app.AveragingEditFieldLabel.Text = 'Averaging';
            app.AveragingEditField = uieditfield(app.Panel, 'text');
            app.AveragingEditField.Value = '1';
            app.AveragingCheckBox = uicheckbox(app.Panel);
            app.AveragingCheckBox.Text = '';
            app.WavelengthEditFieldLabel = uilabel(app.Panel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.Panel, 'text');
            app.WavelengthEditField.Value = '980';
            app.WavelengthCheckBox = uicheckbox(app.Panel);
            app.WavelengthCheckBox.Text = '';
            app.ChoosefunctionLabel = uilabel(app.Panel);
            app.ChoosefunctionLabel.Text = 'Choose function';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Tree = uitree(app.Panel);
            app.ReadpowerdBmNode = uitreenode(app.Tree);
            app.ReadpowerdBmNode.Text = 'Read power dBm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appOPMthorlabs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end