classdef appTestCoeff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ForcoeffLabel  matlab.ui.control.Label
        Panel          matlab.ui.container.Panel
        Label10        matlab.ui.control.Label
        Label9         matlab.ui.control.Label
        Label8         matlab.ui.control.Label
        Label7         matlab.ui.control.Label
        Label6         matlab.ui.control.Label
        Label5         matlab.ui.control.Label
        Label4         matlab.ui.control.Label
        Label3         matlab.ui.control.Label
        Label2         matlab.ui.control.Label
        Label1         matlab.ui.control.Label
        getinfoVDandaddrowtotableButton  matlab.ui.control.Button
        EditField11_1  matlab.ui.control.EditField
        EditField11_2  matlab.ui.control.NumericEditField
        EditField12_1  matlab.ui.control.EditField
        EditField12_2  matlab.ui.control.NumericEditField
        EditField20_1  matlab.ui.control.EditField
        EditField20_2  matlab.ui.control.NumericEditField
        EditField19_1  matlab.ui.control.EditField
        EditField19_2  matlab.ui.control.NumericEditField
        EditField18_1  matlab.ui.control.EditField
        EditField18_2  matlab.ui.control.NumericEditField
        EditField17_1  matlab.ui.control.EditField
        EditField17_2  matlab.ui.control.NumericEditField
        EditField16_1  matlab.ui.control.EditField
        EditField16_2  matlab.ui.control.NumericEditField
        EditField15_1  matlab.ui.control.EditField
        EditField15_2  matlab.ui.control.NumericEditField
        EditField14_1  matlab.ui.control.EditField
        EditField14_2  matlab.ui.control.NumericEditField
        EditField13_1  matlab.ui.control.EditField
        EditField13_2  matlab.ui.control.NumericEditField
        EditField1_1   matlab.ui.control.EditField
        EditField1_2   matlab.ui.control.NumericEditField
        EditField2_1   matlab.ui.control.EditField
        EditField2_2   matlab.ui.control.NumericEditField
        EditField10_1  matlab.ui.control.EditField
        EditField10_2  matlab.ui.control.NumericEditField
        EditField9_1   matlab.ui.control.EditField
        EditField9_2   matlab.ui.control.NumericEditField
        EditField8_1   matlab.ui.control.EditField
        EditField8_2   matlab.ui.control.NumericEditField
        EditField7_1   matlab.ui.control.EditField
        EditField7_2   matlab.ui.control.NumericEditField
        EditField6_1   matlab.ui.control.EditField
        EditField6_2   matlab.ui.control.NumericEditField
        EditField5_1   matlab.ui.control.EditField
        EditField5_2   matlab.ui.control.NumericEditField
        EditField4_1   matlab.ui.control.EditField
        EditField4_2   matlab.ui.control.NumericEditField
        EditField3_1   matlab.ui.control.EditField
        EditField3_2   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        fpga % Description
        callingApp
    end
    
    methods (Access = private)
        
        function app = writeValueForName(app,wvalue,name)
            wvalue = num2str(wvalue);
            app.fpga = writeDataToFPGA(app.fpga,name,wvalue);
        end
        function res = readVD(app,name)
            app.fpga = readDataFromFPGA(app.fpga,name);
            res = str2double(app.fpga.lastRead);
        end
        function app = readCoeff(app)
            app.EditField1_2.Value = readVD(app,app.EditField1_1.Value);
            app.EditField2_2.Value = readVD(app,app.EditField2_1.Value);
            app.EditField3_2.Value = readVD(app,app.EditField3_1.Value);
            app.EditField4_2.Value = readVD(app,app.EditField4_1.Value);
            app.EditField5_2.Value = readVD(app,app.EditField5_1.Value);
            app.EditField6_2.Value = readVD(app,app.EditField6_1.Value);
            app.EditField7_2.Value = readVD(app,app.EditField7_1.Value);
        end
        function app = addStr(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.fpga = app.callingApp.calledAppFPGA.fpga;
            
            %app.table = table()
            app = readCoeff(app);
        end

        % Value changed function: EditField1_2
        function EditField1_2ValueChanged(app, event)
            %app.Label1.FontColor = 'gray';
            value = app.EditField1_2.Value;
            name = app.EditField1_1.Value;
            app = writeValueForName(app,value,name);
            %app.Label1.FontColor = 'black';
        end

        % Value changed function: EditField2_2
        function EditField2_2ValueChanged(app, event)
            %app.Label2.FontColor = 'gray';
            value = app.EditField2_2.Value;
            name = app.EditField2_1.Value;
            app = writeValueForName(app,value,name);
            %app.Label1.FontColor = 'black';
        end

        % Value changed function: EditField3_2
        function EditField3_2ValueChanged(app, event)
            %app.Label3.FontColor = 'gray';
            value = app.EditField3_2.Value;
            name = app.EditField3_1.Value;
            app = writeValueForName(app,value,name);
            %app.Label1.FontColor = 'black';
        end

        % Value changed function: EditField4_2
        function EditField4_2ValueChanged(app, event)
            %app.Label4.FontColor = 'gray';
            value = app.EditField4_2.Value;
            name = app.EditField4_1.Value;
            app = writeValueForName(app,value,name);
            %app.Label4.FontColor = 'black';
        end

        % Value changed function: EditField5_2
        function EditField5_2ValueChanged(app, event)
                        %app.Label5.FontColor = 'gray';
            value = app.EditField5_2.Value;
            name = app.EditField5_1.Value;
            app = writeValueForName(app,value,name);
            %app.Label5.FontColor = 'black';
        end

        % Value changed function: EditField6_2
        function EditField6_2ValueChanged(app, event)
                        %app.Label6.FontColor = 'gray';
            value = app.EditField6_2.Value;
            name = app.EditField6_1.Value;
            app = writeValueForName(app,value,name);
            %app.Label6.FontColor = 'black';
        end

        % Value changed function: EditField7_2
        function EditField7_2ValueChanged(app, event)
                        %app.Label7.FontColor = 'gray';
            value = app.EditField7_2.Value;
            name = app.EditField7_1.Value;
            app = writeValueForName(app,value,name);
            %app.Label7.FontColor = 'black';
        end

        % Button pushed function: getinfoVDandaddrowtotableButton
        function getinfoVDandaddrowtotableButtonPushed(app, event)
            app.EditField11_2.Value = readVD(app,app.EditField11_1.Value);
            app.EditField12_2.Value = readVD(app,app.EditField12_1.Value);
            app.EditField13_2.Value = readVD(app,app.EditField13_1.Value);
            app.EditField14_2.Value = readVD(app,app.EditField14_1.Value);
            app.EditField15_2.Value = readVD(app,app.EditField15_1.Value);
            app.EditField16_2.Value = readVD(app,app.EditField16_1.Value);
            app.EditField17_2.Value = readVD(app,app.EditField17_1.Value);
            app.EditField18_2.Value = readVD(app,app.EditField18_1.Value);
            %app = addStr(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.EditField3_2 = uieditfield(app.Panel, 'numeric');
            app.EditField3_2.ValueChangedFcn = createCallbackFcn(app, @EditField3_2ValueChanged, true);
            app.EditField3_1 = uieditfield(app.Panel, 'text');
            app.EditField3_1.Editable = 'off';
            app.EditField3_1.Value = 'P_VALUE_CH0';
            app.EditField4_2 = uieditfield(app.Panel, 'numeric');
            app.EditField4_2.ValueChangedFcn = createCallbackFcn(app, @EditField4_2ValueChanged, true);
            app.EditField4_1 = uieditfield(app.Panel, 'text');
            app.EditField4_1.Editable = 'off';
            app.EditField4_1.Value = 'I_VALUE_CH0';
            app.EditField5_2 = uieditfield(app.Panel, 'numeric');
            app.EditField5_2.ValueChangedFcn = createCallbackFcn(app, @EditField5_2ValueChanged, true);
            app.EditField5_1 = uieditfield(app.Panel, 'text');
            app.EditField5_1.Editable = 'off';
            app.EditField5_1.Value = 'I_MAX_CH0';
            app.EditField6_2 = uieditfield(app.Panel, 'numeric');
            app.EditField6_2.ValueChangedFcn = createCallbackFcn(app, @EditField6_2ValueChanged, true);
            app.EditField6_1 = uieditfield(app.Panel, 'text');
            app.EditField6_1.Editable = 'off';
            app.EditField6_1.Value = 'I_VALUE_CH1';
            app.EditField7_2 = uieditfield(app.Panel, 'numeric');
            app.EditField7_2.ValueChangedFcn = createCallbackFcn(app, @EditField7_2ValueChanged, true);
            app.EditField7_1 = uieditfield(app.Panel, 'text');
            app.EditField7_1.Editable = 'off';
            app.EditField7_1.Value = 'I_VALUE_CH2';
            app.EditField8_2 = uieditfield(app.Panel, 'numeric');
            app.EditField8_2.Enable = 'off';
            app.EditField8_1 = uieditfield(app.Panel, 'text');
            app.EditField8_1.Enable = 'off';
            app.EditField9_2 = uieditfield(app.Panel, 'numeric');
            app.EditField9_2.Enable = 'off';
            app.EditField9_1 = uieditfield(app.Panel, 'text');
            app.EditField9_1.Enable = 'off';
            app.EditField10_2 = uieditfield(app.Panel, 'numeric');
            app.EditField10_2.Enable = 'off';
            app.EditField10_1 = uieditfield(app.Panel, 'text');
            app.EditField10_1.Enable = 'off';
            app.EditField2_2 = uieditfield(app.Panel, 'numeric');
            app.EditField2_2.ValueChangedFcn = createCallbackFcn(app, @EditField2_2ValueChanged, true);
            app.EditField2_1 = uieditfield(app.Panel, 'text');
            app.EditField2_1.Editable = 'off';
            app.EditField2_1.Value = 'P2_1550_OUT';
            app.EditField1_2 = uieditfield(app.Panel, 'numeric');
            app.EditField1_2.ValueChangedFcn = createCallbackFcn(app, @EditField1_2ValueChanged, true);
            app.EditField1_1 = uieditfield(app.Panel, 'text');
            app.EditField1_1.Editable = 'off';
            app.EditField1_1.Value = 'P1_1550_OUT';
            app.EditField13_2 = uieditfield(app.Panel, 'numeric');
            app.EditField13_2.ValueDisplayFormat = '%.0f';
            app.EditField13_1 = uieditfield(app.Panel, 'text');
            app.EditField13_1.Value = 'ADC1CH3_VD5_Pout_L1_1';
            app.EditField14_2 = uieditfield(app.Panel, 'numeric');
            app.EditField14_2.ValueDisplayFormat = '%.0f';
            app.EditField14_1 = uieditfield(app.Panel, 'text');
            app.EditField14_1.Value = 'ADC2CH3_VD11_Pout_L1_2';
            app.EditField15_2 = uieditfield(app.Panel, 'numeric');
            app.EditField15_2.ValueDisplayFormat = '%.0f';
            app.EditField15_1 = uieditfield(app.Panel, 'text');
            app.EditField15_1.Value = 'ADC1CH4_VD4_Pin_L2_1';
            app.EditField16_2 = uieditfield(app.Panel, 'numeric');
            app.EditField16_2.ValueDisplayFormat = '%.0f';
            app.EditField16_1 = uieditfield(app.Panel, 'text');
            app.EditField16_1.Value = 'ADC2CH4_VD10_Pin_L2_2';
            app.EditField17_2 = uieditfield(app.Panel, 'numeric');
            app.EditField17_2.ValueDisplayFormat = '%.0f';
            app.EditField17_1 = uieditfield(app.Panel, 'text');
            app.EditField17_1.Value = 'ADC1CH2_VD6_Pout_L2_1';
            app.EditField18_2 = uieditfield(app.Panel, 'numeric');
            app.EditField18_2.ValueDisplayFormat = '%.0f';
            app.EditField18_1 = uieditfield(app.Panel, 'text');
            app.EditField18_1.Value = 'ADC2CH2_VD12_Pout_L2_2';
            app.EditField19_2 = uieditfield(app.Panel, 'numeric');
            app.EditField19_2.Enable = 'off';
            app.EditField19_1 = uieditfield(app.Panel, 'text');
            app.EditField19_1.Enable = 'off';
            app.EditField20_2 = uieditfield(app.Panel, 'numeric');
            app.EditField20_2.Enable = 'off';
            app.EditField20_1 = uieditfield(app.Panel, 'text');
            app.EditField20_1.Enable = 'off';
            app.EditField12_2 = uieditfield(app.Panel, 'numeric');
            app.EditField12_2.ValueDisplayFormat = '%.0f';
            app.EditField12_1 = uieditfield(app.Panel, 'text');
            app.EditField12_1.Value = 'ADC2CH5_VD9_Pin_L1_2';
            app.EditField11_2 = uieditfield(app.Panel, 'numeric');
            app.EditField11_2.ValueDisplayFormat = '%.0f';
            app.EditField11_1 = uieditfield(app.Panel, 'text');
            app.EditField11_1.Value = 'ADC1CH5_VD3_Pin_L1_1';
            app.getinfoVDandaddrowtotableButton = uibutton(app.Panel, 'push');
            app.getinfoVDandaddrowtotableButton.ButtonPushedFcn = createCallbackFcn(app, @getinfoVDandaddrowtotableButtonPushed, true);
            app.getinfoVDandaddrowtotableButton.Text = 'get info VD and add row to table';
            app.Label1 = uilabel(app.Panel);
            app.Label1.Visible = 'off';
            app.Label1.Text = '✓';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Visible = 'off';
            app.Label2.Text = '✓';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Visible = 'off';
            app.Label3.Text = '✓';
            app.Label4 = uilabel(app.Panel);
            app.Label4.Visible = 'off';
            app.Label4.Text = '✓';
            app.Label5 = uilabel(app.Panel);
            app.Label5.Visible = 'off';
            app.Label5.Text = '✓';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Visible = 'off';
            app.Label6.Text = '✓';
            app.Label7 = uilabel(app.Panel);
            app.Label7.Visible = 'off';
            app.Label7.Text = '✓';
            app.Label8 = uilabel(app.Panel);
            app.Label8.Enable = 'off';
            app.Label8.Visible = 'off';
            app.Label8.Text = '✓';
            app.Label9 = uilabel(app.Panel);
            app.Label9.Enable = 'off';
            app.Label9.Visible = 'off';
            app.Label9.Text = '✓';
            app.Label10 = uilabel(app.Panel);
            app.Label10.Enable = 'off';
            app.Label10.Visible = 'off';
            app.Label10.Text = '✓';
            app.ForcoeffLabel = uilabel(app.UIFigure);
            app.ForcoeffLabel.Text = 'For coeff';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestCoeff(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end