classdef appATTENdicon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        setButton                     matlab.ui.control.Button
        Attenuationao0EditField       matlab.ui.control.NumericEditField
        Attenuationao0EditFieldLabel  matlab.ui.control.Label
        DiconpassportTextArea         matlab.ui.control.TextArea
        DiconpassportTextAreaLabel    matlab.ui.control.Label
        downloadxlsxButton            matlab.ui.control.Button
        Switch                        matlab.ui.control.Switch
        DiconviaDataAcquisitionDAQLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        callingApp
        folder
        %userText
    end
    properties (Access = public)
        tableAttenuatorDiconPassport
        attenuatorDicon
    end
    methods (Access = public)
        function output = connect(app)
%             try
                app.attenuatorDicon = [];
                app.attenuatorDicon = AttenuatorDicon(app);
                output = 'DICON connected';
                app.Panel.Enable = 'on';
%             catch ME
%                 fig = uifigure;
%                 output = "Connection failed";
%                 uialert(fig,ME.message,output);
%                 app.Switch.Value = "Disconnect";
%             end
        end
        function output = disconnect(app)
            if ~isequal(class(app.attenuatorDicon),'double')
                app.attenuatorDicon = deleteVirtualObject(app.attenuatorDicon);
                %заменить на реальную проверку
                app.Panel.Enable = 'off';
            end
            output = 'DICON disconnected';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            
            app.folder = MainApp.folder;
            %app.userText = MainApp.userText;
            
            app.tableAttenuatorDiconPassport = app.DiconpassportTextArea.Value{1};
        end

        % Button pushed function: downloadxlsxButton
        function downloadxlsxButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select DICON passport table','AttenuatorDiconPassportCut.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableAttenuatorDiconPassport = output;
            app.DiconpassportTextArea.Value = output;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            attenuation = app.Attenuationao0EditField.Value;
            app.attenuatorDicon = setAttenuation(app.attenuatorDicon,attenuation);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiconviaDataAcquisitionDAQLabel = uilabel(app.UIFigure);
            app.DiconviaDataAcquisitionDAQLabel.Text = 'Dicon via Data Acquisition (DAQ)';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            app.downloadxlsxButton = uibutton(app.UIFigure, 'push');
            app.downloadxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @downloadxlsxButtonPushed, true);
            app.downloadxlsxButton.Text = 'download  *.xlsx';
            app.DiconpassportTextAreaLabel = uilabel(app.UIFigure);
            app.DiconpassportTextAreaLabel.Text = 'Dicon passport';
            app.DiconpassportTextArea = uitextarea(app.UIFigure);
            app.DiconpassportTextArea.Value = {'AttenuatorDiconPassportCut.xlsx'};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Enable = 'off';
            app.Attenuationao0EditFieldLabel = uilabel(app.Panel);
            app.Attenuationao0EditFieldLabel.Text = 'Attenuation (ao0)';
            app.Attenuationao0EditField = uieditfield(app.Panel, 'numeric');
            app.setButton = uibutton(app.Panel, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appATTENdicon(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end