classdef appAddDevice_ATTENniMyDaq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ATTENnimyDAQUIFigure          matlab.ui.Figure
        ConnectSwitch                 matlab.ui.control.Switch
        ConnectPanel                  matlab.ui.container.Panel
        Tip1Image                     matlab.ui.control.Image
        plotButton                    matlab.ui.control.Button
        AddTableButton                matlab.ui.control.Button
        MainTablePathTextArea         matlab.ui.control.TextArea
        AddattenuatortableVoltagevsAttenuationLabel  matlab.ui.control.Label
        FunctionPanel                 matlab.ui.container.Panel
        rangeLabel                    matlab.ui.control.Label
        Tip2Image                     matlab.ui.control.Image
        dBmLabel                      matlab.ui.control.Label
        okButton                      matlab.ui.control.Button
        Attenuationao0EditField       matlab.ui.control.NumericEditField
        Attenuationao0EditFieldLabel  matlab.ui.control.Label
        AttenuatornimyDAQLabel        matlab.ui.control.Label
    end

    properties (Access = public)
        callingApp
        folder
    end
    properties (Access = public)
        mainTable
        funcAttenVolt
        mainObj
    end
    methods (Access = public)
        function connect(app)
             try
                app.mainObj = [];
                app.mainObj = Device_ATTENniMyDaq(app);
                output = 'Attenuator connected';
                addStrOutput(app.callingApp,output);
                app.ConnectPanel.Enable = 'off';
                app.FunctionPanel.Enable = 'on';
             catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
                output = "Wrong connection";
                addStrOutput(app.callingApp,output);
                app.ConnectSwitch.Value = "Disconnect";
                app.ConnectPanel.Enable = 'on';
                app.FunctionPanel.Enable = 'off';
             end
        end
        function disconnect(app)
            if ~isequal(class(app.mainObj),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = 'Attenuator disconnected';
                addStrOutput(app.callingApp,output);
            end
            app.ConnectPanel.Enable = 'on';
            app.FunctionPanel.Enable = 'off';
        end
        function selectFile(app)
            [file,path] = uigetfile('*.xlsx','Select attenuator table',app.MainTablePathTextArea.Value);
            output = [path file];
            if isequal(file,0) || ~isfile(output)
                output = '';
            end
            app.MainTablePathTextArea.Value = output;
        end
        function readFile(app)
           if isempty(app.MainTablePathTextArea.Value{1})
                app.plotButton.Enable = "off";
                app.ConnectSwitch.Enable = "off";
           else
                app.plotButton.Enable = "on";
                app.ConnectSwitch.Enable = "on";
                readMainTable(app);
                editRangeString(app);
            end
        end
        function readMainTable(app)
            try
                app.mainTable = readmatrix(app.MainTablePathTextArea.Value{1});
                [Attenuation_dBm, Voltage_V] = prepareCurveData(app.mainTable(:,1), app.mainTable(:,2));
                [app.funcAttenVolt, gof] = fit(Attenuation_dBm, Voltage_V, 'linearinterp', 'Normalize', 'on' );
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
            end
        end
        function editRangeString(app)
            minAttenuationStr = num2str(min(app.mainTable(:,1)));
            maxAttenuationStr = num2str(max(app.mainTable(:,1)));
            app.rangeLabel.Text = strcat("(must be in range ",...
                minAttenuationStr," - ",maxAttenuationStr," dBm)");
        end
        function defaultRangeString(app)
            app.rangeLabel.Text = "(must be in range ____ - ____ dBm)";
        end
        function plotPreview(app) %change it
            plotAttTable = gcf;
            plotAttTable.Name = 'Voltage vs. attenuation';
            plotAttTable.NumberTitle = 'off';
            plot(app.mainTable(:,1), app.mainTable(:,2));
            plotAttTable.CurrentAxes.XGrid = 'on';
            plotAttTable.CurrentAxes.YGrid = 'on';
            plotAttTable.CurrentAxes.XLabel.String = 'Attenuation, dBm';
            plotAttTable.CurrentAxes.YLabel.String = 'Voltage, V';
            plotAttTable.CurrentAxes.Title.String = 'Voltage vs. attenuation';
            plotAttTable.CurrentAxes.LineStyleOrder = '--o';
        end
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appAddDevice_ATTENniMyDaq');
            app.MainTablePathTextArea.Value = appAddDevice_ATTENniMyDaq.MainTablePathTextArea;
        end
        function saveFromAppToMemFile(app)
            appAddDevice_ATTENniMyDaq.MainTablePathTextArea = app.MainTablePathTextArea.Value{1};
            save('TheGreatUniterMemory.mat','appAddDevice_ATTENniMyDaq','-append');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
            readFile(app);
        end

        % Button pushed function: AddTableButton
        function AddTableButtonPushed(app, event)
            selectFile(app);
            readFile(app);
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                connect(app);
            elseif value == "Disconnect"
                disconnect(app);
            end
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            attenuation = app.Attenuationao0EditField.Value;
            app.mainObj = setAttenuation(app.mainObj,attenuation);
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            plotPreview(app);
        end

        % Close request function: ATTENnimyDAQUIFigure
        function ATTENnimyDAQUIFigureCloseRequest(app, event)
            saveFromAppToMemFile(app);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ATTENnimyDAQUIFigure = uifigure('Visible', 'off');
            app.ATTENnimyDAQUIFigure.Name = 'ATTEN ni myDAQ';
            app.ATTENnimyDAQUIFigure.Icon = 'gu_device.png';
            app.ATTENnimyDAQUIFigure.CloseRequestFcn = createCallbackFcn(app, @ATTENnimyDAQUIFigureCloseRequest, true);
            app.AttenuatornimyDAQLabel = uilabel(app.ATTENnimyDAQUIFigure);
            app.AttenuatornimyDAQLabel.Text = 'Attenuator (ni myDAQ)';
            app.FunctionPanel = uipanel(app.ATTENnimyDAQUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.Attenuationao0EditFieldLabel = uilabel(app.FunctionPanel);
            app.Attenuationao0EditFieldLabel.Text = 'Attenuation (ao0)';
            app.Attenuationao0EditField = uieditfield(app.FunctionPanel, 'numeric');
            app.okButton = uibutton(app.FunctionPanel, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.dBmLabel = uilabel(app.FunctionPanel);
            app.dBmLabel.Text = 'dBm';
            app.Tip2Image = uiimage(app.FunctionPanel);
            app.Tip2Image.Tooltip = {'Attenuation should be in range [minimum;maximum] from table.'; 'Here we use Analog Output channel 0.'};
            app.Tip2Image.ImageSource = 'gu_help.png';
            app.rangeLabel = uilabel(app.FunctionPanel);
            app.rangeLabel.Text = '(must be in range ____ - ____ dBm)';
            app.ConnectPanel = uipanel(app.ATTENnimyDAQUIFigure);
            app.AddattenuatortableVoltagevsAttenuationLabel = uilabel(app.ConnectPanel);
            app.AddattenuatortableVoltagevsAttenuationLabel.Text = {'Add attenuator table'; '(Voltage vs. Attenuation)'};
            app.MainTablePathTextArea = uitextarea(app.ConnectPanel);
            app.MainTablePathTextArea.Editable = 'off';
            app.AddTableButton = uibutton(app.ConnectPanel, 'push');
            app.AddTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddTableButtonPushed, true);
            app.AddTableButton.Icon = 'gu_microsoft-excel.png';
            app.AddTableButton.Text = '';
            app.plotButton = uibutton(app.ConnectPanel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.Tip1Image = uiimage(app.ConnectPanel);
            app.Tip1Image.Tooltip = {'Table should have 2 columns: attenuation_dBm, voltage_V in this order.'; 'Note that values should form a function V = f(dBm) for better approximation. You can test yourself by looking at the plot below.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.ConnectSwitch = uiswitch(app.ATTENnimyDAQUIFigure, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            % Show the figure after all components are created
            app.ATTENnimyDAQUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_ATTENniMyDaq(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ATTENnimyDAQUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ATTENnimyDAQUIFigure)
        end
    end
end