classdef appAddDevice_FPGA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FPGAUIFigure                  matlab.ui.Figure
        DataformatEditField           matlab.ui.control.EditField
        DataformatEditFieldLabel      matlab.ui.control.Label
        getadminaccessButton          matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        AddressnameTextArea           matlab.ui.control.TextArea
        AddressnameTextAreaLabel      matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        AdminFunctionPanel            matlab.ui.container.Panel
        WriteEEPROMButton             matlab.ui.control.Button
        WriteEepromEditField          matlab.ui.control.EditField
        LabelType                     matlab.ui.control.Label
        AddressnameEditField          matlab.ui.control.EditField
        AddressnameEditFieldLabel     matlab.ui.control.Label
        IPaddressEditField            matlab.ui.control.EditField
        IPaddressLabel                matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        runtestButton                 matlab.ui.control.Button
        TextforfileEditField          matlab.ui.control.EditField
        TextforfileEditFieldLabel     matlab.ui.control.Label
        downloadButton_2              matlab.ui.control.Button
        AddtableforautotestsTextArea  matlab.ui.control.TextArea
        AddtableforautotestsTextAreaLabel  matlab.ui.control.Label
        ConnectPanel                  matlab.ui.container.Panel
        downloadtxtButton             matlab.ui.control.Button
        MainTablePathTextArea         matlab.ui.control.TextArea
        AddresstableTextAreaLabel     matlab.ui.control.Label
        InterfaceDropDown             matlab.ui.control.DropDown
        InterfaceDropDownLabel        matlab.ui.control.Label
        ConnectSwitch                 matlab.ui.control.Switch
        PanelMode                     matlab.ui.container.Panel
        MiniveryslotLabel             matlab.ui.control.Label
        readvalueButton_2             matlab.ui.control.Button
        downloadButton                matlab.ui.control.Button
        UITableFPGA                   matlab.ui.control.Table
        PanelFunction                 matlab.ui.container.Panel
        ModeDropDown                  matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        onaddressEditField            matlab.ui.control.EditField
        onaddressEditFieldLabel       matlab.ui.control.Label
        TablevaluewrittenEditField    matlab.ui.control.EditField
        TablevaluewrittenEditFieldLabel  matlab.ui.control.Label
        TurndiodesLabel               matlab.ui.control.Label
        writenexttablevalueButton     matlab.ui.control.Button
        TurndiodesSwitch              matlab.ui.control.Switch
        Lamp                          matlab.ui.control.Lamp
        FunctionPanel                 matlab.ui.container.Panel
        UnlockWritetoEEPROMfunctionCheckBox  matlab.ui.control.CheckBox
        AddressnameListBox            matlab.ui.control.ListBox
        AddressnameListBoxLabel       matlab.ui.control.Label
        writeButton                   matlab.ui.control.Button
        readButton                    matlab.ui.control.Button
        WriteValueEditField           matlab.ui.control.EditField
        ReadValueEditField            matlab.ui.control.EditField
        FPGALabel                     matlab.ui.control.Label
    end


    properties (Access = public)
        callingApp
        folder
    end

    properties (Access = public)
        mainTable
        mainObj
        password
    end
%     properties (Access = public)
%         tableName
%         tableAutoFunction
%         tableAutoFunctionPath
%     end

    methods (Access = public)
function output = connect(app)
             try
                app.mainObj = [];
                app.mainObj = Device_FPGA(app);
                output = 'FPGA connected';
                app.FunctionPanel.Enable = 'on';
             catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
                output = "Wrong connection";
                app.ConnectSwitch.Value = "Disconnect";
             end
        end
        function output = disconnect(app)
            if ~isequal(class(app.mainObj),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = 'FPGA disconnected';
            end
            app.FunctionPanel.Enable = 'off';
            app.UnlockWritetoEEPROMfunctionCheckBox.Value = 0;
            app.AdminFunctionPanel.Enable = "off";
        end
        function selectFile(app)
            [file,path] = uigetfile('*.txt','Select ADDRESS.txt file','ADDRESS.txt');%memory
            output = [path file];
            if isequal(file,0) || ~isfile(output)
                output = '';
            end
            app.MainTablePathTextArea.Value = output;
        end
        function readFile(app)
           if isempty(app.MainTablePathTextArea.Value{1})
                app.ConnectSwitch.Enable = "off";
           else
                app.ConnectSwitch.Enable = "on";
                readMainTable(app);
            end
        end
        function readMainTable(app)
            try
%                 app.mainTable = readmatrix(app.MainTablePathTextArea.Value{1});
%                 [Attenuation_dBm, Voltage_V] = prepareCurveData(app.mainTable(:,1), app.mainTable(:,2));
%                 [app.funcAttenVolt, gof] = fit(Attenuation_dBm, Voltage_V, 'linearinterp', 'Normalize', 'on' );

                % filename = 'ADDRESS.txt';
            fileID = fopen(app.MainTablePathTextArea.Value{1},'r+');
            reading_txt = fscanf(fileID,'%c');
            fclose(fileID);
            address_cell = regexp(reading_txt,'[^# ](\w{4}) = \[(\d*)\] = (\w*) = (\w*.\w*) =',...
                'tokens');
            number_of_cell = length(address_cell);
            address_cell_Name = strings(number_of_cell,1);
            address_cell_Address = cell(number_of_cell,1);
            address_cell_Number_of_bits = cell(number_of_cell,1);
            address_cell_Type = strings(number_of_cell,1);
            address_Data = cell(number_of_cell,1);
            for i = 1:number_of_cell
                address_cell_Name(i) = address_cell{1,i}{1,4};
                address_cell_Address(i) = address_cell{1,i}(1);
                address_cell_Number_of_bits(i) = address_cell{1,i}(2);
                address_cell_Type(i) = address_cell{1,i}{1,3};
            end
            app.mainTable = table(address_cell_Address,address_cell_Number_of_bits,address_cell_Type,address_Data,...
                'RowNames',address_cell_Name',...
                'VariableNames',{'ADDR','BITS','FRM','DATA'});
            app.AddressnameListBox.Items = app.mainTable.Properties.RowNames;
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
            end
        end
%         function plotPreview(app) %change it
%             plotAttTable = gcf;
%             plotAttTable.Name = 'Voltage vs. attenuation';
%             plotAttTable.NumberTitle = 'off';
%             plot(app.mainTable(:,1), app.mainTable(:,2));
%             plotAttTable.CurrentAxes.XGrid = 'on';
%             plotAttTable.CurrentAxes.YGrid = 'on';
%             plotAttTable.CurrentAxes.XLabel.String = 'Attenuation, dBm';
%             plotAttTable.CurrentAxes.YLabel.String = 'Voltage, V';
%             plotAttTable.CurrentAxes.Title.String = 'Voltage vs. attenuation';
%             plotAttTable.CurrentAxes.LineStyleOrder = '--o';
%         end
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appAddDevice_FPGA');
            app.MainTablePathTextArea.Value = appAddDevice_FPGA.MainTablePathTextArea;
            app.password = appAddDevice_FPGA.Password;
        end
        function saveFromAppToMemFile(app)
            appAddDevice_FPGA.MainTablePathTextArea = app.MainTablePathTextArea.Value{1};
            save('TheGreatUniterMemory.mat','appAddDevice_FPGA','-append');
        end


%         function output = connect(app)
% %             try
%                 app.mainObj = [];
%                 app.mainObj = FPGA(app);
%                 app.PanelMode.Enable = 'on';
%                 app.PanelFunction.Enable = 'on';
%                 app.FunctionPanel.Enable = 'on';
%                 output = "FPGA connected";
% %             catch
% %                 fig = uifigure;
% %                 message = ["Cannot create a communication link with the remote server.";...
% %                     "Please make sure the server is running and device is on"];
% %                 output = "Connection failed";
% %                 uialert(fig,message,output);
% %                 app.ConnectSwitch.Value = "Disconnect";
% %             end
%         end
%         function output = disconnect(app)
%             if ~isequal(class(app.mainObj),'double')
%                 app.mainObj = deleteVirtualObject(app.mainObj);
%                 
%                 app.PanelMode.Enable = 'off';
%                 app.PanelFunction.Enable = 'off';
%                 app.FunctionPanel.Enable = 'off';
%             end
%             output = "FPGA disconnected";%заменить на реальную проверку
%         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
app.folder = MainApp.folder;
loadFromMemFileToApp(app);
%saveFromAppToMemFile(app);
            readFile(app);
%app.AddressnameListBox.Items = {'123','456'};
        end

        % Button pushed function: downloadtxtButton
        function downloadtxtButtonPushed(app, event)
            selectFile(app);
            readFile(app);


%             [file,path] = uigetfile('*.txt','Select ADDRESS.txt file','ADDRESS.txt');
%             if isequal(file,0)
%                 output = "не выбрана";
%             else
%                 output = [path file];
%             end
%             figure(app.FPGAUIFigure);
%             app.MainTablePathTextArea.Value = output;
%             app.AddressnameTextArea.Value = output;
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: readButton
        function readButtonPushed(app, event)
            try
                name = app.AddressnameEditField.Value;
                app.mainObj = readDataFromFPGA(app.mainObj,name);
                value = app.mainObj.lastRead;
                app.ReadValueEditField.Value = value; %num2strForPrint()
            catch ME
                fig = uifigure;
                uialert(fig,ME.message,'Reading failed');
            end
        end

        % Button pushed function: writeButton
        function writeButtonPushed(app, event)
            try
                wvalue = app.WriteValueEditField.Value;
                name = app.AddressnameEditField.Value;
                app.mainObj = writeDataToFPGA(app.mainObj,name,wvalue);
                %readButtonPushed(app, event);
                value = app.mainObj.lastRead;
                app.ReadValueEditField.Value = value; %num2strForPrint()
            catch ME
                fig = uifigure;
                uialert(fig,ME.message,'Writing failed');
            end
        end

        % Value changed function: AddressnameEditField
        function AddressnameEditFieldValueChanged(app, event)
            name = app.AddressnameEditField.Value;
            app.LabelType.Text = whatType(app.mainObj,name);
        end

        % Value changed function: InterfaceDropDown
        function InterfaceDropDownValueChanged(app, event)
            value = app.InterfaceDropDown.Value;
            if isequal(value,'JTAG')
                app.IPaddressEditField.Enable = 'off';
            elseif isequal(value,'Ethernet')
                app.IPaddressEditField.Enable = 'on';
            end
        end

        % Button pushed function: downloadButton
        function downloadButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select file with addresses for read','fpgaReadTable.xlsx');
            if isequal(file,0)
                error('выберите таблицу')
            else
                fpgaReadTable = [path file];
            end
            figure(app.FPGAUIFigure);
            
            t = readtable(fpgaReadTable,'ReadVariableNames',true);
            app.UITableFPGA.Data = t;
            app.UITableFPGA.ColumnName = t.Properties.VariableNames;
        end

        % Button pushed function: readvalueButton_2
        function readvalueButton_2Pushed(app, event)
            t = app.UITableFPGA.Data;
            for i = 1:height(app.UITableFPGA.Data)
                name = t{i,1};
                name = name{1};
                type = whatType(app.mainObj,name);
                t{i,2}={type};
                app.mainObj = readDataFromFPGA(app.mainObj,name);
                t{i,3}={app.mainObj.lastRead};
            end
            app.UITableFPGA.Data = t;
        end

        % Button pushed function: WriteEEPROMButton
        function WriteEEPROMButtonPushed(app, event)
            try
                wvalue = app.WriteEepromEditField.Value;
                name = app.AddressnameEditField.Value;
                app.mainObj = writeDataToFPGAeeprom(app.mainObj,name,wvalue);
                %readButtonPushed(app, event);
                value = app.mainObj.lastRead;
                app.ReadValueEditField.Value = value; %num2strForPrint()
            catch ME
                fig = uifigure;
                uialert(fig,ME.message,'Writing failed');
            end
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select table for autoFunction','FPGAautoFunction.xlsx');
            if isequal(file,0)
                output = "";
            else
                output = [path file];
            end
            figure(app.FPGAUIFigure);
            app.tableAutoFunctionPath = output;
            app.AddtableforautotestsTextArea.Value = output;

            %app.tableAutoFunction = readtable(app.tableAutoFunctionPath,...
            %                     'Sheet','Sample','ReadVariableNames',true);
        end

        % Button pushed function: runtestButton
        function runtestButtonPushed(app, event)
            app.tableAutoFunction= readtable(app.tableAutoFunctionPath,...
                    'Sheet','Sample','ReadRowNames',false,'ReadVariableNames',true);
                strForWaveform = app.TextforfileEditField.Value;%app.osa.waveformFile;%%%
                app.tableName = [createFilename(strForWaveform,now) '.xlsx'];
                  varNames = app.tableAutoFunction.Properties.VariableNames;
                    [logic, index] = ismember('timecodeHHMMSS',varNames);

                if ~isempty(app.switchOsaEdfa)
                    app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                end
                %pause(pauseBeforeStart);
                for i = 1:height(app.tableAutoFunction)
                    newRow = {'Номер измерения',i};
                    appendMainTable(app.callingApp,newRow);

                    enableWarningLaser(app.callingApp);
                app.mainObj = turnOnOffDiodes(app.mainObj,'on');
                addStrOutput(app.callingApp,'Диоды включены');
                %pause(1)
                    for j = 3:index-1
                        FPGAname = varNames{j};
                        app.mainObj = writeDataToFPGA(app.mainObj,FPGAname);
                        app.tableAutoFunction{i,FPGAname} = str2double(app.mainObj.lastRead);
                        newRow = {FPGAname,str2double(app.mainObj.lastRead)};
                        appendMainTable(app.callingApp,newRow);
                        pause(0.5);
                    end
                    pause(app.tableAutoFunction{i,'XtimeDelayS'});
                    addStrOutput(app.callingApp,'Ожидание после включения диодов');
                
app.tableAutoFunction{i,'timecodeHHMMSS'} = str2double(datestr(app.osa.time(end), 'HHMMSS'));
newRow = {'timecodeHHMMSS',str2double(datestr(app.osa.time(end), 'HHMMSS'))};
                    appendMainTable(app.callingApp,newRow);

                    for j = (index+1):width(app.tableAutoFunction)
                        FPGAname = varNames{j};
                        app.mainObj = readDataFromFPGA(app.mainObj,FPGAname);
                        app.tableAutoFunction{i,FPGAname} = str2double(app.mainObj.lastRead);
                        newRow = {FPGAname,str2double(app.mainObj.lastRead)};
                        appendMainTable(app.callingApp,newRow);
                        pause(0.5);
                    end
                    writetable(app.tableAutoFunction,[app.folder app.tableName],'AutoFitWidth',false);
                    pause(app.tableAutoFunction{i,'YtimeDelayS'});
                    addStrOutput(app.callingApp,'Ожидание после выключения диодов');
                end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.EditField.Value = ['b ' app.AddressnameListBox.Value];
        end

        % Value changed function: AddressnameListBox
        function AddressnameListBoxValueChanged(app, event)
            value = app.AddressnameListBox.Value;
            
        end

        % Value changed function: UnlockWritetoEEPROMfunctionCheckBox
        function UnlockWritetoEEPROMfunctionCheckBoxValueChanged(app, event)
            value = app.UnlockWritetoEEPROMfunctionCheckBox.Value;
            if value
                prompt = {'Enter admin password'};
                dlgtitle = 'Password';
                dims = [1 35];
                inputPassword = inputdlg(prompt,dlgtitle,dims);
                if ~isempty(inputPassword) && isequal(inputPassword,{app.password})
                    app.AdminFunctionPanel.Enable = 'on';
                else
                    app.UnlockWritetoEEPROMfunctionCheckBox.Value = false;
                    addStrOutput(app.callingApp,'Wrong or empty password');
                end
            else
                app.AdminFunctionPanel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FPGAUIFigure = uifigure('Visible', 'off');
            app.FPGAUIFigure.Name = 'FPGA';
            app.FPGAUIFigure.Icon = 'gu_device.png';
            app.FPGALabel = uilabel(app.FPGAUIFigure);
            app.FPGALabel.Text = 'FPGA';
            app.FunctionPanel = uipanel(app.FPGAUIFigure);
            app.ReadValueEditField = uieditfield(app.FunctionPanel, 'text');
            app.ReadValueEditField.Editable = 'off';
            app.WriteValueEditField = uieditfield(app.FunctionPanel, 'text');
            app.readButton = uibutton(app.FunctionPanel, 'push');
            app.readButton.ButtonPushedFcn = createCallbackFcn(app, @readButtonPushed, true);
            app.readButton.Text = 'read';
            app.writeButton = uibutton(app.FunctionPanel, 'push');
            app.writeButton.ButtonPushedFcn = createCallbackFcn(app, @writeButtonPushed, true);
            app.writeButton.Text = 'write';
            app.AddressnameListBoxLabel = uilabel(app.FunctionPanel);
            app.AddressnameListBoxLabel.Text = 'Address name';
            app.AddressnameListBox = uilistbox(app.FunctionPanel);
            app.AddressnameListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 1', 'Item 2', 'Item 3', 'Item 4'};
            app.AddressnameListBox.ValueChangedFcn = createCallbackFcn(app, @AddressnameListBoxValueChanged, true);
            app.UnlockWritetoEEPROMfunctionCheckBox = uicheckbox(app.FunctionPanel);
            app.UnlockWritetoEEPROMfunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnlockWritetoEEPROMfunctionCheckBoxValueChanged, true);
            app.UnlockWritetoEEPROMfunctionCheckBox.Text = 'Unlock Write to EEPROM function';
            app.PanelFunction = uipanel(app.FPGAUIFigure);
            app.PanelFunction.Enable = 'off';
            app.Lamp = uilamp(app.PanelFunction);
            app.TurndiodesSwitch = uiswitch(app.PanelFunction, 'slider');
            app.TurndiodesSwitch.Items = {'off', 'on'};
            app.TurndiodesSwitch.Value = 'off';
            app.writenexttablevalueButton = uibutton(app.PanelFunction, 'push');
            app.writenexttablevalueButton.Text = 'write next table value';
            app.TurndiodesLabel = uilabel(app.PanelFunction);
            app.TurndiodesLabel.Text = 'Turn diodes';
            app.TablevaluewrittenEditFieldLabel = uilabel(app.PanelFunction);
            app.TablevaluewrittenEditFieldLabel.Text = 'Table value written';
            app.TablevaluewrittenEditField = uieditfield(app.PanelFunction, 'text');
            app.onaddressEditFieldLabel = uilabel(app.PanelFunction);
            app.onaddressEditFieldLabel.Text = 'on address';
            app.onaddressEditField = uieditfield(app.PanelFunction, 'text');
            app.ModeDropDownLabel = uilabel(app.PanelFunction);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.PanelFunction);
            app.ModeDropDown.Items = {'0 - ручная установка тока', '1 - компенсация ЛД по мощности', '2 - компенсация по выходной мощности', '3 - компенсация по коэф.усиления'};
            app.ModeDropDown.Value = '0 - ручная установка тока';
            app.PanelMode = uipanel(app.FPGAUIFigure);
            app.PanelMode.Enable = 'off';
            app.UITableFPGA = uitable(app.PanelMode);
            app.UITableFPGA.ColumnName = {'Column 1'; 'Column 2'};
            app.UITableFPGA.RowName = {};
            app.downloadButton = uibutton(app.PanelMode, 'push');
            app.downloadButton.ButtonPushedFcn = createCallbackFcn(app, @downloadButtonPushed, true);
            app.downloadButton.Text = 'download';
            app.readvalueButton_2 = uibutton(app.PanelMode, 'push');
            app.readvalueButton_2.ButtonPushedFcn = createCallbackFcn(app, @readvalueButton_2Pushed, true);
            app.readvalueButton_2.Text = 'read value';
            app.MiniveryslotLabel = uilabel(app.PanelMode);
            app.MiniveryslotLabel.Text = 'Mini veryslot';
            app.ConnectPanel = uipanel(app.FPGAUIFigure);
            app.ConnectSwitch = uiswitch(app.ConnectPanel, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            app.InterfaceDropDownLabel = uilabel(app.ConnectPanel);
            app.InterfaceDropDownLabel.Text = 'Interface';
            app.InterfaceDropDown = uidropdown(app.ConnectPanel);
            app.InterfaceDropDown.Items = {'JTAG'};
            app.InterfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @InterfaceDropDownValueChanged, true);
            app.InterfaceDropDown.Value = 'JTAG';
            app.AddresstableTextAreaLabel = uilabel(app.ConnectPanel);
            app.AddresstableTextAreaLabel.Text = 'Address table:';
            app.MainTablePathTextArea = uitextarea(app.ConnectPanel);
            app.MainTablePathTextArea.Value = {'ADDRESS.txt'};
            app.downloadtxtButton = uibutton(app.ConnectPanel, 'push');
            app.downloadtxtButton.ButtonPushedFcn = createCallbackFcn(app, @downloadtxtButtonPushed, true);
            app.downloadtxtButton.Icon = 'gu_download_txt.png';
            app.downloadtxtButton.Tooltip = {'download txt'};
            app.downloadtxtButton.Text = '';
            app.Panel = uipanel(app.FPGAUIFigure);
            app.AddtableforautotestsTextAreaLabel = uilabel(app.Panel);
            app.AddtableforautotestsTextAreaLabel.Text = 'Add table for auto tests';
            app.AddtableforautotestsTextArea = uitextarea(app.Panel);
            app.AddtableforautotestsTextArea.Value = {'FPGAautoFunction.xlsx'};
            app.downloadButton_2 = uibutton(app.Panel, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Text = '';
            app.TextforfileEditFieldLabel = uilabel(app.Panel);
            app.TextforfileEditFieldLabel.Text = 'Text for file';
            app.TextforfileEditField = uieditfield(app.Panel, 'text');
            app.TextforfileEditField.Value = 'edfaN1_test';
            app.runtestButton = uibutton(app.Panel, 'push');
            app.runtestButton.ButtonPushedFcn = createCallbackFcn(app, @runtestButtonPushed, true);
            app.runtestButton.Text = 'run test';
            app.IPaddressLabel = uilabel(app.FPGAUIFigure);
            app.IPaddressLabel.Text = 'IP address';
            app.IPaddressEditField = uieditfield(app.FPGAUIFigure, 'text');
            app.IPaddressEditField.Enable = 'off';
            app.IPaddressEditField.Value = '192.168.111.522';
            app.AddressnameEditFieldLabel = uilabel(app.FPGAUIFigure);
            app.AddressnameEditFieldLabel.Text = 'Address name';
            app.AddressnameEditField = uieditfield(app.FPGAUIFigure, 'text');
            app.AddressnameEditField.ValueChangedFcn = createCallbackFcn(app, @AddressnameEditFieldValueChanged, true);
            app.AddressnameEditField.Value = 'CC_TEST';
            app.LabelType = uilabel(app.FPGAUIFigure);
            app.LabelType.Text = 'HEX';
            app.AdminFunctionPanel = uipanel(app.FPGAUIFigure);
            app.AdminFunctionPanel.Enable = 'off';
            app.WriteEepromEditField = uieditfield(app.AdminFunctionPanel, 'text');
            app.WriteEEPROMButton = uibutton(app.AdminFunctionPanel, 'push');
            app.WriteEEPROMButton.ButtonPushedFcn = createCallbackFcn(app, @WriteEEPROMButtonPushed, true);
            app.WriteEEPROMButton.Text = 'Write EEPROM';
            app.EditFieldLabel = uilabel(app.FPGAUIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.FPGAUIFigure, 'text');
            app.AddressnameTextAreaLabel = uilabel(app.FPGAUIFigure);
            app.AddressnameTextAreaLabel.Text = 'Address name';
            app.AddressnameTextArea = uitextarea(app.FPGAUIFigure);
            app.AddressnameTextArea.Value = {'CC_TEST'};
            app.Button = uibutton(app.FPGAUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.getadminaccessButton = uibutton(app.FPGAUIFigure, 'push');
            app.getadminaccessButton.Text = 'get admin access';
            app.DataformatEditFieldLabel = uilabel(app.FPGAUIFigure);
            app.DataformatEditFieldLabel.Text = 'Data format';
            app.DataformatEditField = uieditfield(app.FPGAUIFigure, 'text');
            app.DataformatEditField.Editable = 'off';
            app.DataformatEditField.Tooltip = {'Data format'};
            % Show the figure after all components are created
            app.FPGAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_FPGA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FPGAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FPGAUIFigure)
        end
    end
end