classdef appAddDevice_OSAyokogawa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSAYokogawaUIFigure            matlab.ui.Figure
        FunctionTree                   matlab.ui.container.Tree
        BasicfunctionsNode             matlab.ui.container.TreeNode
        WritereferencetraceNode        matlab.ui.container.TreeNode
        WritewaveformNode              matlab.ui.container.TreeNode
        ReadwaveformNode               matlab.ui.container.TreeNode
        ReadanalysisEDFANFNode         matlab.ui.container.TreeNode
        ReadpowerNode                  matlab.ui.container.TreeNode
        AutofunctionsNode              matlab.ui.container.TreeNode
        WritewaveformloopEDFANFNode    matlab.ui.container.TreeNode
        CustomfunctionsNode            matlab.ui.container.TreeNode
        WriterefpowerNode              matlab.ui.container.TreeNode
        WritewaveformEDFANFNode        matlab.ui.container.TreeNode
        ConnectSwitch                  matlab.ui.control.Switch
        Panel_2                        matlab.ui.container.Panel
        TextforfileEditField           matlab.ui.control.EditField
        TextforfileEditFieldLabel      matlab.ui.control.Label
        AddtableforautotestsTextArea   matlab.ui.control.TextArea
        AddtableforautotestsTextAreaLabel  matlab.ui.control.Label
        ConnectPanel                   matlab.ui.container.Panel
        WaveformtraceDropDown          matlab.ui.control.DropDown
        WaveformtraceDropDownLabel     matlab.ui.control.Label
        ReferencetraceDropDown         matlab.ui.control.DropDown
        ReferencetraceDropDownLabel    matlab.ui.control.Label
        TimeoutEditField               matlab.ui.control.NumericEditField
        TimeoutEditFieldLabel          matlab.ui.control.Label
        IPaddressEditField             matlab.ui.control.EditField
        IPaddressEditFieldLabel        matlab.ui.control.Label
        FunctionPanel                  matlab.ui.container.Panel
        dBmLabel_2                     matlab.ui.control.Label
        CurrentPowerEditField          matlab.ui.control.NumericEditField
        Tip1Image                      matlab.ui.control.Image
        SelectfunctionsLabel           matlab.ui.control.Label
        readpowerCheckBox              matlab.ui.control.CheckBox
        readandsaveanalysisEDFANFCheckBox  matlab.ui.control.CheckBox
        savewaveformCheckBox           matlab.ui.control.CheckBox
        plotwaveformCheckBox           matlab.ui.control.CheckBox
        readwaveformCheckBox           matlab.ui.control.CheckBox
        writewaveformCheckBox          matlab.ui.control.CheckBox
        ActivetraceDropDown            matlab.ui.control.DropDown
        ActivetraceDropDownLabel       matlab.ui.control.Label
        UsertextEditField              matlab.ui.control.EditField
        UsertextEditFieldLabel         matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        OffsetsPanel                   matlab.ui.container.Panel
        OutoffsetEditField             matlab.ui.control.EditField
        OutoffsetEditFieldLabel        matlab.ui.control.Label
        InoffsetEditField              matlab.ui.control.EditField
        InoffsetEditFieldLabel         matlab.ui.control.Label
        PoweroffsetEditField           matlab.ui.control.EditField
        PoweroffsetEditFieldLabel      matlab.ui.control.Label
        dBLabel_4                      matlab.ui.control.Label
        dBLabel_3                      matlab.ui.control.Label
        dBLabel_2                      matlab.ui.control.Label
        CheckBoxOutOffset              matlab.ui.control.CheckBox
        CheckBoxInOffset               matlab.ui.control.CheckBox
        CheckBoxPowerOffset            matlab.ui.control.CheckBox
        ApplyoffsetsButton             matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        StopwavelengthEditField        matlab.ui.control.EditField
        StopwavelengthEditFieldLabel   matlab.ui.control.Label
        LevelscaleEditField            matlab.ui.control.EditField
        LevelscaleEditFieldLabel       matlab.ui.control.Label
        ReflevelEditField              matlab.ui.control.EditField
        ReflevelEditFieldLabel         matlab.ui.control.Label
        ResolutionEditField            matlab.ui.control.EditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        SensemodeEditField             matlab.ui.control.EditField
        SensemodeEditFieldLabel        matlab.ui.control.Label
        StartwavelengthEditField       matlab.ui.control.EditField
        StartwavelengthEditFieldLabel  matlab.ui.control.Label
        dBDLabel                       matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        CheckBoxLevelScale             matlab.ui.control.CheckBox
        CheckBoxReferenceLevel         matlab.ui.control.CheckBox
        CheckBoxResolution             matlab.ui.control.CheckBox
        CheckBoxSenseMode              matlab.ui.control.CheckBox
        CheckBoxStopWl                 matlab.ui.control.CheckBox
        CheckBoxStartWl                matlab.ui.control.CheckBox
        ApplysettingsButton            matlab.ui.control.Button
        nmLabel_3                      matlab.ui.control.Label
        nmLabel_2                      matlab.ui.control.Label
        nmLabel                        matlab.ui.control.Label
        OpticalspectrumanalyzerYokogawaLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        callingApp
        folder
    end
    properties (Access = public)
        mainObj
    end
    
    methods (Access = private)
        function connect(app)
            try
                app.mainObj = [];
                appStruct = struct();
                appStruct.folder = app.folder;
                appStruct.IPaddress = app.IPaddressEditField.Value;
                appStruct.Timeout = app.TimeoutEditField.Value;
                appStruct.Referencetrace = app.ReferencetraceDropDown.Value;
                appStruct.Waveformtrace = app.WaveformtraceDropDown.Value;
                app.mainObj = Device_OSAyokogawa(appStruct);
                output = "OSA connected";
                addStrOutput(app.callingApp,output);
                app.ConnectPanel.Enable = 'off';
                app.FunctionPanel.Enable = 'on';
                app.OffsetsPanel.Enable = 'on';
                app.SettingsPanel.Enable = 'on';
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
                output = "Wrong connection";
                addStrOutput(app.callingApp,output);
                app.ConnectSwitch.Value = "Disconnect";
                app.ConnectPanel.Enable = 'on';
                app.FunctionPanel.Enable = 'off';
                app.OffsetsPanel.Enable = 'off';
                app.SettingsPanel.Enable = 'off';
            end
        end
        function disconnect(app)
            if ~isequal(class(app.mainObj),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = 'OSA disconnected';
                addStrOutput(app.callingApp,output);
            end
            app.ConnectPanel.Enable = 'on';
            app.FunctionPanel.Enable = 'off';
            app.OffsetsPanel.Enable = 'off';
            app.SettingsPanel.Enable = 'off';
        end
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appAddDevice_OSAyokogawa');
            app.IPaddressEditField.Value = appAddDevice_OSAyokogawa.IPaddress;
            app.TimeoutEditField.Value = appAddDevice_OSAyokogawa.Timeout;
            app.ReferencetraceDropDown.Value = appAddDevice_OSAyokogawa.Referencetrace;
            app.WaveformtraceDropDown.Value = appAddDevice_OSAyokogawa.Waveformtrace;
            app.CheckBoxStartWl.Value = appAddDevice_OSAyokogawa.startWavelengthLogic;
            app.StartwavelengthEditField.Value = appAddDevice_OSAyokogawa.startWavelengthValue;
            app.CheckBoxStopWl.Value = appAddDevice_OSAyokogawa.stopWavelengthLogic;
            app.StopwavelengthEditField.Value = appAddDevice_OSAyokogawa.stopWavelengthValue;
            app.CheckBoxSenseMode.Value = appAddDevice_OSAyokogawa.senseModeLogic;
            app.SensemodeEditField.Value = appAddDevice_OSAyokogawa.senseModeValue;
            app.CheckBoxResolution.Value = appAddDevice_OSAyokogawa.senseBandwidthLogic;
            app.ResolutionEditField.Value = appAddDevice_OSAyokogawa.senseBandwidthValue;
            app.CheckBoxReferenceLevel.Value = appAddDevice_OSAyokogawa.referenceLevelLogic;
            app.ReflevelEditField.Value = appAddDevice_OSAyokogawa.referenceLevelValue;
            app.CheckBoxLevelScale.Value = appAddDevice_OSAyokogawa.levelScaleLogic;
            app.LevelscaleEditField.Value = appAddDevice_OSAyokogawa.levelScaleValue;
            app.OutoffsetEditField.Value = appAddDevice_OSAyokogawa.Outoffset;
            app.InoffsetEditField.Value = appAddDevice_OSAyokogawa.Inoffset;
            app.PoweroffsetEditField.Value = appAddDevice_OSAyokogawa.Poweroffset;
        end
        function saveFromAppToMemFile(app)
            appAddDevice_OSAyokogawa.IPaddress = app.IPaddressEditField.Value;
            appAddDevice_OSAyokogawa.Timeout = app.TimeoutEditField.Value;
            appAddDevice_OSAyokogawa.Referencetrace = app.ReferencetraceDropDown.Value;
            appAddDevice_OSAyokogawa.Waveformtrace = app.WaveformtraceDropDown.Value;
            appAddDevice_OSAyokogawa.startWavelengthLogic = app.CheckBoxStartWl.Value;
            appAddDevice_OSAyokogawa.startWavelengthValue = app.StartwavelengthEditField.Value;
            appAddDevice_OSAyokogawa.stopWavelengthLogic = app.CheckBoxStopWl.Value;
            appAddDevice_OSAyokogawa.stopWavelengthValue = app.StopwavelengthEditField.Value;
            appAddDevice_OSAyokogawa.senseModeLogic = app.CheckBoxSenseMode.Value;
            appAddDevice_OSAyokogawa.senseModeValue = app.SensemodeEditField.Value;
            appAddDevice_OSAyokogawa.senseBandwidthLogic = app.CheckBoxResolution.Value;
            appAddDevice_OSAyokogawa.senseBandwidthValue = app.ResolutionEditField.Value;
            appAddDevice_OSAyokogawa.referenceLevelLogic = app.CheckBoxReferenceLevel.Value;
            appAddDevice_OSAyokogawa.referenceLevelValue = app.ReflevelEditField.Value;
            appAddDevice_OSAyokogawa.levelScaleLogic = app.CheckBoxLevelScale.Value;
            appAddDevice_OSAyokogawa.levelScaleValue = app.LevelscaleEditField.Value;
            appAddDevice_OSAyokogawa.Outoffset = app.OutoffsetEditField.Value;
            appAddDevice_OSAyokogawa.Inoffset = app.InoffsetEditField.Value;
            appAddDevice_OSAyokogawa.Poweroffset = app.PoweroffsetEditField.Value;
            save('TheGreatUniterMemory.mat','appAddDevice_OSAyokogawa','-append');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
        end

        % Button pushed function: ApplyoffsetsButton
        function ApplyoffsetsButtonPushed(app, event)
            if app.CheckBoxPowerOffset.Value
                powerOffset = app.PoweroffsetEditField.Value;
                applyOffsetSettings(app.mainObj,powerOffset,'power');
            end
            if app.CheckBoxInOffset.Value
                inOffset = app.InoffsetEditField.Value;
                applyOffsetSettings(app.mainObj,inOffset,'in');
            end
            if app.CheckBoxOutOffset.Value
                outOffset = app.OutoffsetEditField.Value;
                applyOffsetSettings(app.mainObj,outOffset,'out');
            end
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                connect(app);
            elseif value == "Disconnect"
                disconnect(app);
            end
        end

        % Button pushed function: ApplysettingsButton
        function ApplysettingsButtonPushed(app, event)
            appStructSettings.startWavelengthLogic = app.CheckBoxStartWl.Value;
            appStructSettings.startWavelengthValue = app.StartwavelengthEditField.Value;
            appStructSettings.stopWavelengthLogic = app.CheckBoxStopWl.Value;
            appStructSettings.stopWavelengthValue = app.StopwavelengthEditField.Value;
            appStructSettings.senseModeLogic = app.CheckBoxSenseMode.Value;
            appStructSettings.senseModeValue = app.SensemodeEditField.Value;
            appStructSettings.senseBandwidthLogic = app.CheckBoxResolution.Value;
            appStructSettings.senseBandwidthValue = app.ResolutionEditField.Value;
            appStructSettings.referenceLevelLogic = app.CheckBoxReferenceLevel.Value;
            appStructSettings.referenceLevelValue = app.ReflevelEditField.Value;
            appStructSettings.levelScaleLogic = app.CheckBoxLevelScale.Value;
            appStructSettings.levelScaleValue = app.LevelscaleEditField.Value;
            app.mainObj = applySettings(app.mainObj,appStructSettings);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            userText = app.UsertextEditField.Value;
            activeTrace = app.ActivetraceDropDown.Value;
            if app.writewaveformCheckBox.Value
                writeWaveform(app.mainObj,activeTrace);
                output = ['OSA. Write waveform on trace ',activeTrace];
                addStrOutput(app.callingApp,output);
            end
            if app.readwaveformCheckBox.Value
                app.mainObj = readWaveform(app.mainObj,activeTrace);
                output = ['OSA. Read waveform on trace ',activeTrace];
                addStrOutput(app.callingApp,output);
                if app.plotwaveformCheckBox.Value
                    output = 'OSA. Plot function has not exist yet';
                    addStrOutput(app.callingApp,output);
                end
                if app.savewaveformCheckBox.Value
                    saveWaveform(app.mainObj,userText);
                    output = 'OSA. Save waveform';
                    addStrOutput(app.callingApp,output);
                end
            end
            if app.readandsaveanalysisEDFANFCheckBox.Value
                app.mainObj = readAnalysisEDFANF(app.mainObj,activeTrace);
                saveAnalysisEDFANF(app.mainObj,userText);
                output = ['OSA. Read and save analysis EDFA-NF on trace ',activeTrace];
                addStrOutput(app.callingApp,output);
            end
            if app.readpowerCheckBox.Value
                power = readPower(app.mainObj,activeTrace);
                app.CurrentPowerEditField.Value = power;
                str = num2strForPrint(power);
                output = ['OSA. Power on trace ',activeTrace,' = ',str,' dBm'];
                addStrOutput(app.callingApp,output);
            end

            
            %             value = app.FunctionTree.SelectedNodes;
%             if value == app.WritereferencetraceNode
%                 if ~isempty(app.switchOsaEdfa)
%                     app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
%                 end
%                 writeReferenceTrace(app.osa);
%                 output = 'Write reference waveform: success';
%             elseif value == app.WritewaveformNode
%                 if ~isempty(app.switchOsaEdfa)
%                     app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
%                 end
%                 writeWaveformTrace(app.osa);
%                 output = 'Write waveform: success';
%             elseif value == app.ReadwaveformNode
%                 app.osa = readWaveform(app.osa);
%                 strForWaveform = '';%app.osa.waveformFile;%%%
%                 app.osa = saveWaveformToPC(app.osa,strForWaveform);
%                 plotWaveformYoko(app.osa,app.callingApp);
%                 output = 'Read waveform: success';
%             elseif value == app.ReadanalysisEDFANFNode
%                 app.osa = readAnalysisEDFANF(app.osa);
%                 strForWaveform = '';
%                 saveAnalysisEDFANF(app.osa,strForWaveform);
%                 output = 'Read analysis EDFA-NF: success';
%             elseif value == app.ReadpowerNode
%                 app.osa = readPower(app.osa);
%                 savePower(app.osa);
%                 powerValue = app.osa.power(end);
%                 powerStr = num2strForPrint(powerValue);
%                 output = ['Read power = ' powerStr];
%             elseif value == app.WritewaveformEDFANFNode
%                 if ~isempty(app.switchOsaEdfa)
%                     app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
%                 end
%                 writeWaveformTrace(app.osa);
%                 output = 'Write waveform: success';
%                 addStrOutput(app.callingApp,output);
%                 app.osa = readWaveform(app.osa);
%                 strForWaveform = '';%app.osa.waveformFile;%%%
%                 app.osa = saveWaveformToPC(app.osa,strForWaveform);
%                 plotWaveformYoko(app.osa,app.callingApp);
%                 output = 'Read waveform: success';
%                 addStrOutput(app.callingApp,output);
%                 %                 strForWaveform = '';%app.osa.waveformFile;%%%
%                 %                 app.osa = saveWaveformToPC(app.osa,strForWaveform);
%                 %                 plotWaveformYoko(app.osa,app.callingApp);
%                 app.osa = readAnalysisEDFANF(app.osa);
%                 app.osa = readPower(app.osa);
%                 savePower(app.osa);
%                 strForWaveform = '';
%                 saveAnalysisEDFANF(app.osa,strForWaveform);
%                 newRow = {'Максимальный ШФ',app.osa.maxNF};
%                 appendMainTable(app.callingApp,newRow);
%                 newRow = {'Средний КУ',app.osa.meanGAIN};
%                 appendMainTable(app.callingApp,newRow);
%                 newRow = {'Нелинейность',app.osa.deltaGAIN};
%                 appendMainTable(app.callingApp,newRow);
%                 newRow = {'Выходная мощность, дБм',app.osa.power(end)};
%                 appendMainTable(app.callingApp,newRow);
%                 output = 'finished';
%             elseif value == app.WriterefpowerNode
%                 if ~isempty(app.switchOsaEdfa)
%                     app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
%                 end
%                 writeReferenceTrace(app.osa);
%                 app.osa = readAndSaveReference(app.osa);
%                 %app.osa = readPower(app.osa);
%                 output = num2str(readReferencePower(app.osa));
%             elseif value == app.WritewaveformloopEDFANFNode
%                 app.tableAutoFunction= readtable(app.tableAutoFunctionPath,...
%                     'Sheet','Sample','ReadRowNames',false,'ReadVariableNames',true);
%                 strForWaveform = app.TextforfileEditField.Value;%app.osa.waveformFile;%%%
%                 app.tableName = [createFilename(strForWaveform,now) '.xlsx'];
%                 if ~isempty(app.switchOsaEdfa)
%                     app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
%                 end
%                 for i = 1:height(app.tableAutoFunction)
%                     newRow = {'Номер измерения',i};
%                     appendMainTable(app.callingApp,newRow);
%                     writeWaveformTrace(app.osa);
%                     output = 'Write waveform: success';
%                     addStrOutput(app.callingApp,output);
%                     app.osa = readWaveform(app.osa);
%                     app.osa = saveWaveformToPC(app.osa,strForWaveform);
%                     plotWaveformYoko(app.osa,app.callingApp);
%                     output = 'Read waveform: success';
%                     addStrOutput(app.callingApp,output);
%                     app.osa = readAnalysisEDFANF(app.osa);
%                     app.osa = readPower(app.osa);
%                     app.tableAutoFunction{i,'powerDBm'} = app.osa.power(end);%{}
%                     %savePower(app.osa);%%%
%                     %strForWaveform = '';
%                     saveAnalysisEDFANF(app.osa,strForWaveform);
%                     newRow = {'Минимальный ШФ',app.osa.minNF};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'minNF'} = app.osa.minNF;
%                     newRow = {'Средний ШФ',app.osa.meanNF};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'meanNF'} = app.osa.meanNF;
%                     newRow = {'Максимальный ШФ',app.osa.maxNF};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'maxNF'} = app.osa.maxNF;
%                     newRow = {'Минимальный КУ',app.osa.minGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'minGain'} = app.osa.minGAIN;
%                     newRow = {'Средний КУ',app.osa.meanGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'meanGain'} = app.osa.meanGAIN;
%                     newRow = {'Максимальный КУ',app.osa.maxGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'maxGain'} = app.osa.maxGAIN;
%                     newRow = {'Нелинейность',app.osa.deltaGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'deltaGain'} = app.osa.deltaGAIN;
%                     %disp(string(createFilename('',app.osa.time(end))))
%                     %app.tableAutoFunction{i,'timecode'} = string(createFilename('',app.osa.time(end)));
%                     pause(app.tableAutoFunction{i,'timeDelayS'});
%                     writetable(app.tableAutoFunction,[app.folder app.tableName '.xlsx'],'AutoFitWidth',false);
%                 end
%                 
%                 output = 'finished';
%             elseif value == app.WritewffpgaloopEDFANFNode
%                 app.tableAutoFunction= readtable(app.tableAutoFunctionPath,...
%                     'Sheet','Sample','ReadRowNames',false,'ReadVariableNames',true);
%                 strForWaveform = app.TextforfileEditField.Value;%app.osa.waveformFile;%%%
%                 app.tableName = [createFilename(strForWaveform,now) '.xlsx'];
%                 if ~isempty(app.switchOsaEdfa)
%                     app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
%                 end
%                 for i = 1:height(app.tableAutoFunction)
%                     newRow = {'Номер измерения',i};
%                     appendMainTable(app.callingApp,newRow);
%                     writeWaveformTrace(app.osa);
%                     output = 'Write waveform: success';
%                     addStrOutput(app.callingApp,output);
%                     app.osa = readWaveform(app.osa);
%                     app.osa = saveWaveformToPC(app.osa,strForWaveform);
%                     plotWaveformYoko(app.osa,app.callingApp);
%                     output = 'Read waveform: success';
%                     addStrOutput(app.callingApp,output);
%                     app.osa = readAnalysisEDFANF(app.osa);
%                     app.osa = readPower(app.osa);
%                     app.tableAutoFunction{i,'powerDBm'} = app.osa.power(end);%{}
%                     %savePower(app.osa);%%%
%                     %strForWaveform = '';
%                     saveAnalysisEDFANF(app.osa,strForWaveform);
% %                     newRow = {'Минимальный ШФ',app.osa.minNF};
% %                     appendMainTable(app.callingApp,newRow);
% %                     app.tableAutoFunction{i,'minNF'} = app.osa.minNF;
% %                     newRow = {'Средний ШФ',app.osa.meanNF};
% %                     appendMainTable(app.callingApp,newRow);
% %                     app.tableAutoFunction{i,'meanNF'} = app.osa.meanNF;
%                     newRow = {'Максимальный ШФ',app.osa.maxNF};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'maxNF'} = app.osa.maxNF;
% %                     newRow = {'Минимальный КУ',app.osa.minGAIN};
% %                     appendMainTable(app.callingApp,newRow);
% %                     app.tableAutoFunction{i,'minGain'} = app.osa.minGAIN;
% %                     newRow = {'Средний КУ',app.osa.meanGAIN};
% %                     appendMainTable(app.callingApp,newRow);
% %                     app.tableAutoFunction{i,'meanGain'} = app.osa.meanGAIN;
% %                     newRow = {'Максимальный КУ',app.osa.maxGAIN};
% %                     appendMainTable(app.callingApp,newRow);
% %                     app.tableAutoFunction{i,'maxGain'} = app.osa.maxGAIN;
% %                     newRow = {'Нелинейность',app.osa.deltaGAIN};
% %                     appendMainTable(app.callingApp,newRow);
% %                     app.tableAutoFunction{i,'deltaGain'} = app.osa.deltaGAIN;
%                     app.tableAutoFunction{i,'timecodeHHMMSS'} = str2double(datestr(app.osa.time(end), 'HHMMSS'));
%                     newRow = {'timecodeHHMMSS',str2double(datestr(app.osa.time(end), 'HHMMSS'))};
%                     appendMainTable(app.callingApp,newRow);
% 
%                     varNames = app.tableAutoFunction.Properties.VariableNames;
%                     [logic, index] = ismember('timecodeHHMMSS',varNames);
% 
%                     for j = (index+1):width(app.tableAutoFunction)
%                         FPGAname = varNames{j};
%                         app.fpga = readDataFromFPGA(app.fpga,FPGAname);
%                         app.tableAutoFunction{i,FPGAname} = str2double(app.fpga.lastRead);
%                         newRow = {FPGAname,str2double(app.fpga.lastRead)};
%                         appendMainTable(app.callingApp,newRow);
%                         pause(0.5);
%                     end
%                     writetable(app.tableAutoFunction,[app.folder app.tableName '.xlsx'],'AutoFitWidth',false);
%                     pause(app.tableAutoFunction{i,'timeDelayS'});
%                     
%                 end
%                 output = 'finished';
%             end
%             addStrOutput(app.callingApp,output);
        end

        % Close request function: OSAYokogawaUIFigure
        function OSAYokogawaUIFigureCloseRequest(app, event)
            fig = app.OSAYokogawaUIFigure;
            closeRequestWarning(app,fig);
        end

        % Value changed function: readwaveformCheckBox
        function readwaveformCheckBoxValueChanged(app, event)
            value = app.readwaveformCheckBox.Value;
            if value
                app.plotwaveformCheckBox.Enable = 'on';
                app.savewaveformCheckBox.Enable = 'on';
            else
                app.plotwaveformCheckBox.Enable = 'off';
                app.savewaveformCheckBox.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSAYokogawaUIFigure = uifigure('Visible', 'off');
            app.OSAYokogawaUIFigure.Name = 'OSA Yokogawa';
            app.OSAYokogawaUIFigure.Icon = 'gu_device.png';
            app.OSAYokogawaUIFigure.CloseRequestFcn = createCallbackFcn(app, @OSAYokogawaUIFigureCloseRequest, true);
            app.OpticalspectrumanalyzerYokogawaLabel = uilabel(app.OSAYokogawaUIFigure);
            app.OpticalspectrumanalyzerYokogawaLabel.Text = 'Optical spectrum analyzer (Yokogawa)';
            app.SettingsPanel = uipanel(app.OSAYokogawaUIFigure);
            app.SettingsPanel.Enable = 'off';
            app.nmLabel = uilabel(app.SettingsPanel);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.SettingsPanel);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.SettingsPanel);
            app.nmLabel_3.Text = 'nm';
            app.ApplysettingsButton = uibutton(app.SettingsPanel, 'push');
            app.ApplysettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplysettingsButtonPushed, true);
            app.ApplysettingsButton.Text = 'Apply settings';
            app.CheckBoxStartWl = uicheckbox(app.SettingsPanel);
            app.CheckBoxStartWl.Text = '';
            app.CheckBoxStartWl.Value = true;
            app.CheckBoxStopWl = uicheckbox(app.SettingsPanel);
            app.CheckBoxStopWl.Text = '';
            app.CheckBoxStopWl.Value = true;
            app.CheckBoxSenseMode = uicheckbox(app.SettingsPanel);
            app.CheckBoxSenseMode.Text = '';
            app.CheckBoxSenseMode.Value = true;
            app.CheckBoxResolution = uicheckbox(app.SettingsPanel);
            app.CheckBoxResolution.Text = '';
            app.CheckBoxResolution.Value = true;
            app.CheckBoxReferenceLevel = uicheckbox(app.SettingsPanel);
            app.CheckBoxReferenceLevel.Text = '';
            app.CheckBoxReferenceLevel.Value = true;
            app.CheckBoxLevelScale = uicheckbox(app.SettingsPanel);
            app.CheckBoxLevelScale.Text = '';
            app.CheckBoxLevelScale.Value = true;
            app.dBmLabel = uilabel(app.SettingsPanel);
            app.dBmLabel.Text = 'dBm';
            app.dBDLabel = uilabel(app.SettingsPanel);
            app.dBDLabel.Text = 'dB/D';
            app.StartwavelengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.StartwavelengthEditFieldLabel.Text = 'Start wavelength';
            app.StartwavelengthEditField = uieditfield(app.SettingsPanel, 'text');
            app.StartwavelengthEditField.Value = '1547';
            app.SensemodeEditFieldLabel = uilabel(app.SettingsPanel);
            app.SensemodeEditFieldLabel.Text = 'Sense mode';
            app.SensemodeEditField = uieditfield(app.SettingsPanel, 'text');
            app.SensemodeEditField.Value = 'HIGH1';
            app.ResolutionEditFieldLabel = uilabel(app.SettingsPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.SettingsPanel, 'text');
            app.ResolutionEditField.Value = '0.1';
            app.ReflevelEditFieldLabel = uilabel(app.SettingsPanel);
            app.ReflevelEditFieldLabel.Text = 'Ref level';
            app.ReflevelEditField = uieditfield(app.SettingsPanel, 'text');
            app.ReflevelEditField.Value = '3';
            app.LevelscaleEditFieldLabel = uilabel(app.SettingsPanel);
            app.LevelscaleEditFieldLabel.Text = 'Level scale';
            app.LevelscaleEditField = uieditfield(app.SettingsPanel, 'text');
            app.LevelscaleEditField.Value = '10';
            app.StopwavelengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.StopwavelengthEditFieldLabel.Text = 'Stop wavelength';
            app.StopwavelengthEditField = uieditfield(app.SettingsPanel, 'text');
            app.StopwavelengthEditField.Value = '1560';
            app.OffsetsPanel = uipanel(app.OSAYokogawaUIFigure);
            app.OffsetsPanel.Enable = 'off';
            app.ApplyoffsetsButton = uibutton(app.OffsetsPanel, 'push');
            app.ApplyoffsetsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyoffsetsButtonPushed, true);
            app.ApplyoffsetsButton.Text = 'Apply offsets';
            app.CheckBoxPowerOffset = uicheckbox(app.OffsetsPanel);
            app.CheckBoxPowerOffset.Text = '';
            app.CheckBoxPowerOffset.Value = true;
            app.CheckBoxInOffset = uicheckbox(app.OffsetsPanel);
            app.CheckBoxInOffset.Text = '';
            app.CheckBoxInOffset.Value = true;
            app.CheckBoxOutOffset = uicheckbox(app.OffsetsPanel);
            app.CheckBoxOutOffset.Text = '';
            app.CheckBoxOutOffset.Value = true;
            app.dBLabel_2 = uilabel(app.OffsetsPanel);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.OffsetsPanel);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.OffsetsPanel);
            app.dBLabel_4.Text = 'dB';
            app.PoweroffsetEditFieldLabel = uilabel(app.OffsetsPanel);
            app.PoweroffsetEditFieldLabel.Text = 'Power offset';
            app.PoweroffsetEditField = uieditfield(app.OffsetsPanel, 'text');
            app.PoweroffsetEditField.Value = '0';
            app.InoffsetEditFieldLabel = uilabel(app.OffsetsPanel);
            app.InoffsetEditFieldLabel.Text = 'In offset';
            app.InoffsetEditField = uieditfield(app.OffsetsPanel, 'text');
            app.InoffsetEditField.Value = '0';
            app.OutoffsetEditFieldLabel = uilabel(app.OffsetsPanel);
            app.OutoffsetEditFieldLabel.Text = 'Out offset';
            app.OutoffsetEditField = uieditfield(app.OffsetsPanel, 'text');
            app.OutoffsetEditField.Value = '0';
            app.FunctionPanel = uipanel(app.OSAYokogawaUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.RunButton = uibutton(app.FunctionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UsertextEditFieldLabel = uilabel(app.FunctionPanel);
            app.UsertextEditFieldLabel.Text = 'User text';
            app.UsertextEditField = uieditfield(app.FunctionPanel, 'text');
            app.ActivetraceDropDownLabel = uilabel(app.FunctionPanel);
            app.ActivetraceDropDownLabel.Text = 'Active trace';
            app.ActivetraceDropDown = uidropdown(app.FunctionPanel);
            app.ActivetraceDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G'};
            app.ActivetraceDropDown.Value = 'A';
            app.writewaveformCheckBox = uicheckbox(app.FunctionPanel);
            app.writewaveformCheckBox.Text = 'write waveform';
            app.writewaveformCheckBox.Value = true;
            app.readwaveformCheckBox = uicheckbox(app.FunctionPanel);
            app.readwaveformCheckBox.ValueChangedFcn = createCallbackFcn(app, @readwaveformCheckBoxValueChanged, true);
            app.readwaveformCheckBox.Text = 'read waveform';
            app.plotwaveformCheckBox = uicheckbox(app.FunctionPanel);
            app.plotwaveformCheckBox.Enable = 'off';
            app.plotwaveformCheckBox.Text = 'plot waveform';
            app.savewaveformCheckBox = uicheckbox(app.FunctionPanel);
            app.savewaveformCheckBox.Enable = 'off';
            app.savewaveformCheckBox.Text = 'save waveform';
            app.readandsaveanalysisEDFANFCheckBox = uicheckbox(app.FunctionPanel);
            app.readandsaveanalysisEDFANFCheckBox.Text = 'read and save analysis EDFA-NF';
            app.readpowerCheckBox = uicheckbox(app.FunctionPanel);
            app.readpowerCheckBox.Text = 'read power';
            app.SelectfunctionsLabel = uilabel(app.FunctionPanel);
            app.SelectfunctionsLabel.Text = 'Select functions';
            app.Tip1Image = uiimage(app.FunctionPanel);
            app.Tip1Image.Tooltip = {'Be careful! If "write" is not checked, result will be unpredictable.'; 'write waveform - on active trace'; 'read waveform - system function, user will not see changes, but user have to check it if wants to plot or save waveform'; 'plot - plots waveform in main TheGU window'; 'save - saves file at PC with user text'; 'read ans save analysis - saves table with user text in name'; 'read power - shows power in Output Window of TheGU'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.CurrentPowerEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.CurrentPowerEditField.Editable = 'off';
            app.dBmLabel_2 = uilabel(app.FunctionPanel);
            app.dBmLabel_2.Text = 'dBm';
            app.ConnectPanel = uipanel(app.OSAYokogawaUIFigure);
            app.IPaddressEditFieldLabel = uilabel(app.ConnectPanel);
            app.IPaddressEditFieldLabel.Text = 'IP address';
            app.IPaddressEditField = uieditfield(app.ConnectPanel, 'text');
            app.IPaddressEditField.Value = '192.168.100.23';
            app.TimeoutEditFieldLabel = uilabel(app.ConnectPanel);
            app.TimeoutEditFieldLabel.Text = 'Time out';
            app.TimeoutEditField = uieditfield(app.ConnectPanel, 'numeric');
            app.TimeoutEditField.Value = 30;
            app.ReferencetraceDropDownLabel = uilabel(app.ConnectPanel);
            app.ReferencetraceDropDownLabel.Text = 'Reference trace';
            app.ReferencetraceDropDown = uidropdown(app.ConnectPanel);
            app.ReferencetraceDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G'};
            app.ReferencetraceDropDown.Value = 'A';
            app.WaveformtraceDropDownLabel = uilabel(app.ConnectPanel);
            app.WaveformtraceDropDownLabel.Text = 'Waveform trace';
            app.WaveformtraceDropDown = uidropdown(app.ConnectPanel);
            app.WaveformtraceDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G'};
            app.WaveformtraceDropDown.Value = 'B';
            app.Panel_2 = uipanel(app.OSAYokogawaUIFigure);
            app.AddtableforautotestsTextAreaLabel = uilabel(app.Panel_2);
            app.AddtableforautotestsTextAreaLabel.Text = 'Add table for auto tests';
            app.AddtableforautotestsTextArea = uitextarea(app.Panel_2);
            app.AddtableforautotestsTextArea.Value = {'OSAautoFunction.xlsx'};
            app.TextforfileEditFieldLabel = uilabel(app.Panel_2);
            app.TextforfileEditFieldLabel.Text = 'Text for file';
            app.TextforfileEditField = uieditfield(app.Panel_2, 'text');
            app.TextforfileEditField.Value = 'edfaN1_test';
            app.ConnectSwitch = uiswitch(app.OSAYokogawaUIFigure, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            app.FunctionTree = uitree(app.OSAYokogawaUIFigure);
            app.BasicfunctionsNode = uitreenode(app.FunctionTree);
            app.BasicfunctionsNode.Text = 'Basic functions';
            app.WritereferencetraceNode = uitreenode(app.BasicfunctionsNode);
            app.WritereferencetraceNode.Text = 'Write reference trace';
            app.WritewaveformNode = uitreenode(app.BasicfunctionsNode);
            app.WritewaveformNode.Text = 'Write waveform';
            app.ReadwaveformNode = uitreenode(app.BasicfunctionsNode);
            app.ReadwaveformNode.Text = 'Read waveform';
            app.ReadanalysisEDFANFNode = uitreenode(app.BasicfunctionsNode);
            app.ReadanalysisEDFANFNode.Text = 'Read analysis EDFA-NF';
            app.ReadpowerNode = uitreenode(app.BasicfunctionsNode);
            app.ReadpowerNode.Text = 'Read power';
            app.AutofunctionsNode = uitreenode(app.FunctionTree);
            app.AutofunctionsNode.Text = 'Auto functions';
            app.WritewaveformloopEDFANFNode = uitreenode(app.AutofunctionsNode);
            app.WritewaveformloopEDFANFNode.Text = 'Write waveform (loop), EDFA-NF';
            app.CustomfunctionsNode = uitreenode(app.FunctionTree);
            app.CustomfunctionsNode.Text = 'Custom functions';
            app.WriterefpowerNode = uitreenode(app.CustomfunctionsNode);
            app.WriterefpowerNode.Text = 'Write ref, power';
            app.WritewaveformEDFANFNode = uitreenode(app.CustomfunctionsNode);
            app.WritewaveformEDFANFNode.Text = 'Write waveform, EDFA-NF';
            % Show the figure after all components are created
            app.OSAYokogawaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_OSAyokogawa(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OSAYokogawaUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OSAYokogawaUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSAYokogawaUIFigure)
        end
    end
end