classdef appCalibrateEDFA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        testcoeffButton_2               matlab.ui.control.Button
        getcoeffButton_2                matlab.ui.control.Button
        ApplyButton_2                   matlab.ui.control.StateButton
        Panel_5                         matlab.ui.container.Panel
        getoffsetsButton                matlab.ui.control.Button
        savetableCheckBox_3             matlab.ui.control.CheckBox
        writetoEEPROMCheckBox           matlab.ui.control.CheckBox
        writetoFLASHCheckBox            matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.NumericEditField
        TimestogetvaluesfromVDLabel     matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        OutDropDown_2                   matlab.ui.control.DropDown
        calibrateP_outButton            matlab.ui.control.Button
        mkWADCLabel_2                   matlab.ui.control.Label
        dBLabel_3                       matlab.ui.control.Label
        savetableCheckBox_2             matlab.ui.control.CheckBox
        writetoFLASHCheckBox_3          matlab.ui.control.CheckBox
        writetoEEPROMCheckBox_3         matlab.ui.control.CheckBox
        Coeff_outEditField              matlab.ui.control.NumericEditField
        Coeff_outEditFieldLabel         matlab.ui.control.Label
        Loss_outEditField               matlab.ui.control.NumericEditField
        Loss_outEditFieldLabel          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        InDropDown                      matlab.ui.control.DropDown
        calibrateP_inButton             matlab.ui.control.Button
        mkWADCLabel                     matlab.ui.control.Label
        dBLabel_2                       matlab.ui.control.Label
        savetableCheckBox               matlab.ui.control.CheckBox
        writetoFLASHCheckBox_2          matlab.ui.control.CheckBox
        writetoEEPROMCheckBox_2         matlab.ui.control.CheckBox
        Coeff_inEditField               matlab.ui.control.NumericEditField
        Coeff_inEditFieldLabel          matlab.ui.control.Label
        Loss_inEditField                matlab.ui.control.NumericEditField
        Loss_inEditFieldLabel           matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SelectmodeDropDown              matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        writeinitialcoeffstoFPGAButton  matlab.ui.control.Button
        readcoefficientsfromFPGAButton  matlab.ui.control.Button
        Label_14                        matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        downloadComponentsTableButton   matlab.ui.control.Button
        ZoomInOneButton                 matlab.ui.control.Button
        UserTextEditField               matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        deltaPrubinLabel                matlab.ui.control.Label
        AssembleopticalcircuitentervaluePPPinPoutLabel  matlab.ui.control.Label
        CircuitImage                    matlab.ui.control.Image
        LineDropDown                    matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        tableTextArea                   matlab.ui.control.TextArea
        Label_2                         matlab.ui.control.Label
        HeaderLabel                     matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        FPGA
        ATTENniMyDaq
        SWITCHosaEdfa
        Pin
        Pout
    end

    properties
        tableAutoCalibPath
        %         tableAutoCalib
        rowNumberPin
        rowNumberPout
        opticLineNumber
        %tableVDforOFFSET
        tableInfo
        tableExtraInfo
        tableResultSample
        tablePin
        tablePout
        PINname
        POUTname
        nameVDout_1
        nameVDout_2
        nameVDin_1
        nameVDin_2
        N
        mode
    end

    methods (Access = private)
        function app = readTableInfo(app)
            app.tableInfo = readtable(app.tableAutoCalibPath,...
                'Sheet','Info','ReadRowNames',true);
        end
        function app = readTableResultSample(app)
            app.tableResultSample = readtable(app.tableAutoCalibPath,...
                'Sheet','ResultSample','ReadRowNames',true);
            disp(app.tableResultSample)
        end
        function saveTableResultSample(app)
            userText = app.UserTextEditField.Value;
            tableName = createFilename([userText 'Result_'], now);
            writetable(app.tableResultSample,[app.folder tableName '.xlsx'],'AutoFitWidth',false,"WriteRowNames",true);
        end
        function app = readTablePin(app)
            tableArray = readtable(app.tableAutoCalibPath,'Sheet','Pin');
            app.rowNumberPin = height(tableArray);

            app.tablePin = tableArray;
        end
        function saveTablePin(app)
            userText = app.UserTextEditField.Value;
            tableName = createFilename([userText 'VDin_'], now);
            writetable(app.tablePin,[app.folder tableName '.xlsx'],'AutoFitWidth',false);
        end
        function app = readTablePout(app)
            app.tablePout = readtable(app.tableAutoCalibPath,'Sheet','Pout');
            app.rowNumberPout = height(app.tablePout);
        end
        function saveTablePout(app)
            userText = app.UserTextEditField.Value;
            %Pinput = num2strForPrint();
            s = [userText 'VDout_']; %Pinput ];
            tableName = createFilename(s, now);
            writetable(app.tablePout,[app.folder tableName '.xlsx'],'AutoFitWidth',false);
        end
        function nameVD_1 = getNameVD1(app,P)
            rowName = [app.LineDropDown.Value '_1'];
            nameVD_1 = app.tableInfo{rowName,P};
        end
        function nameVD_2 = getNameVD2(app,P)
            rowName = [app.LineDropDown.Value '_2'];
            nameVD_2 = app.tableInfo{rowName,P};
        end
        function app = getMeanForOffset(app,VD)
            arrayForMean = zeros(1,app.N);
            
            for i = 1:app.N
                %disp(VD)
                app.FPGA = readData(app.FPGA,VD);
                %disp(app.FPGA.lastRead)
                arrayForMean(i) = str2double(app.FPGA.lastRead);
            end
            
            offset = round(-1*mean(arrayForMean));
            app.tableResultSample{VD,'OFFSET_'} = offset;
            %disp('offset')
            %disp(offset)


            %disp(app.FPGA.lastRead)

            if app.writetoFLASHCheckBox.Value
app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,['OFFSET_' VD], num2str(offset));
addStrOutput(app.callingApp, ['in FLASH mem: OFFSET_',VD, ' = ',app.FPGA.lastRead])
            end
            
            if app.writetoEEPROMCheckBox.Value
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,['OFFSET_' VD],num2str(offset));
            addStrOutput(app.callingApp, ['in EEPROM mem: OFFSET_',VD, ' = ',app.FPGA.lastRead])
            end
            pause(0.5)
        end

%         function loadFromMemFileToApp(app)
%             load('TheGreatUniterMemory.mat','appTestFPGA_Photodiode');
%             app.SelectComponentsTableTextArea.Value = appTestFPGA_Photodiode.SelectComponentsTableTextArea;
%             app.SelectSampleTableTextArea.Value = appTestFPGA_Photodiode.SelectSampleTableTextArea;
%             app.BoardnumberEditField.Value = appTestFPGA_Photodiode.BoardnumberEditField;
%             app.CommentEditField.Value = appTestFPGA_Photodiode.CommentEditField;
%             app.InspectornameEditField.Value = appTestFPGA_Photodiode.InspectornameEditField;
%             app.TestmodeDropDown.Value = appTestFPGA_Photodiode.TestmodeDropDown;
%         end
%         function saveFromAppToMemFile(app)
%             appTestFPGA_Photodiode.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
%             appTestFPGA_Photodiode.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
%             appTestFPGA_Photodiode.BoardnumberEditField = app.BoardnumberEditField.Value;
%             appTestFPGA_Photodiode.CommentEditField = app.CommentEditField.Value;
%             appTestFPGA_Photodiode.InspectornameEditField = app.InspectornameEditField.Value;
%             appTestFPGA_Photodiode.TestmodeDropDown = app.TestmodeDropDown.Value;
%             save('TheGreatUniterMemory.mat','appTestFPGA_Photodiode','-append');
%         end
%         function checkFieldsNotEmpty(app)
%             fieldsArray = {...
%                 app.SelectComponentsTableTextArea,...
%                 app.SelectSampleTableTextArea,...
%                 app.BoardnumberEditField,...
%                 app.CommentEditField,...
%                 app.InspectornameEditField...
%                 };
%             for i = 1:length(fieldsArray)
%                 if iscell(fieldsArray{i}.Value)
%                     value = fieldsArray{i}.Value{1};
%                 else
%                     value = fieldsArray{i}.Value;
%                 end
%                 if isempty(value)
%                     fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
%                     output = 'Fields must not be empty';
%                     addStrOutput(app.callingApp,output)
%                     error(output);
%                 else
%                     fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
%                 end
%             end
%         end
% %         function checkTestMode(app)
% %             if app.TestmodeDropDown.Value == app.TestmodeValueAutoRubin
% %                 
% %             elseif app.TestmodeDropDown.Value == app.TestmodeValueManual
% %                 
% %             end
% %         end
%         function createMainObjects(app)
%             app.tableArray = readtable(app.SelectSampleTableTextArea.Value{1},'Sheet','Array');
%             app.mainObjects = cell(1,height(app.tableArray));
%             for numberVD = 1:height(app.tableArray)
%                 infoString = app.tableArray(numberVD,:);
%                 appStruct = struct(...
%                     'time',now, ...
%                     'folder',app.folder, ...
%                     'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
%                     'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
%                     'boardNumber',app.BoardnumberEditField.Value, ...
%                     'designation',infoString{1,'Designition'},...
%                     'serialNumber',infoString{1,'SerialNumber'},...
%                     'comment',app.CommentEditField.Value, ...
%                     'inspectorName',app.InspectornameEditField.Value, ...
%                     'wavelength',infoString{1,'OperatingWavelength'},...
%                     'fpgaName',infoString{1,'FPGAAddress'},...
%                     'loss',infoString{1,'Loss'},...
%                     'resistor',infoString{1,'Resistor'}...
%                     );
%                 app.mainObjects{numberVD} = FPGA_Photodiode(appStruct);
%             end           
%         end
%         function saveMatFiles(app)
%             for numberVD = 1:height(app.tableArray)
%                 photodiode = app.mainObjects{numberVD};
%                 photodiodeName = photodiode.tableName;%createFilename(app.mainObj.time,'AT');
%                 save([app.folder photodiodeName '.mat'],'photodiode');
%             end
%         end
%         function deleteMainObjects(app)
%             app.mainObjects = [];
%         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
                app.ATTENniMyDaq = MainApp.AddDevice_ATTENniMyDaq.mainObj;
                app.SWITCHosaEdfa = MainApp.AddDevice_SWITCHosaEdfa.mainObj;
                if isvalid(MainApp.AddDevice_OPMrubin)
                    if ~isempty(MainApp.AddDevice_OPMrubin)
                        app.Pin = mainApp.struct_OPMrubin.Pin;
                        app.Pout = mainApp.struct_OPMrubin.Pout;
                    end
                end
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Callback function
        function readADCP_inforoffsetButtonPushed(app, event)


            app.FPGA=setDiodesEn(app.FPGA, '0000');
            disableWarningLaser(app.callingApp);

            app.Pin = requestPower(app.Pin);
            if app.Pin.powerdBm(end) > -50
                warning('мощность с рубина > -50 дБм');
            end

            nameVD_1 = getNameVD1(app,'P_in');
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = nameVD_1;
            nameVD_1 = nameVD_1{1};
            app.tablePin.Properties.VariableNames = varNames;
            app.FPGA = readData(app.FPGA,nameVD_1);
            app.tablePin{1,nameVD_1} = -1*str2double(app.FPGA.lastRead);
            app.tableResultSample{nameVD_1,'OFFSET_'} = str2double(app.FPGA.lastRead);
            str = ['OFFSET_' nameVD_1 ' = ' app.FPGA.lastRead];
            addStrOutput(app.callingApp,str);

            app.FPGA = readData(app.FPGA,'PIN1_FILT_CAL');
            app.tablePin{1,'PIN1_FILT_CAL'} = str2double(app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,'PIN2_FILT_CAL');
            app.tablePin{1,'PIN2_FILT_CAL'} = str2double(app.FPGA.lastRead);

            nameVD_2 = getNameVD2(app,'P_in');
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = nameVD_2;
            nameVD_2 = nameVD_2{1};
            %disp(varNames)
            app.tablePin.Properties.VariableNames = varNames;
            app.FPGA = readData(app.FPGA,nameVD_2);
            app.tablePin{1,nameVD_2} = -1*str2double(app.FPGA.lastRead);
            app.tableResultSample{nameVD_2,'OFFSET_'} = str2double(app.FPGA.lastRead);
            str = ['OFFSET_' nameVD_2 ' = ' app.FPGA.lastRead];
            addStrOutput(app.callingApp,str);

            app.calibrateP_inButton.Enable = 'on';
            
            
        end

        % Button pushed function: calibrateP_inButton
        function calibrateP_inButtonPushed(app, event)
            app.FPGA=setDiodesEn(app.FPGA, '0000');
            disableWarningLaser(app.callingApp);
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
            app.SWITCHosaEdfa = switchSignalTo(app.SWITCHosaEdfa,'EDFA');
            app.ATTENniMyDaq = setMaxAttenuation(app.ATTENniMyDaq);
            for i = 1:app.rowNumberPin
                if i ~= 1 && app.tablePin{i,'InputPowerThDBm'} == app.tablePin{i-1,'InputPowerThDBm'}
                    n = 1;
                else
                    n = 3;
                end
                for j = 1:n
                    app.Pin = requestPower(app.Pin);
                    powerReal = app.Pin.powerdBm(end);
                    powerTheor = app.tablePin{i,'InputPowerThDBm'};
                    currentAttenuation = app.ATTENniMyDaq.lastAttenuation;
                    attenuation = powerReal - powerTheor + currentAttenuation;
                    app.tablePin{i,'AttenuationForInputPowerDBm'} = attenuation;
                    app.ATTENniMyDaq = setAttenuation(app.ATTENniMyDaq,attenuation);
                    pause(1)
                end
                addStrOutput(app.callingApp,['Выставлена аттенюация ' num2strForPrint(attenuation) ' дБм']);

                pause(app.tablePin{i,'PauseAfterAttenuationS'});

                app.Pin = requestPower(app.Pin);
                powerReal = app.Pin.powerdBm(end);

                app.tablePin{i,'InputPowerRealDBm'} = powerReal;
                newRow = {'Входная мощность (OPM), дБм',powerReal};
                appendMainTable(app.callingApp,newRow);

                powerRealmW = dbm2w(powerReal,'m');
                app.tablePin{i,'InputPowerRealMW'} = powerRealmW;
                newRow = {'Входная мощность (OPM), мВт',powerRealmW};
                appendMainTable(app.callingApp,newRow);

                app.FPGA = readData(app.FPGA,app.nameVDin_1);
                app.tablePin{i,app.nameVDin_1} = str2double(app.FPGA.lastRead);
                newRow = {app.nameVDin_1,app.FPGA.lastRead};
                appendMainTable(app.callingApp,newRow);

                app.FPGA = readData(app.FPGA,app.nameVDin_2);
                app.tablePin{i,app.nameVDin_2} = str2double(app.FPGA.lastRead);
                newRow = {app.nameVDin_2,app.FPGA.lastRead};
                appendMainTable(app.callingApp,newRow);

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'SEL_PH_Pin','0');
                pause(0.5)
                app.FPGA = readData(app.FPGA,app.PINname);
                app.tablePin{i,'PIN_1'} = str2double(app.FPGA.lastRead);

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'SEL_PH_Pin','3');
                pause(0.5)
                app.FPGA = readData(app.FPGA,app.PINname);
                app.tablePin{i,'PIN_2'} = str2double(app.FPGA.lastRead);
            end
            addStrOutput(app.callingApp,'Входные ФП проверены');
if app.InDropDown.Value == "test coeffs"
            tableVD = app.tablePin;
            lossdB = app.Loss_inEditField.Value;
            k_mkWadc = app.Coeff_inEditField.Value;

            coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_1);
            app.tableResultSample{app.nameVDin_1,'COEFF_'} = coeff;
            coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_1'];
            if app.writetoEEPROMCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
%                 disp(coeffName)
%                 disp(coeff)
            end
            if app.writetoFLASHCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                
            end
            
            coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_2);
            app.tableResultSample{app.nameVDin_2,'COEFF_'} = coeff;
            coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_2'];
            if app.writetoEEPROMCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
%                 disp(coeffName)
%                 disp(coeff)
            end
            if app.writetoFLASHCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                
            end
            
            addStrOutput(app.callingApp,'Входные ФП обработаны');
elseif app.InDropDown.Value == "get coeffs"
tableVD = app.tablePin;
            lossdB = app.Loss_inEditField.Value;
            k_mkWadc = app.Coeff_inEditField.Value;

            coeff = addToCalibVDFUN(app.folder,'in','get',tableVD,lossdB,k_mkWadc,app.nameVDin_1);
            app.tableResultSample{app.nameVDin_1,'COEFF_'} = coeff;
            coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_1'];
            if app.writetoEEPROMCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
%                 disp(coeffName)
%                 disp(coeff)
            end
            if app.writetoFLASHCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                
            end
            
            coeff = addToCalibVDFUN(app.folder,'in','get',tableVD,lossdB,k_mkWadc,app.nameVDin_2);
            app.tableResultSample{app.nameVDin_2,'COEFF_'} = coeff;
            coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_2'];
            if app.writetoEEPROMCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
%                 disp(coeffName)
%                 disp(coeff)
            end
            if app.writetoFLASHCheckBox_2.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                
            end
            
            addStrOutput(app.callingApp,'Входные ФП обработаны');
end
            if app.savetableCheckBox.Value
                saveTablePin(app);
                addStrOutput(app.callingApp,'Таблица TablePin сохранена');
            end
        end

        % Callback function
        function saveResultButtonPushed(app, event)
%             saveTableResultSample(app);
%             addStrOutput(app.callingApp,'Таблица TableResultSample сохранена');
        end

        % Callback function
        function savePinButtonPushed(app, event)
%             saveTablePin(app);
%             addStrOutput(app.callingApp,'Таблица TablePin сохранена');
        end

        % Callback function
        function savePoutButtonPushed(app, event)
%             saveTablePout(app);
%             addStrOutput(app.callingApp,'Таблица TablePout сохранена');
        end

        % Button pushed function: calibrateP_outButton
        function calibrateP_outButtonPushed(app, event)
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
            for i = 1:app.rowNumberPout
                if i ~= 1 && app.tablePout{i,'InputPowerThDBm'} == app.tablePout{i-1,'InputPowerThDBm'}
                    n = 1;
                else
                    n = 3;
                end
                for j = 1:n
                    app.Pin = requestPower(app.Pin);
                    powerReal = app.Pin.powerdBm(end);
                    powerTheor = app.tablePout{i,'InputPowerThDBm'};
                    currentAttenuation = app.ATTENniMyDaq.lastAttenuation;
                    attenuation = powerReal - powerTheor + currentAttenuation;
                    app.tablePout{i,'AttenuationForInputPowerDBm'} = attenuation;
                    app.ATTENniMyDaq = setAttenuation(app.ATTENniMyDaq,attenuation);
                    pause(1)
                end
                addStrOutput(app.callingApp,['Выставлена аттенюация ' num2strForPrint(attenuation) ' дБм']);
                pause(app.tablePout{i,'PauseAfterAttenuationS'});

                app.Pin = requestPower(app.Pin);
                powerReal = app.Pin.powerdBm(end);

                app.tablePout{i,'InputPowerRealDBm'} = powerReal;
                newRow = {'Входная мощность (OPM), дБм',powerReal};
                appendMainTable(app.callingApp,newRow);

                powerRealmW = dbm2w(powerReal,'m');
                app.tablePout{i,'InputPowerRealMW'} = powerRealmW;
                newRow = {'Входная мощность (OPM), мВт',powerRealmW};
                appendMainTable(app.callingApp,newRow);

                enableWarningLaser(app.callingApp);
                app.FPGA=setDiodesEn(app.FPGA, '1111');
                %powerValue = app.tablePout{i,'OutputPowerTh'};

                %%%% ЧАСТЬ РАБОТЫ С ТАБЛИЦЕЙ %%%%
                varNames = app.tablePout.Properties.VariableNames;
                name1 = varNames{1,6};
                name2 = varNames{1,7};

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'MODE','0');

                wvalue1 = num2str(app.tablePout{i,name1});
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name1,wvalue1);
                addStrOutput(app.callingApp,['Записано ' name1 ' = ' wvalue1]);

                wvalue2 = num2str(app.tablePout{i,name2});
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name2,wvalue2);
                addStrOutput(app.callingApp,['Записано ' name2 ' = ' wvalue2]);

                pauseSec = app.tablePout{i,'PauseAfterSetOutputPowerS'};
                addStrOutput(app.callingApp,['Ожидание ' num2strForPrint(pauseSec) ' с']);
                pause(pauseSec);
                %сделать аналогично тесту
                %setOutputPowerEDFA(app.FPGA,app.opticLineNumber,powerValue);

                pause(app.tablePout{i,'PauseAfterSetOutputPowerS'});

                app.Pout = requestPower(app.Pout);
                powerReal = app.Pout.powerdBm(end);
                powerReal = powerReal - app.PEditField.Value;
                app.tablePout{i,'OutputPowerRealDBm'} = powerReal;
                newRow = {'Выходная мощность (OPM), дБм',powerReal};
                appendMainTable(app.callingApp,newRow);

                powerRealmW = dbm2w(powerReal,'m');
                app.tablePout{i,'OutputPowerRealMW'} = powerRealmW;
                newRow = {'Выходная мощность (OPM), мВт',powerRealmW};
                appendMainTable(app.callingApp,newRow);

                app.FPGA = readData(app.FPGA,app.nameVDout_1);
                app.tablePout{i,app.nameVDout_1} = str2double(app.FPGA.lastRead);
                newRow = {app.nameVDout_1,app.FPGA.lastRead};
                appendMainTable(app.callingApp,newRow);

                app.FPGA = readData(app.FPGA,app.nameVDout_2);
                app.tablePout{i,app.nameVDout_2} = str2double(app.FPGA.lastRead);
                newRow = {app.nameVDout_2,app.FPGA.lastRead};
                appendMainTable(app.callingApp,newRow);

                nameVDoffset_1 = ['OFFSET_' app.nameVDout_1];
                app.FPGA = readData(app.FPGA,nameVDoffset_1);
                oldOffset_1 = app.FPGA.lastRead;

                nameVDoffset_2 = ['OFFSET_' app.nameVDout_2];
                app.FPGA = readData(app.FPGA,nameVDoffset_2);
                oldOffset_2 = app.FPGA.lastRead;

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_2,'-100000');
                pause(0.5);
                app.FPGA = readData(app.FPGA,app.POUTname);
                app.tablePout{i,'POUT_1'} = str2double(app.FPGA.lastRead);
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_2,oldOffset_2);

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_1,'-100000');
                pause(0.5);
                app.FPGA = readData(app.FPGA,app.POUTname);
                app.tablePout{i,'POUT_2'} = str2double(app.FPGA.lastRead);
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_1,oldOffset_1);
            end

            disableWarningLaser(app.callingApp);
            app.FPGA=setDiodesEn(app.FPGA, '0000');
            wvalue1 = '0';
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name1,wvalue1);
            addStrOutput(app.callingApp,['Записано ' name1 ' = ' wvalue1]);
            wvalue2 = '0';
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name2,wvalue2);
            addStrOutput(app.callingApp,['Записано ' name2 ' = ' wvalue2]);
            
            addStrOutput(app.callingApp,'Выходные ФП проверены');

            if app.savetableCheckBox_2.Value
saveTablePout(app);
            addStrOutput(app.callingApp,'Таблица TablePout сохранена');
            end
        end

        % Button pushed function: getoffsetsButton
        function getoffsetsButtonPushed(app, event)
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
            app.SWITCHosaEdfa = switchSignalTo(app.SWITCHosaEdfa,'OSA');
            
            app.N = app.EditField.Value;%количество снятий для вычисления среднего
            
            app = getMeanForOffset(app,app.nameVDin_1);
            app = getMeanForOffset(app,app.nameVDin_2);
            app = getMeanForOffset(app,app.nameVDout_1);
            app = getMeanForOffset(app,app.nameVDout_2);

            if app.savetableCheckBox_3.Value
                saveTableResultSample(app);
            addStrOutput(app.callingApp,'Таблица TableResultSample сохранена');
            end

        end

        % Button pushed function: getcoeffButton_2
        function getcoeffButton_2Pushed(app, event)
            tableVD = app.tablePout;
            lossdB = app.Loss_outEditField.Value;
            k_mkWadc = app.Coeff_outEditField.Value;
            
            coeff = addToCalibVDFUN(app.folder,'out','get',tableVD,lossdB,k_mkWadc,app.nameVDout_1);
            app.tableResultSample{app.nameVDout_1,'COEFF_'} = coeff;
            %disp(coeff)
            %disp(num2str(coeff))
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_1'];
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
            end
            
            coeff = addToCalibVDFUN(app.folder,'out','get',tableVD,lossdB,k_mkWadc,app.nameVDout_2);
            app.tableResultSample{app.nameVDout_2,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_2'];
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
            end
            addStrOutput(app.callingApp,'Выходные ФП обработаны');
        end

        % Button pushed function: writeinitialcoeffstoFPGAButton
        function writeinitialcoeffstoFPGAButtonPushed(app, event)
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_2','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_2','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_2','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_2','65535');
            addStrOutput(app.callingApp,'Нулевые коэффициенты записаны');
        end

        % Value changed function: savetableCheckBox_2
        function savetableCheckBox_2ValueChanged(app, event)
            value = app.savetableCheckBox_2.Value;
            
        end

        % Callback function
        function testcoeffButtonPushed(app, event)
            tableVD = app.tablePin;
            lossdB = app.Loss_inEditField.Value;
            k_mkWadc = app.Coeff_inEditField.Value;

            coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_1);
            app.tableResultSample{app.nameVDin_1,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_1'];
%                 disp(coeffName)
%                 disp(coeff)
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
            end
            
            coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_2);
            app.tableResultSample{app.nameVDin_2,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_2'];
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
            end
            
            addStrOutput(app.callingApp,'Входные ФП обработаны');
        end

        % Button pushed function: testcoeffButton_2
        function testcoeffButton_2Pushed(app, event)
            tableVD = app.tablePout;
            lossdB = app.Loss_outEditField.Value;
            k_mkWadc = app.Coeff_outEditField.Value;
            
            coeff = addToCalibVDFUN(app.folder,'out','test',tableVD,lossdB,k_mkWadc,app.nameVDout_1);
            app.tableResultSample{app.nameVDout_1,'COEFF_'} = coeff;
            %disp(coeff)
            %disp(num2str(coeff))
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_1'];
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
            end
            
            coeff = addToCalibVDFUN(app.folder,'out','test',tableVD,lossdB,k_mkWadc,app.nameVDout_2);
            app.tableResultSample{app.nameVDout_2,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_2'];
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
            end
            addStrOutput(app.callingApp,'Выходные ФП обработаны');
        end

        % Button pushed function: readcoefficientsfromFPGAButton
        function readcoefficientsfromFPGAButtonPushed(app, event)
            addStrOutput(app.callingApp,'Коэффициенты записаны следующие:');
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            output = selectTable(app.tableTextArea.Value{1});
            app.tableTextArea.Value = output;
        end

        % Value changed function: ApplyButton_2
        function ApplyButton_2ValueChanged(app, event)
            value = app.ApplyButton_2.Value;
                        app.tableAutoCalibPath = app.tableTextArea.Value{1};

            app = readTableInfo(app);
            app = readTableResultSample(app);
            app = readTablePin(app);
            app = readTablePout(app);

            app.opticLineNumber = app.LineDropDown.Value;
            app.PINname = ['PIN' app.opticLineNumber(2) '_FILT_CAL'];
            %disp(app.PIN)
            app.POUTname = ['POUT' app.opticLineNumber(2) '_FILT_CAL'];

            app.mode = app.SelectmodeDropDown.Value;
            
            app.Panel_2.Enable = 'on';
            app.Panel_3.Enable = 'on';
            app.Panel_4.Enable = 'on';

            app.SWITCHosaEdfa = switchSignalEDFA(app.SWITCHosaEdfa);

            app.nameVDout_1 = getNameVD1(app,'P_out');
            app.nameVDout_2 = getNameVD2(app,'P_out');
            varNames = app.tablePout.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = app.nameVDout_1;
            app.tablePout.Properties.VariableNames = varNames;
            varNames = app.tablePout.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = app.nameVDout_2;
            app.tablePout.Properties.VariableNames = varNames;
            app.nameVDout_1 = app.nameVDout_1{1};
            app.nameVDout_2 = app.nameVDout_2{1};

            app.nameVDin_1 = getNameVD1(app,'P_in');
            app.nameVDin_1 = app.nameVDin_1{1};
            app.nameVDin_2 = getNameVD2(app,'P_in');
            app.nameVDin_2 = app.nameVDin_2{1};
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = {app.nameVDin_1};
            app.tablePin.Properties.VariableNames = varNames;
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = {app.nameVDin_2};
            app.tablePin.Properties.VariableNames = varNames;
            
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
        end

        % Button pushed function: ZoomInOneButton
        function ZoomInOneButtonPushed(app, event)
            imageview(app.CircuitImage.ImageSource);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Сalibrate EDFA';
            app.Panel = uipanel(app.UIFigure);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Select calibration table';
            app.tableTextArea = uitextarea(app.Panel);
            app.tableTextArea.Value = {'autoCalibration.xlsx'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Select optical line';
            app.LineDropDown = uidropdown(app.Panel);
            app.LineDropDown.Items = {'L1', 'L2'};
            app.LineDropDown.Value = 'L1';
            app.CircuitImage = uiimage(app.Panel);
            app.CircuitImage.ImageSource = 'EDFA_Calibrate.jpg';
            app.AssembleopticalcircuitentervaluePPPinPoutLabel = uilabel(app.Panel);
            app.AssembleopticalcircuitentervaluePPPinPoutLabel.Text = {'Assemble optical circuit, enter value ΔP'; 'ΔP = Pin - Pout'};
            app.deltaPrubinLabel = uilabel(app.Panel);
            app.deltaPrubinLabel.Text = 'ΔP =';
            app.PEditField = uieditfield(app.Panel, 'numeric');
            app.PEditField.Tooltip = {'P_2 - P_1'};
            app.PEditField.Value = 0.13;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Custom text for filenames';
            app.UserTextEditField = uieditfield(app.Panel, 'text');
            app.UserTextEditField.Value = 'myFirstCalibration';
            app.ZoomInOneButton = uibutton(app.Panel, 'push');
            app.ZoomInOneButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInOneButtonPushed, true);
            app.ZoomInOneButton.Icon = 'gu_zoomin.png';
            app.ZoomInOneButton.Tooltip = {'Zoom in'};
            app.ZoomInOneButton.Text = '';
            app.downloadComponentsTableButton = uibutton(app.Panel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.dBLabel = uilabel(app.Panel);
            app.dBLabel.Text = 'dB';
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = 'Before starting make sure there are initial coefficients in FPGA memory';
            app.readcoefficientsfromFPGAButton = uibutton(app.Panel, 'push');
            app.readcoefficientsfromFPGAButton.ButtonPushedFcn = createCallbackFcn(app, @readcoefficientsfromFPGAButtonPushed, true);
            app.readcoefficientsfromFPGAButton.Tooltip = {'Читает коэффициенты с ПЛИС и выводит в '};
            app.readcoefficientsfromFPGAButton.Text = 'read coefficients from FPGA';
            app.writeinitialcoeffstoFPGAButton = uibutton(app.Panel, 'push');
            app.writeinitialcoeffstoFPGAButton.ButtonPushedFcn = createCallbackFcn(app, @writeinitialcoeffstoFPGAButtonPushed, true);
            app.writeinitialcoeffstoFPGAButton.Text = 'write initial coeffs to FPGA';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Enable = 'off';
            app.Label_13.Text = 'Select mode';
            app.SelectmodeDropDown = uidropdown(app.Panel);
            app.SelectmodeDropDown.Items = {'0', '2'};
            app.SelectmodeDropDown.Enable = 'off';
            app.SelectmodeDropDown.Value = '0';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Enable = 'off';
            app.Loss_inEditFieldLabel = uilabel(app.Panel_2);
            app.Loss_inEditFieldLabel.Text = 'Loss_in';
            app.Loss_inEditField = uieditfield(app.Panel_2, 'numeric');
            app.Loss_inEditField.Value = 19.26;
            app.Coeff_inEditFieldLabel = uilabel(app.Panel_2);
            app.Coeff_inEditFieldLabel.Text = 'Coeff_in';
            app.Coeff_inEditField = uieditfield(app.Panel_2, 'numeric');
            app.Coeff_inEditField.Value = 0.00024;
            app.writetoEEPROMCheckBox_2 = uicheckbox(app.Panel_2);
            app.writetoEEPROMCheckBox_2.Text = 'write to EEPROM';
            app.writetoFLASHCheckBox_2 = uicheckbox(app.Panel_2);
            app.writetoFLASHCheckBox_2.Text = 'write to FLASH';
            app.savetableCheckBox = uicheckbox(app.Panel_2);
            app.savetableCheckBox.Text = 'save table';
            app.savetableCheckBox.Value = true;
            app.dBLabel_2 = uilabel(app.Panel_2);
            app.dBLabel_2.Text = 'dB';
            app.mkWADCLabel = uilabel(app.Panel_2);
            app.mkWADCLabel.Text = 'mkW/ADC';
            app.calibrateP_inButton = uibutton(app.Panel_2, 'push');
            app.calibrateP_inButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateP_inButtonPushed, true);
            app.calibrateP_inButton.Text = 'calibrate P_in';
            app.InDropDown = uidropdown(app.Panel_2);
            app.InDropDown.Items = {'test coeffs', 'get coeffs'};
            app.InDropDown.Value = 'test coeffs';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Enable = 'off';
            app.Loss_outEditFieldLabel = uilabel(app.Panel_3);
            app.Loss_outEditFieldLabel.Text = 'Loss_out';
            app.Loss_outEditField = uieditfield(app.Panel_3, 'numeric');
            app.Loss_outEditField.Value = 17.66;
            app.Coeff_outEditFieldLabel = uilabel(app.Panel_3);
            app.Coeff_outEditFieldLabel.Text = 'Coeff_out';
            app.Coeff_outEditField = uieditfield(app.Panel_3, 'numeric');
            app.Coeff_outEditField.Value = 0.00457;
            app.writetoEEPROMCheckBox_3 = uicheckbox(app.Panel_3);
            app.writetoEEPROMCheckBox_3.Text = 'write to EEPROM';
            app.writetoFLASHCheckBox_3 = uicheckbox(app.Panel_3);
            app.writetoFLASHCheckBox_3.Text = 'write to FLASH';
            app.savetableCheckBox_2 = uicheckbox(app.Panel_3);
            app.savetableCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @savetableCheckBox_2ValueChanged, true);
            app.savetableCheckBox_2.Text = 'save table';
            app.savetableCheckBox_2.Value = true;
            app.dBLabel_3 = uilabel(app.Panel_3);
            app.dBLabel_3.Text = 'dB';
            app.mkWADCLabel_2 = uilabel(app.Panel_3);
            app.mkWADCLabel_2.Text = 'mkW/ADC';
            app.calibrateP_outButton = uibutton(app.Panel_3, 'push');
            app.calibrateP_outButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateP_outButtonPushed, true);
            app.calibrateP_outButton.Text = 'calibrate P_out';
            app.OutDropDown_2 = uidropdown(app.Panel_3);
            app.OutDropDown_2.Items = {'test coeffs', 'get coeffs'};
            app.OutDropDown_2.Value = 'test coeffs';
            app.Panel_5 = uipanel(app.UIFigure);
            app.TimestogetvaluesfromVDLabel = uilabel(app.Panel_5);
            app.TimestogetvaluesfromVDLabel.Text = 'Times to get values from VD';
            app.EditField = uieditfield(app.Panel_5, 'numeric');
            app.EditField.Value = 40;
            app.writetoFLASHCheckBox = uicheckbox(app.Panel_5);
            app.writetoFLASHCheckBox.Text = 'write to FLASH';
            app.writetoFLASHCheckBox.Value = true;
            app.writetoEEPROMCheckBox = uicheckbox(app.Panel_5);
            app.writetoEEPROMCheckBox.Text = 'write to EEPROM';
            app.writetoEEPROMCheckBox.Value = true;
            app.savetableCheckBox_3 = uicheckbox(app.Panel_5);
            app.savetableCheckBox_3.Text = 'save table';
            app.savetableCheckBox_3.Value = true;
            app.getoffsetsButton = uibutton(app.Panel_5, 'push');
            app.getoffsetsButton.ButtonPushedFcn = createCallbackFcn(app, @getoffsetsButtonPushed, true);
            app.getoffsetsButton.Text = 'get offsets';
            app.ApplyButton_2 = uibutton(app.UIFigure, 'state');
            app.ApplyButton_2.ValueChangedFcn = createCallbackFcn(app, @ApplyButton_2ValueChanged, true);
            app.ApplyButton_2.Text = 'Apply';
            app.getcoeffButton_2 = uibutton(app.UIFigure, 'push');
            app.getcoeffButton_2.ButtonPushedFcn = createCallbackFcn(app, @getcoeffButton_2Pushed, true);
            app.getcoeffButton_2.Text = 'get coeff';
            app.testcoeffButton_2 = uibutton(app.UIFigure, 'push');
            app.testcoeffButton_2.ButtonPushedFcn = createCallbackFcn(app, @testcoeffButton_2Pushed, true);
            app.testcoeffButton_2.Tooltip = {'Читает коэффициенты с ПЛИС и выводит в '};
            app.testcoeffButton_2.Text = 'test coeff';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCalibrateEDFA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end