classdef appCustomScript < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CustomscriptUIFigure        matlab.ui.Figure
        StartPanel                  matlab.ui.container.Panel
        DownloadSampleScriptButton  matlab.ui.control.Button
        DownloadsamplescriptLabel   matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        AddScriptButton             matlab.ui.control.Button
        TipImage                    matlab.ui.control.Image
        ScriptPathTextArea          matlab.ui.control.TextArea
        SelectyourscriptLabel       matlab.ui.control.Label
        CustomscriptLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp % Description
    end
    
    methods (Access = public)
        
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appCustomScript');
            app.ScriptPathTextArea.Value = appCustomScript.ScriptPathTextArea;
        end
        function saveFromAppToMemFile(app)
            appCustomScript.ScriptPathTextArea = app.ScriptPathTextArea.Value{1};
            save('TheGreatUniterMemory.mat','appCustomScript','-append');
        end
        function output = selectFile(app)
            [file,path] = uigetfile({'*.m';'*.mlx';'*.mlapp'},'Select script or app file',app.ScriptPathTextArea.Value{1});
            output = [path file];
            if isequal(file,0) || ~isfile(output)
                output = '';
            end
        end
        function result = migrateMainObj(app, window)
            if ~isequal(class(window),'double') && isvalid(window) %
                result = window.mainObj;
            else
                result = nan;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            loadFromMemFileToApp(app);
        end

        % Button pushed function: DownloadSampleScriptButton
        function DownloadSampleScriptButtonPushed(app, event)
            %select folder function
            newFile = fullfile(folder,'ANewFile.m');
            fid = fopen(newFile,'w');
            fprintf(fid,'Z = magic(5);\n');
            fprintf(fid,'b = bar3(Z);\n');
            fclose(fid);
        end

        % Button pushed function: AddScriptButton
        function AddScriptButtonPushed(app, event)
            output = selectFile(app);
            app.ScriptPathTextArea.Value = output;
            figure(app.CustomscriptUIFigure);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            appStruct = app.callingApp.structDevice_OPMrubin;
            appStructNames = fieldnames(appStruct);
            if ~isempty(appStructNames)
                for i = 1:length(appStructNames)
                    mainApp.struct_OPMrubin.(appStructNames{i}) = appStruct.(appStructNames{i}).mainObj;
                end
            end
            
            mainApp.OSAyokogawa = migrateMainObj(app, app.callingApp.AddDevice_OSAyokogawa);
            mainApp.FPGA = migrateMainObj(app, app.callingApp.AddDevice_FPGA);
            mainApp.ATTENniMyDaq  = migrateMainObj(app, app.callingApp.AddDevice_ATTENniMyDaq);
            mainApp.SWITCHosaEdfa = migrateMainObj(app, app.callingApp.AddDevice_SWITCHosaEdfa);
            mainApp.mainWindow = app.callingApp;
            assignin('base','mainApp',mainApp);
            
            run('custom_migrations.m');
            addStrOutput(app.callingApp, 'Open apps migrated to MATLAB workspace');
            addStrOutput(app.callingApp, 'Script launched');
            run(app.ScriptPathTextArea.Value{1});
            addStrOutput(app.callingApp, 'Script finished');
        end

        % Close request function: CustomscriptUIFigure
        function CustomscriptUIFigureCloseRequest(app, event)
            fig = app.CustomscriptUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CustomscriptUIFigure = uifigure('Visible', 'off');
            app.CustomscriptUIFigure.Name = 'Custom script';
            app.CustomscriptUIFigure.Icon = 'gu_action.png';
            app.CustomscriptUIFigure.CloseRequestFcn = createCallbackFcn(app, @CustomscriptUIFigureCloseRequest, true);
            app.CustomscriptLabel = uilabel(app.CustomscriptUIFigure);
            app.CustomscriptLabel.Text = 'Custom script';
            app.StartPanel = uipanel(app.CustomscriptUIFigure);
            app.SelectyourscriptLabel = uilabel(app.StartPanel);
            app.SelectyourscriptLabel.Text = 'Select script';
            app.ScriptPathTextArea = uitextarea(app.StartPanel);
            app.TipImage = uiimage(app.StartPanel);
            app.TipImage.Tooltip = {'Make sure you select *.m or *.mlapp file'};
            app.TipImage.ImageSource = 'gu_help.png';
            app.AddScriptButton = uibutton(app.StartPanel, 'push');
            app.AddScriptButton.ButtonPushedFcn = createCallbackFcn(app, @AddScriptButtonPushed, true);
            app.AddScriptButton.Icon = 'gu_add-property.png';
            app.AddScriptButton.Tooltip = {'Select script'};
            app.AddScriptButton.Text = '';
            app.RunButton = uibutton(app.StartPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'icons8-play-96.png';
            app.RunButton.Tooltip = {'Run script'};
            app.RunButton.Text = '';
            app.DownloadsamplescriptLabel = uilabel(app.StartPanel);
            app.DownloadsamplescriptLabel.Text = 'Download sample script';
            app.DownloadSampleScriptButton = uibutton(app.StartPanel, 'push');
            app.DownloadSampleScriptButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadSampleScriptButtonPushed, true);
            app.DownloadSampleScriptButton.Icon = 'gu_download.png';
            app.DownloadSampleScriptButton.Enable = 'off';
            app.DownloadSampleScriptButton.Tooltip = {'Download sample'};
            app.DownloadSampleScriptButton.Text = '';
            % Show the figure after all components are created
            app.CustomscriptUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCustomScript(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CustomscriptUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CustomscriptUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CustomscriptUIFigure)
        end
    end
end