classdef appTestEDFA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        finishedLabel            matlab.ui.control.Label
        autooffsetButton         matlab.ui.control.Button
        offsetLabel              matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        zoomInButton             matlab.ui.control.Button
        SelectunitsDropDown      matlab.ui.control.DropDown
        Label_4                  matlab.ui.control.Label
        SelectmodeDropDown       matlab.ui.control.DropDown
        SelectmodeDropDownLabel  matlab.ui.control.Label
        UserTextEditField        matlab.ui.control.EditField
        Label_3                  matlab.ui.control.Label
        downloadTableButton      matlab.ui.control.Button
        startButton              matlab.ui.control.Button
        LineDropDown             matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        tableTextArea            matlab.ui.control.TextArea
        Label_2                  matlab.ui.control.Label
        schemeImage              matlab.ui.control.Image
        schemeLabel              matlab.ui.control.Label
        HeaderLabel              matlab.ui.control.Label
    end


    properties (Access = public)
        fpga % Description
        osa
        opm
        switchOsaEdfa
        dicon
        sensor
    end
    properties
        callingApp
        tableAutoTestPath
        tableAutoTest
        rowNumber
        opticLineNumber
        folder
        tableName
        %strForWaveform
        %time
        mode
    end

    methods (Access = private)

        function app = readTable(app)

            app.tableAutoTest = readtable(app.tableAutoTestPath,'Sheet','Sample');
            app.rowNumber = height(app.tableAutoTest);
            %row1 = app.tableAutoTest(1,:);
            %save('test.mat','row1')
        end

        function autoSetOffset(app)
            %             loss6_5 = 1.16;
            %             loss4_5 = 0.83;
            %
            %             app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
            %             writeReferenceTrace(app.osa);
            %             refPower = readReferencePower(app.osa);
            %             app.opm = readPowerdBm(app.opm);
            %             powerReal = app.opm.lastReadPower;
            %             offsetValue = powerReal - refPower;
            %             applyOffsetSettings(app.osa,offsetValue,'in');
            %             offsetPowOut = loss4_5 + (offsetValue - loss6_5);
            %             applyOffsetSettings(app.osa,offsetPowOut,'out');
            %             applyOffsetSettings(app.osa,offsetPowOut,'power');

        end

        function readSensor(app)


        end

        function app = autoTestEDFA(app)
            app.opticLineNumber = app.LineDropDown.Value;
            setLabelsProgressAxes(app.callingApp,'(blue-real,red-theor.)','Power Input','Power Output'); % (blue-real,red-theor.)
            setLabelsProgressAxes2(app.callingApp,'Max NF vs Power Input','Power Input','Max NF');
            addStrOutput(app.callingApp,'Началось авто-тестирование БОУ');
            refreshProgress(app.callingApp,0);
            app.tableName = createFilename(app.UserTextEditField.Value, now);

            for i = 1:app.rowNumber

                %disp(1)
                %clearMainTable(app.callingApp);
                newRow = {'Номер измерения',i};
                appendMainTable(app.callingApp,newRow);

                app.fpga = readDataFromFPGA(app.fpga,'TEMPERATURE_LD');
                temperatureLD = str2double(app.fpga.lastRead)/100;
                newRow = {'Температура LD',temperatureLD};
                appendMainTable(app.callingApp,newRow);

                app.fpga = readDataFromFPGA(app.fpga,'TEMP_CS');
                tempCS = str2double(app.fpga.lastRead)/10;
                newRow = {'Температура CS',tempCS};
                appendMainTable(app.callingApp,newRow);

                %                 newRow = {'Температура с датчика',temperatureSens};
                %                 appendMainTable(app.callingApp,newRow);
                if i ~= 1 && app.tableAutoTest{i,'InputPowerThDBm'} == app.tableAutoTest{i-1,'InputPowerThDBm'}
                    n = 1;
                else
                    n = 3;
                end
                for j = 1:n
                    app.opm = readPowerdBm(app.opm);
                    powerReal = app.opm.lastReadPower;
                    powerTheor = app.tableAutoTest{i,'InputPowerThDBm'};
                    currentAttenuation = app.dicon.attenuation;
                    %                 disp('powerReal')
                    %                 disp(powerReal)
                    %                 disp('currentAttenuation')
                    %                 disp(currentAttenuation)
                    attenuation = powerReal - powerTheor + currentAttenuation;
                    %disp(app.tableAutoTest{i,'AttenuationForInputPowerDBm'})
                    %                 disp(attenuation)
                    app.tableAutoTest{i,'AttenuationForInputPowerDBm'} = attenuation;
                    %DiconAtten = attenuation;
                    app.dicon = setAttenuation(app.dicon,attenuation);
                    pause(1)
                end
                addStrOutput(app.callingApp,['Выставлена аттенюация ' num2strForPrint(attenuation) ' дБм']);

                pauseSec = app.tableAutoTest{i,'PauseAfterAttenuationS'};
                addStrOutput(app.callingApp,['Ожидание ' num2strForPrint(pauseSec) ' с']);
                pause(pauseSec);

                app.opm = readPowerdBm(app.opm);
                powerReal = app.opm.lastReadPower;

                app.tableAutoTest{i,'InputPowerRealDBm'} = powerReal;
                disp('powerReal')
                disp(powerReal)
                newRow = {'Входная мощность (OPM), дБм',powerReal};
                appendMainTable(app.callingApp,newRow);
                addStrOutput(app.callingApp,'Измерена входная мощность OPM');

                app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
                addStrOutput(app.callingApp,'Ключ: сигнал - ОСА');

                writeReferenceTrace(app.osa);
                app.osa = readAndSaveReference(app.osa);
                addStrOutput(app.callingApp,'Опорный сигнал записан');

                app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                addStrOutput(app.callingApp,'Ключ: сигнал - БОУ');

                enableWarningLaser(app.callingApp);
                app.fpga = turnOnOffDiodes(app.fpga,'on');
                addStrOutput(app.callingApp,'Диоды включены');
                pause(1)

                %%%% ЧАСТЬ РАБОТЫ С ТАБЛИЦЕЙ %%%%
                varNames = app.tableAutoTest.Properties.VariableNames;
                name1 = varNames{1,5};
                name2 = varNames{1,6};

                app.fpga = writeDataToFPGA(app.fpga,'MODE','0');
pause(1)
                wvalue1 = app.tableAutoTest{i,name1};
                wvalue2 = app.tableAutoTest{i,name2};
                %disp(wvalue2)
                if isequal(app.SelectmodeDropDown.Value,'2')
                    
                    if isequal(app.SelectunitsDropDown.Value,'dBm')
                        if strcmpi(wvalue1,{'-Inf'})
                            wvalue1 = 0;
                        else
                            wvalue1 = round(PoutPowerToADC(wvalue1,'dBm'));
                        end
                        if strcmpi(wvalue2,{'-Inf'})
                            wvalue2 = 0;
                        else
                            wvalue2 = round(PoutPowerToADC(wvalue2,'dBm'));
                        end
                    end
                end
disp('p1_output')
disp(wvalue1)
    disp('p2_output')      
    disp(wvalue2)
                wvalue1 = num2str(wvalue1);
                app.fpga = writeDataToFPGA(app.fpga,name1,wvalue1);
                addStrOutput(app.callingApp,['Записано ' name1 ' = ' wvalue1]);

                wvalue2 = num2str(wvalue2);
                app.fpga = writeDataToFPGA(app.fpga,name2,wvalue2);
                addStrOutput(app.callingApp,['Записано ' name2 ' = ' wvalue2]);

                 app.fpga = writeDataToFPGA(app.fpga,'MODE',app.SelectmodeDropDown.Value);
                 addStrOutput(app.callingApp,'Установлен режим уставки выходной мощности');
                
                pauseSec = app.tableAutoTest{i,'PauseAfterSetOutputPowerS'};
                addStrOutput(app.callingApp,['Ожидание ' num2strForPrint(pauseSec) ' с']);
                pause(pauseSec);

                writeWaveformTrace(app.osa);
                app.osa = readWaveform(app.osa);
                addStrOutput(app.callingApp,'Спектр записан');

                app.osa = readAnalysisEDFANF(app.osa);
                addStrOutput(app.callingApp,'Результаты анализа EDFA-NF записаны');

                app.osa = readPower(app.osa);
                app.tableAutoTest{i,'OutputPowerRealDBmOSA'} = app.osa.power(end);
                newRow = {'Выходная мощность (OSA), дБм',app.osa.power(end)};
                appendMainTable(app.callingApp,newRow);
                %savePower(app.osa);

                app.tableAutoTest{i,'MaxNF'} = app.osa.maxNF;
                newRow = {'Максимальный ШФ',app.osa.maxNF};
                appendMainTable(app.callingApp,newRow);

                app.tableAutoTest{i,'MeanGAIN'} = app.osa.meanGAIN;
                newRow = {'Средний КУ',app.osa.meanGAIN};
                appendMainTable(app.callingApp,newRow);

                app.tableAutoTest{i,'DeltaGAIN'} = app.osa.deltaGAIN;
                newRow = {'Нелинейность',app.osa.deltaGAIN};
                appendMainTable(app.callingApp,newRow);
                
                app.fpga = readDataFromFPGA(app.fpga,'POUT1_FILT_CAL');
                app.tableAutoTest{i,'POUT1_FILT_CAL'} = str2double(app.fpga.lastRead);
                app.fpga = readDataFromFPGA(app.fpga,'POUT2_FILT_CAL');
                app.tableAutoTest{i,'POUT2_FILT_CAL'} = str2double(app.fpga.lastRead);

                app.fpga = turnOnOffDiodes(app.fpga,'off');
                %setOutputPowerEDFA(app.fpga,app.opticLineNumber,'0');
                app.fpga = writeDataToFPGA(app.fpga,name1,'0');%%%%
                app.fpga = writeDataToFPGA(app.fpga,name2,'0');%%%%
                disableWarningLaser(app.callingApp);
                addStrOutput(app.callingApp,'Диоды выключены');

                s0 = app.LineDropDown.Value;
                s1 = ['Pin_' num2strForPrint(powerReal)];
                s2 = ['Pout_' num2strForPrint(app.osa.power(end))];
                s3 = ['NFmax_' num2strForPrint(app.osa.maxNF)];
                strForWaveform = [s0 '_' s1 '_' s2 '_' s3];
                app.osa = saveWaveformToPC(app.osa,strForWaveform);
                plotWaveformYoko(app.osa,app.callingApp);
                saveAnalysisEDFANF(app.osa,strForWaveform);
                addStrOutput(app.callingApp,'Спектр и результаты анализа сохранены');

                x = powerReal; %входная Р измеренная
                y = app.osa.power(end); %вых Р измеренная
                color = 'blue';
                %legend1 = 'Выходная мощность (OSA)';
                addPointProgressAxes(app.callingApp,x,y,color);

                %                 y = app.tableAutoTest{i,'OutputPowerThDBm'};%вых Р теоретич
                %                 color = 'red';
                %                 %legend2 = 'Выходная мощность (из таблицы)';
                %                 addPointProgressAxes(app.callingApp,x,y,color);
                %%%
                %pause(1)

                y = app.tableAutoTest{i,'MaxNF'};
                color = 'red';
                addPointProgressAxes2(app.callingApp,x,y,color);
                %setLegendProgressAxes(app.callingApp,{legend1,legend2});
                newProgress = round(i/app.rowNumber)*100;
                refreshProgress(app.callingApp,newProgress);
                writetable(app.tableAutoTest,[app.folder app.tableName '.xlsx'],'AutoFitWidth',false);
                pause(3)
            end
            app.dicon = setAttenuation(app.dicon,0);
            addStrOutput(app.callingApp,'Авто-тестирование БОУ завершено');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;

            app.fpga = MainApp.calledAppFPGA.fpga;
            app.osa = MainApp.calledAppOSA.osa;

            if ~isempty(MainApp.calledAppOPMrubin)
                app.opm = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(1);
            end
            if ~isempty(MainApp.calledAppOPMthorlabs)
                app.opm = MainApp.calledAppOPMthorlabs.opm;
            end

            app.switchOsaEdfa = MainApp.calledAppSWITCH.switchOsaEdfa;
            app.dicon = MainApp.calledAppATTENdicon.attenuatorDicon;
            %app.sensor = MainApp.calledAppSENSOR.sensor;
            app.tableAutoTestPath = "autoTestCurrent.xlsx";
            app = readTable(app);
        end

        % Button pushed function: autooffsetButton
        function autooffsetButtonPushed(app, event)
            autoSetOffset(app);
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app = autoTestEDFA(app);
            addStrOutput(app.callingApp,'Файлы сохранены');
        end

        % Button pushed function: downloadTableButton
        function downloadTableButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select table for auto-test','autoTestPout.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableAutoTestPath = output;
            app.tableTextArea.Value = {output};
            app = readTable(app);
        end

        % Value changed function: SelectmodeDropDown
        function SelectmodeDropDownValueChanged(app, event)
            value = app.SelectmodeDropDown.Value;
            if isequal(value,'2')
                app.SelectunitsDropDown.Enable = 'on';
            elseif isequal(value,'0')
                app.SelectunitsDropDown.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Test EDFA';
            app.Panel = uipanel(app.UIFigure);
            app.schemeLabel = uilabel(app.Panel);
            app.schemeLabel.Text = 'Assemble circuit';
            app.schemeImage = uiimage(app.Panel);
            app.schemeImage.ImageSource = 'EDFA_Calibrate.PNG';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Select table for test EDFA';
            app.tableTextArea = uitextarea(app.Panel);
            app.tableTextArea.Value = {'autoTestCurrent.xlsx'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Select optical line';
            app.LineDropDown = uidropdown(app.Panel);
            app.LineDropDown.Items = {'L1', 'L2'};
            app.LineDropDown.Value = 'L1';
            app.startButton = uibutton(app.Panel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Icon = 'icons8-play-96.png';
            app.startButton.Text = 'Run test';
            app.downloadTableButton = uibutton(app.Panel, 'push');
            app.downloadTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadTableButtonPushed, true);
            app.downloadTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadTableButton.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Enter filename';
            app.UserTextEditField = uieditfield(app.Panel, 'text');
            app.UserTextEditField.Value = 'B01_testEDFA_';
            app.SelectmodeDropDownLabel = uilabel(app.Panel);
            app.SelectmodeDropDownLabel.Text = 'Select mode';
            app.SelectmodeDropDown = uidropdown(app.Panel);
            app.SelectmodeDropDown.Items = {'0', '2'};
            app.SelectmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectmodeDropDownValueChanged, true);
            app.SelectmodeDropDown.Value = '0';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = 'Select units';
            app.SelectunitsDropDown = uidropdown(app.Panel);
            app.SelectunitsDropDown.Items = {'dBm', 'ADC'};
            app.SelectunitsDropDown.Enable = 'off';
            app.SelectunitsDropDown.Value = 'dBm';
            app.zoomInButton = uibutton(app.Panel, 'push');
            app.zoomInButton.Icon = 'gu_zoomin.png';
            app.zoomInButton.Text = '';
            app.offsetLabel = uilabel(app.UIFigure);
            app.offsetLabel.Text = 'Произведите настройку оффсетов на спектроанализаторе (OSA) самостоятельно или включите ее в авто-режиме';
            app.autooffsetButton = uibutton(app.UIFigure, 'push');
            app.autooffsetButton.ButtonPushedFcn = createCallbackFcn(app, @autooffsetButtonPushed, true);
            app.autooffsetButton.Enable = 'off';
            app.autooffsetButton.Text = 'auto offset';
            app.finishedLabel = uilabel(app.UIFigure);
            app.finishedLabel.Visible = 'off';
            app.finishedLabel.Text = 'finished';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestEDFA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end