classdef appTestFPGA_Attenuator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestattenuatorUIFigure          matlab.ui.Figure
        ApplyButton                     matlab.ui.control.StateButton
        TestCircuitTwoPanel             matlab.ui.container.Panel
        ApplyTestTwoButton              matlab.ui.control.Button
        ReadPowerTestTwoButton          matlab.ui.control.Button
        SaveButtonLabel                 matlab.ui.control.Label
        AssembleopticalcircuitTwoLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        dBmTwoLabel                     matlab.ui.control.Label
        PinputEditField                 matlab.ui.control.NumericEditField
        PinputEditFieldLabel            matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        FPGAaddressDropDown             matlab.ui.control.DropDown
        FPGAaddressDropDownLabel        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        SelectSampleTableTextArea       matlab.ui.control.TextArea
        SelectComponentsTableTextArea   matlab.ui.control.TextArea
        Label_23                        matlab.ui.control.Label
        downloadSampleTableButton       matlab.ui.control.Button
        downloadComponentsTableButton   matlab.ui.control.Button
        TestmodeDropDown                matlab.ui.control.DropDown
        TestmodeDropDownLabel           matlab.ui.control.Label
        CommentEditField                matlab.ui.control.EditField
        CommentEditFieldLabel           matlab.ui.control.Label
        BoardnumberEditField            matlab.ui.control.EditField
        BoardnumberEditFieldLabel       matlab.ui.control.Label
        DesignationEditField            matlab.ui.control.EditField
        DesignationLabel                matlab.ui.control.Label
        SerialnumberEditField           matlab.ui.control.EditField
        SerialnumberLabel               matlab.ui.control.Label
        InspectornameEditField          matlab.ui.control.EditField
        InspectornameEditFieldLabel     matlab.ui.control.Label
        TestFPGAattenuatorLabel         matlab.ui.control.Label
        TestCircuitOnePanel             matlab.ui.container.Panel
        WriteDACTestOneButton           matlab.ui.control.Button
        ReadPowerTestOneButton          matlab.ui.control.Button
        ZoomInOneButton                 matlab.ui.control.Button
        CircuitOneImage                 matlab.ui.control.Image
        AssembleopticalcircuitLabel     matlab.ui.control.Label
        dBmOneLabel                     matlab.ui.control.Label
        DACvalueEditField               matlab.ui.control.NumericEditField
        DACvalueEditFieldLabel          matlab.ui.control.Label
        PoutputEditField                matlab.ui.control.NumericEditField
        PoutputEditFieldLabel           matlab.ui.control.Label
        ApplyTestOneButton              matlab.ui.control.Button
    end


    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        OPMrubin
        FPGA
        mainObj
    end
    properties (Constant)
        TestmodeValueAutoRubin = 'auto (rubin)'
        TestmodeValueManual = 'manual'
    end
    methods (Access = public)
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appTestFPGA_Attenuator');
            app.SelectComponentsTableTextArea.Value = appTestFPGA_Attenuator.SelectComponentsTableTextArea;
            app.SelectSampleTableTextArea.Value = appTestFPGA_Attenuator.SelectSampleTableTextArea;
            app.BoardnumberEditField.Value = appTestFPGA_Attenuator.BoardnumberEditField;
            app.DesignationEditField.Value = appTestFPGA_Attenuator.DesignationEditField;
            app.SerialnumberEditField.Value = appTestFPGA_Attenuator.SerialnumberEditField;
            app.CommentEditField.Value = appTestFPGA_Attenuator.CommentEditField;
            app.InspectornameEditField.Value = appTestFPGA_Attenuator.InspectornameEditField;
            app.TestmodeDropDown.Value = appTestFPGA_Attenuator.TestmodeDropDown;
            app.FPGAaddressDropDown.Value = appTestFPGA_Attenuator.FPGAaddressDropDown;
        end
        function saveFromAppToMemFile(app)
            appTestFPGA_Attenuator.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
            appTestFPGA_Attenuator.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
            appTestFPGA_Attenuator.BoardnumberEditField = app.BoardnumberEditField.Value;
            appTestFPGA_Attenuator.DesignationEditField = app.DesignationEditField.Value;
            appTestFPGA_Attenuator.SerialnumberEditField = app.SerialnumberEditField.Value;
            appTestFPGA_Attenuator.CommentEditField = app.CommentEditField.Value;
            appTestFPGA_Attenuator.InspectornameEditField = app.InspectornameEditField.Value;
            appTestFPGA_Attenuator.TestmodeDropDown = app.TestmodeDropDown.Value;
            appTestFPGA_Attenuator.FPGAaddressDropDown = app.FPGAaddressDropDown.Value;
            save('TheGreatUniterMemory.mat','appTestFPGA_Attenuator','-append');
        end
        function checkTestMode(app)
            if app.TestmodeDropDown.Value == app.TestmodeValueAutoRubin
                app.AssembleopticalcircuitLabel.Text = "Assemble optical" + ...
                    "circuit with attenuator and push Run";
                app.DACvalueEditField.Editable = 'off';
                app.PoutputEditField.Editable = 'off';
                app.ApplyTestOneButton.Text = "Run";
                app.ReadPowerTestOneButton.Visible = 'off';
                app.WriteDACTestOneButton.Visible = 'off';
                app.AssembleopticalcircuitTwoLabel.Text = "Assemble optical " + ...
                    "circuit for reference power (without attenuator), " + ...
                    "push Read and Apply";
            elseif app.TestmodeDropDown.Value == app.TestmodeValueManual
                app.AssembleopticalcircuitLabel.Text = "Assemble optical " + ...
                    "circuit with attenuator, fill in DAC and power values";
                app.DACvalueEditField.Editable = 'on';
                app.PoutputEditField.Editable = 'on';
                app.ApplyTestOneButton.Text = "Add";
                app.ReadPowerTestOneButton.Visible = 'on';
                app.WriteDACTestOneButton.Visible = 'on';
                app.AssembleopticalcircuitTwoLabel.Text = "Assemble optical " + ...
                    "circuit for reference power (without attenuator), " + ...
                    "fill in input power and push Apply";
            end
        end
        function checkFPGAaddressName(app)
            value = app.FPGAaddressDropDown.Value;
            output = whatFRM(app.FPGA,value);
            if output == "ERR"
                app.FPGAaddressDropDown.BackgroundColor = app.callingApp.errorColor;
                output = 'Enter correct FPGA address name';
                addStrOutput(app.callingApp,output);
                error(output);
            else
                app.FPGAaddressDropDown.BackgroundColor = app.callingApp.defaultColor;
            end
        end
        function checkFieldsNotEmpty(app)
            fieldsArray = {...
                app.SelectComponentsTableTextArea,...
                app.SelectSampleTableTextArea,...
                app.BoardnumberEditField,...
                app.DesignationEditField,...
                app.SerialnumberEditField,...
                app.CommentEditField,...
                app.InspectornameEditField,...
                app.FPGAaddressDropDown,...
                };
            for i = 1:length(fieldsArray)
                if iscell(fieldsArray{i}.Value)
                    value = fieldsArray{i}.Value{1};
                else
                    value = fieldsArray{i}.Value;
                end
                if isempty(value)
                    fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
                    output = 'Fields must not be empty';
                    addStrOutput(app.callingApp,output)
                    error(output);
                else
                    fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                end
            end
        end
        function createMainObj(app)
            appStruct = struct(...
                'time',now, ...
                'folder',app.folder, ...
                'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
                'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
                'boardNumber',app.BoardnumberEditField.Value, ...
                'designation',app.DesignationEditField.Value, ...
                'serialNumber',app.SerialnumberEditField.Value, ...
                'comment',app.CommentEditField.Value, ...
                'inspectorName',app.InspectornameEditField.Value ...
                );
            app.mainObj = FPGA_Attenuator(appStruct);
        end
        function saveMatFile(app)
            attenuator = app.mainObj;
            attenuatorName = app.mainObj.tableName;%createFilename(app.mainObj.time,'AT');
            save([app.folder attenuatorName '.mat'],'attenuator');
        end
        function deleteMainObj(app)
            app.mainObj = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            app.TestmodeDropDown.Items = {app.TestmodeValueAutoRubin};
            app.TestmodeDropDown.Value = app.TestmodeValueAutoRubin;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
                if isvalid(MainApp.AddDevice_OPMrubin)
                    if ~isempty(MainApp.AddDevice_OPMrubin)
                        app.OPMrubin = MainApp.AddDevice_OPMrubin.mainObj;
                        app.ReadPowerTestOneButton.Enable = 'on';
                        app.ReadPowerTestTwoButton.Enable = 'on';
                    end
                end
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: downloadSampleTableButton
        function downloadSampleTableButtonPushed(app, event)
            output = selectTable(app.SelectSampleTableTextArea.Value{1});
            app.SelectSampleTableTextArea.Value = output;
        end

        % Button pushed function: ApplyTestOneButton
        function ApplyTestOneButtonPushed(app, event)
            if app.TestmodeDropDown.Value == app.TestmodeValueAutoRubin
                output = 'Auto test started (for attenuator)';
                addStrOutput(app.callingApp,output);
                for i = 1:length(app.mainObj.DAC)
                    currentDAC = app.mainObj.DAC(i);
                    name = app.FPGAaddressDropDown.Value;
                    data = num2str(currentDAC);
                    mem = app.FPGA.FLASH_MEM;
                    app.FPGA = writeAndReadData(app.FPGA,mem,name,data);
                    app.DACvalueEditField.Value = currentDAC;
                    output = ['Value ',data,' was write to FPGA address ',name];
                    addStrOutput(app.callingApp,output);
                    pauseS = app.mainObj.pauseTime(i);
                    output = ['Wait for ',num2str(pauseS),' s'];
                    addStrOutput(app.callingApp,output);
                    pause(pauseS);
                    app.OPMrubin = requestPower(app.OPMrubin);
                    currentPower = app.OPMrubin.powerdBm(end);
                    app.PoutputEditField.Value = currentPower;
                    app.mainObj = addOutputPower(app.mainObj,currentPower);
                    output = ['OPM detect ',num2str(currentPower),' dBm'];
                    addStrOutput(app.callingApp,output);
                    progress = round(i/length(app.mainObj.DAC)*100);
                    refreshProgress(app.callingApp,progress);
                end
                output = 'Auto test finished (for attenuator)';
                addStrOutput(app.callingApp,output);
                app.TestCircuitOnePanel.Enable = 'off';
                app.TestCircuitTwoPanel.Enable = 'on';
            %elseif app.TestmodeDropDown.Value == app.TestmodeValueManual

            end
        end

        % Button pushed function: ApplyTestTwoButton
        function ApplyTestTwoButtonPushed(app, event)
            app.mainObj = addInputPower(app.mainObj,app.PinputEditField.Value);
            app.mainObj = calculateAttenuation(app.mainObj);
            app.mainObj = setCriterias(app.mainObj);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveTables(app.mainObj);
            saveMatFile(app);
            output = 'Files for attenuator was saved';
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: ZoomInOneButton
        function ZoomInOneButtonPushed(app, event)
            imageview(app.CircuitOneImage.ImageSource);
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Apply';
                    app.SettingsPanel.Enable = 'on';
                    app.TestCircuitOnePanel.Enable = 'off';
                    app.TestCircuitTwoPanel.Enable = 'off';
                    deleteMainObj(app);
                case 1
                    app.ApplyButton.Text = 'Restart';
                    app.SettingsPanel.Enable = 'off';
                    app.TestCircuitOnePanel.Enable = 'on';
                    checkFieldsNotEmpty(app);
                    checkTestMode(app);
                    createMainObj(app);
            end
        end

        % Close request function: TestattenuatorUIFigure
        function TestattenuatorUIFigureCloseRequest(app, event)
            fig = app.TestattenuatorUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end

        % Value changed function: DesignationEditField
        function DesignationEditFieldValueChanged(app, event)
            value = app.DesignationEditField.Value;
            ind = find(contains(app.FPGAaddressDropDown.Items,value)==1);
            if length(ind) == 1
                app.FPGAaddressDropDown.Value = app.FPGAaddressDropDown.Items(ind);
                app.FPGAaddressDropDown.BackgroundColor = app.callingApp.customColor;
            end
        end

        % Value changed function: FPGAaddressDropDown
        function FPGAaddressDropDownValueChanged(app, event)
            checkFPGAaddressName(app);
        end

        % Button pushed function: ReadPowerTestTwoButton
        function ReadPowerTestTwoButtonPushed(app, event)
            app.OPMrubin = requestPower(app.OPMrubin);
            currentPower = app.OPMrubin.powerdBm(end);
            app.PinputEditField.Value = currentPower;
            output = ['OPM detect ',num2str(currentPower),' dBm'];
            addStrOutput(app.callingApp,output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestattenuatorUIFigure = uifigure('Visible', 'off');
            app.TestattenuatorUIFigure.Name = 'Test attenuator';
            app.TestattenuatorUIFigure.Icon = 'gu_action.png';
            app.TestattenuatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @TestattenuatorUIFigureCloseRequest, true);
            app.TestCircuitOnePanel = uipanel(app.TestattenuatorUIFigure);
            app.TestCircuitOnePanel.Enable = 'off';
            app.ApplyTestOneButton = uibutton(app.TestCircuitOnePanel, 'push');
            app.ApplyTestOneButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTestOneButtonPushed, true);
            app.ApplyTestOneButton.Text = 'Apply';
            app.PoutputEditFieldLabel = uilabel(app.TestCircuitOnePanel);
            app.PoutputEditFieldLabel.Text = 'P output';
            app.PoutputEditField = uieditfield(app.TestCircuitOnePanel, 'numeric');
            app.PoutputEditField.ValueDisplayFormat = '%11.2g';
            app.DACvalueEditFieldLabel = uilabel(app.TestCircuitOnePanel);
            app.DACvalueEditFieldLabel.Text = 'DAC value';
            app.DACvalueEditField = uieditfield(app.TestCircuitOnePanel, 'numeric');
            app.DACvalueEditField.Limits = [0 Inf];
            app.DACvalueEditField.ValueDisplayFormat = '%.0f';
            app.DACvalueEditField.Editable = 'off';
            app.dBmOneLabel = uilabel(app.TestCircuitOnePanel);
            app.dBmOneLabel.Text = 'dBm';
            app.AssembleopticalcircuitLabel = uilabel(app.TestCircuitOnePanel);
            app.AssembleopticalcircuitLabel.Text = 'Assemble optical circuit with attenuator, fill in power values (manual mode) or press Apply (auto mode)';
            app.CircuitOneImage = uiimage(app.TestCircuitOnePanel);
            app.CircuitOneImage.ImageSource = 'FPGA_attenuator.png';
            app.ZoomInOneButton = uibutton(app.TestCircuitOnePanel, 'push');
            app.ZoomInOneButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInOneButtonPushed, true);
            app.ZoomInOneButton.Icon = 'gu_zoomin.png';
            app.ZoomInOneButton.Tooltip = {'Zoom in'};
            app.ZoomInOneButton.Text = '';
            app.ReadPowerTestOneButton = uibutton(app.TestCircuitOnePanel, 'push');
            app.ReadPowerTestOneButton.Enable = 'off';
            app.ReadPowerTestOneButton.Visible = 'off';
            app.ReadPowerTestOneButton.Text = 'read';
            app.WriteDACTestOneButton = uibutton(app.TestCircuitOnePanel, 'push');
            app.WriteDACTestOneButton.Visible = 'off';
            app.WriteDACTestOneButton.Text = 'write';
            app.TestFPGAattenuatorLabel = uilabel(app.TestattenuatorUIFigure);
            app.TestFPGAattenuatorLabel.Text = 'Test FPGA attenuator';
            app.SettingsPanel = uipanel(app.TestattenuatorUIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.SettingsPanel);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.SettingsPanel, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.SerialnumberLabel = uilabel(app.SettingsPanel);
            app.SerialnumberLabel.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.SerialnumberEditField.Value = '15B6KMG01869';
            app.DesignationLabel = uilabel(app.SettingsPanel);
            app.DesignationLabel.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.SettingsPanel, 'text');
            app.DesignationEditField.ValueChangedFcn = createCallbackFcn(app, @DesignationEditFieldValueChanged, true);
            app.DesignationEditField.Value = 'A1';
            app.BoardnumberEditFieldLabel = uilabel(app.SettingsPanel);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditFieldLabel = uilabel(app.SettingsPanel);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.SettingsPanel, 'text');
            app.CommentEditField.Value = 'T = 22 C';
            app.TestmodeDropDownLabel = uilabel(app.SettingsPanel);
            app.TestmodeDropDownLabel.Text = 'Test mode';
            app.TestmodeDropDown = uidropdown(app.SettingsPanel);
            app.TestmodeDropDown.Items = {'auto (rubin)'};
            app.TestmodeDropDown.Value = 'auto (rubin)';
            app.downloadComponentsTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.downloadSampleTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadSampleTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadSampleTableButtonPushed, true);
            app.downloadSampleTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadSampleTableButton.Text = '';
            app.Label_23 = uilabel(app.SettingsPanel);
            app.Label_23.Text = 'Select table with all components';
            app.SelectComponentsTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectComponentsTableTextArea.Value = {'tableComponent.xlsx'};
            app.SelectSampleTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectSampleTableTextArea.Value = {'tableAttenuator.xlsx'};
            app.Label_24 = uilabel(app.SettingsPanel);
            app.Label_24.Text = 'Select sample table for attenuator';
            app.FPGAaddressDropDownLabel = uilabel(app.SettingsPanel);
            app.FPGAaddressDropDownLabel.Text = 'FPGA address';
            app.FPGAaddressDropDown = uidropdown(app.SettingsPanel);
            app.FPGAaddressDropDown.Items = {'A1_ATTEN_L1', 'A2_ATTEN_L2', 'A3_ATTEN_TEST_L1_TO_L2', 'A4_ATTEN_TEST_L2_TO_L1', ''};
            app.FPGAaddressDropDown.Editable = 'on';
            app.FPGAaddressDropDown.ValueChangedFcn = createCallbackFcn(app, @FPGAaddressDropDownValueChanged, true);
            app.FPGAaddressDropDown.Value = 'A1_ATTEN_L1';
            app.TestCircuitTwoPanel = uipanel(app.TestattenuatorUIFigure);
            app.TestCircuitTwoPanel.Enable = 'off';
            app.PinputEditFieldLabel = uilabel(app.TestCircuitTwoPanel);
            app.PinputEditFieldLabel.Text = 'P input';
            app.PinputEditField = uieditfield(app.TestCircuitTwoPanel, 'numeric');
            app.dBmTwoLabel = uilabel(app.TestCircuitTwoPanel);
            app.dBmTwoLabel.Text = 'dBm';
            app.SaveButton = uibutton(app.TestCircuitTwoPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'gu_save.png';
            app.SaveButton.Tooltip = {'save'};
            app.SaveButton.Text = '';
            app.AssembleopticalcircuitTwoLabel = uilabel(app.TestCircuitTwoPanel);
            app.AssembleopticalcircuitTwoLabel.Text = 'Assemble optical circuit for reference power (without attenuator), fill in power value (manual mode) or press Apply (auto mode)';
            app.SaveButtonLabel = uilabel(app.TestCircuitTwoPanel);
            app.SaveButtonLabel.Text = 'If the test passed, press save button';
            app.ReadPowerTestTwoButton = uibutton(app.TestCircuitTwoPanel, 'push');
            app.ReadPowerTestTwoButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPowerTestTwoButtonPushed, true);
            app.ReadPowerTestTwoButton.Enable = 'off';
            app.ReadPowerTestTwoButton.Text = 'read';
            app.ApplyTestTwoButton = uibutton(app.TestCircuitTwoPanel, 'push');
            app.ApplyTestTwoButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTestTwoButtonPushed, true);
            app.ApplyTestTwoButton.Text = 'Apply';
            app.ApplyButton = uibutton(app.TestattenuatorUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.TestattenuatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_Attenuator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestattenuatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestattenuatorUIFigure)
        end
    end
end