classdef appTestFPGA_LaserDiode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestLaserDiodeUIFigure         matlab.ui.Figure
        DiodesSwitch                   matlab.ui.control.Switch
        ApplyButton                    matlab.ui.control.StateButton
        DiodesLamp                     matlab.ui.control.Lamp
        CheckOffsetPanel               matlab.ui.container.Panel
        OffsetHighRangeLabel           matlab.ui.control.Label
        Tip1Image                      matlab.ui.control.Image
        CheckButton                    matlab.ui.control.Button
        OffsetHighRangeEditField       matlab.ui.control.EditField
        TestCircuitPanel               matlab.ui.container.Panel
        CurrentLabel                   matlab.ui.control.Label
        ZoomInButton                   matlab.ui.control.Button
        CircuitImage                   matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        OutputpowerEditField           matlab.ui.control.NumericEditField
        OutputpowerEditFieldLabel      matlab.ui.control.Label
        SavevaluesButton               matlab.ui.control.Button
        Tip2Image                      matlab.ui.control.Image
        SaveButtonLabel                matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        CurrentEditField               matlab.ui.control.EditField
        WritetoFPGAButton              matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        WavelengthEditField            matlab.ui.control.EditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        CommentEditField               matlab.ui.control.EditField
        CommentEditField_2Label        matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditField_2Label    matlab.ui.control.Label
        DesignationEditField           matlab.ui.control.EditField
        DesignationEditField_2Label    matlab.ui.control.Label
        SerialnumberEditField          matlab.ui.control.EditField
        SerialnumberEditField_2Label   matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditField_2Label  matlab.ui.control.Label
        SelectComponentsTableTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        SelectSampleTableTextArea      matlab.ui.control.TextArea
        Label_2                        matlab.ui.control.Label
        downloadSampleTableButton      matlab.ui.control.Button
        downloadComponentsTableButton  matlab.ui.control.Button
        TestFPGAlaserdiodeLabel        matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        FPGA
        mainObj
    end
    properties (Access = public)
        LDDnumber
    end
    methods (Access = public)
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appTestFPGA_LaserDiode');
            app.SelectComponentsTableTextArea.Value = appTestFPGA_LaserDiode.SelectComponentsTableTextArea;
            app.SelectSampleTableTextArea.Value = appTestFPGA_LaserDiode.SelectSampleTableTextArea;
            app.BoardnumberEditField.Value = appTestFPGA_LaserDiode.BoardnumberEditField;
            app.DesignationEditField.Value = appTestFPGA_LaserDiode.DesignationEditField;
            app.SerialnumberEditField.Value = appTestFPGA_LaserDiode.SerialnumberEditField;
            app.CommentEditField.Value = appTestFPGA_LaserDiode.CommentEditField;
            app.InspectornameEditField.Value = appTestFPGA_LaserDiode.InspectornameEditField;
            app.WavelengthEditField.Value = appTestFPGA_LaserDiode.WavelengthEditField;
        end
        function saveFromAppToMemFile(app)
            appTestFPGA_LaserDiode.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
            appTestFPGA_LaserDiode.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
            appTestFPGA_LaserDiode.BoardnumberEditField = app.BoardnumberEditField.Value;
            appTestFPGA_LaserDiode.DesignationEditField = app.DesignationEditField.Value;
            appTestFPGA_LaserDiode.SerialnumberEditField = app.SerialnumberEditField.Value;
            appTestFPGA_LaserDiode.CommentEditField = app.CommentEditField.Value;
            appTestFPGA_LaserDiode.InspectornameEditField = app.InspectornameEditField.Value;
            appTestFPGA_LaserDiode.WavelengthEditField = app.WavelengthEditField.Value;
            save('TheGreatUniterMemory.mat','appTestFPGA_LaserDiode','-append');
        end
        function checkFieldsNotEmpty(app)
            fieldsArray = {...
                app.SelectComponentsTableTextArea,...
                app.SelectSampleTableTextArea,...
                app.BoardnumberEditField,...
                app.DesignationEditField,...
                app.SerialnumberEditField,...
                app.CommentEditField,...
                app.InspectornameEditField,...
                app.WavelengthEditField,...
                };
            for i = 1:length(fieldsArray)
                if iscell(fieldsArray{i}.Value)
                    value = fieldsArray{i}.Value{1};
                else
                    value = fieldsArray{i}.Value;
                end
                if isempty(value)
                    fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
                    output = 'Fields must not be empty';
                    addStrOutput(app.callingApp,output)
                    error(output);
                else
                    fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                end
            end
        end
        function getLDDnumber(app)
            app.LDDnumberStr = app.DesignationEditField.Value(end);
            app.LDDnumberNum = str2double(app.LDDnumberStr);
            app.OffsetHighRangeLabel.Text = ['LDD',app.LDDnumberStr,'_OFFSET_HIGH_RANGE'];
            app.CurrentLabel.Text = ['LDD',app.LDDnumberStr,'_CURRENT'];
        end
        function createMainObj(app)
            appStruct = struct(...
                'time',now, ...
                'folder',app.folder, ...
                'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
                'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
                'boardNumber',app.BoardnumberEditField.Value, ...
                'designation',app.DesignationEditField.Value, ...
                'serialNumber',app.SerialnumberEditField.Value, ...
                'comment',app.CommentEditField.Value, ...
                'inspectorName',app.InspectornameEditField.Value, ...
                'operatingWavelength_nm',app.WavelengthEditField.Value);
            app.mainObj = FPGA_LaserDiode(appStruct);
        end
        function checkOffsetHighRange(app)
            name = app.OffsetHighRangeLabel.Text;
            mem = app.FPGA.FLASH_MEM;
            app.FPGA = readData(app.FPGA,mem,name);
            dataFlash = app.FPGA.lastRead;
            mem = app.FPGA.EEPROM_MEM;
            app.FPGA = readData(app.FPGA,mem,name);
            dataEEPROM = app.FPGA.lastRead;
            if dataFlash ~= dataEEPROM
                error('dataFlash ~= dataEEPROM');
            end
            app.OffsetHighRangeEditField.Value = data;
            offsetHighRangeNum = str2double(data);
            app.mainObj = addOffsetHighRange(app.mainObj,offsetHighRangeNum);
            output = ['Value ',data,' was read from FPGA address ',name];
            addStrOutput(app.callingApp,output);
            if data ~= "0"
                app.TestCircuitPanel.Enable = 'on';
            else
                app.TestCircuitPanel.Enable = 'off';
                app.OffsetHighRangeEditField.BackgroundColor = app.callingApp.errorColor;
                output = 'Enter OFFSET HIGH RANGE in FPGA window';
                addStrOutput(app.callingApp,output);
            end
        end
        function turnDiodesON(app)
            app.FPGA = setDiodes(app.FPGA,'1111');
            app.DiodesLamp.Color = 'red';
            enableWarningLaser(app.callingApp);
            output = 'Diodes were turned ON';
            addStrOutput(app.callingApp,output);
        end
        function turnDiodesOFF(app)
            app.FPGA = setDiodes(app.FPGA,'0000');
            app.DiodesLamp.Color = app.callingApp.defaultColor;
            disableWarningLaser(app.callingApp);
            output = 'Diodes were turned OFF';
            addStrOutput(app.callingApp,output);
        end
        function checkAndSetDiodesON(app)
            if app.DiodesSwitch.Value == "OFF"
                app.DiodesSwitch.Value = "ON";
            end
        end
        function checkAndSetDiodesOFF(app)
            if app.DiodesSwitch.Value == "ON"
                app.DiodesSwitch.Value = "OFF";
            end
        end
        function setLDDcurrent(app)
            name = app.CurrentLabel.Text;
            mem = app.FPGA.flashMem;
            data = app.CurrentEditField.Value;
            app.FPGA = writeAndReadData(app.FPGA,mem,name,data);
            output = ['Value ',data,' was write to FPGA address ',name];
            addStrOutput(app.callingApp,output);
        end
        function saveMatFile(app)
            laserDiode = app.mainObj;
            laserDiodeName = app.mainObj.tableName;%createFilename(app.mainObj.time,'LD');
            save([app.folder laserDiodeName '.mat'],'laserDiode');
        end
        function deleteMainObj(app)
            app.mainObj = [];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: downloadSampleTableButton
        function downloadSampleTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: WritetoFPGAButton
        function WritetoFPGAButtonPushed(app, event)
            setLDDcurrent(app);
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            checkOffsetHighRange(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.mainObj = setCriterias(app.mainObj);
            saveTables(app.mainObj);
            saveMatFile(app);
            output = 'Files for laser diode was saved';
            addStrOutput(app.callingApp,output);
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Apply';
                    app.SettingsPanel.Enable = 'on';
                    app.CheckOffsetPanel.Enable = 'off';
                    deleteMainObj(app);
                case 1
                    app.ApplyButton.Text = 'Restart';
                    app.SettingsPanel.Enable = 'off';
                    app.CheckOffsetPanel.Enable = 'on';
                    checkFieldsNotEmpty(app);
                    getLDDnumber(app);
                    createMainObj(app);
                    checkAndSetDiodesON(app);
            end
        end

        % Value changed function: DiodesSwitch
        function DiodesSwitchValueChanged(app, event)
            value = app.DiodesSwitch.Value;
            switch value
                case 'ON'
                    turnDiodesON(app);
                case 'OFF'
                    turnDiodesOFF(app);
            end
        end

        % Close request function: TestLaserDiodeUIFigure
        function TestLaserDiodeUIFigureCloseRequest(app, event)
            checkAndSetDiodesOFF(app);
            fig = app.TestLaserDiodeUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            imageview(app.CircuitImage.ImageSource);
        end

        % Button pushed function: SavevaluesButton
        function SavevaluesButtonPushed(app, event)
            DAC = str2double(app.CurrentEditField.Value);
            outputPower = app.OutputpowerEditField.Value;
            app.mainObj = addDACandOutputPower(app.mainObj,DAC,outputPower);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestLaserDiodeUIFigure = uifigure('Visible', 'off');
            app.TestLaserDiodeUIFigure.Name = 'Test LaserDiode';
            app.TestLaserDiodeUIFigure.Icon = 'gu_action.png';
            app.TestLaserDiodeUIFigure.CloseRequestFcn = createCallbackFcn(app, @TestLaserDiodeUIFigureCloseRequest, true);
            app.TestFPGAlaserdiodeLabel = uilabel(app.TestLaserDiodeUIFigure);
            app.TestFPGAlaserdiodeLabel.Text = 'Test FPGA laser diode';
            app.SettingsPanel = uipanel(app.TestLaserDiodeUIFigure);
            app.downloadComponentsTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.downloadSampleTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadSampleTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadSampleTableButtonPushed, true);
            app.downloadSampleTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadSampleTableButton.Text = '';
            app.Label_2 = uilabel(app.SettingsPanel);
            app.Label_2.Text = 'Select sample table for laser diode';
            app.SelectSampleTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectSampleTableTextArea.Value = {'tableLaserDiode.xlsx'};
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.SettingsPanel);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelectComponentsTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectComponentsTableTextArea.Value = {'tableComponent.xlsx'};
            app.InspectornameEditField_2Label = uilabel(app.SettingsPanel);
            app.InspectornameEditField_2Label.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.SettingsPanel, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.SerialnumberEditField_2Label = uilabel(app.SettingsPanel);
            app.SerialnumberEditField_2Label.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.DesignationEditField_2Label = uilabel(app.SettingsPanel);
            app.DesignationEditField_2Label.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.SettingsPanel, 'text');
            app.DesignationEditField.Value = 'PB1';
            app.BoardnumberEditField_2Label = uilabel(app.SettingsPanel);
            app.BoardnumberEditField_2Label.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditField_2Label = uilabel(app.SettingsPanel);
            app.CommentEditField_2Label.Text = 'Comment';
            app.CommentEditField = uieditfield(app.SettingsPanel, 'text');
            app.CommentEditField.Value = 'T = 22 C';
            app.WavelengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.SettingsPanel, 'text');
            app.WavelengthEditField.Value = '980';
            app.TestCircuitPanel = uipanel(app.TestLaserDiodeUIFigure);
            app.WritetoFPGAButton = uibutton(app.TestCircuitPanel, 'push');
            app.WritetoFPGAButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoFPGAButtonPushed, true);
            app.WritetoFPGAButton.Text = 'Write to FPGA';
            app.CurrentEditField = uieditfield(app.TestCircuitPanel, 'text');
            app.SaveButton = uibutton(app.TestCircuitPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'gu_save.png';
            app.SaveButton.Text = '';
            app.dBmLabel = uilabel(app.TestCircuitPanel);
            app.dBmLabel.Text = 'dBm';
            app.SaveButtonLabel = uilabel(app.TestCircuitPanel);
            app.SaveButtonLabel.Text = 'If the test passed, press save button';
            app.Tip2Image = uiimage(app.TestCircuitPanel);
            app.Tip2Image.Tooltip = {'We recommend to check values 1022,1023,1024.'};
            app.Tip2Image.ImageSource = 'gu_help.png';
            app.SavevaluesButton = uibutton(app.TestCircuitPanel, 'push');
            app.SavevaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SavevaluesButtonPushed, true);
            app.SavevaluesButton.Tooltip = {'Save LDD current and output power to sample table'};
            app.SavevaluesButton.Text = 'Save values';
            app.OutputpowerEditFieldLabel = uilabel(app.TestCircuitPanel);
            app.OutputpowerEditFieldLabel.Text = 'Output power';
            app.OutputpowerEditField = uieditfield(app.TestCircuitPanel, 'numeric');
            app.AssembleopticalcircuitLabel = uilabel(app.TestCircuitPanel);
            app.AssembleopticalcircuitLabel.Text = 'Assemble optical circuit at picture, fill in fields';
            app.CircuitImage = uiimage(app.TestCircuitPanel);
            app.CircuitImage.ImageSource = 'FPGA_LaserDiode.png';
            app.ZoomInButton = uibutton(app.TestCircuitPanel, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Icon = 'gu_zoomin.png';
            app.ZoomInButton.Text = '';
            app.CurrentLabel = uilabel(app.TestCircuitPanel);
            app.CurrentLabel.Text = 'LDDN_CURRENT';
            app.CheckOffsetPanel = uipanel(app.TestLaserDiodeUIFigure);
            app.OffsetHighRangeEditField = uieditfield(app.CheckOffsetPanel, 'text');
            app.OffsetHighRangeEditField.Editable = 'off';
            app.CheckButton = uibutton(app.CheckOffsetPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Tip1Image = uiimage(app.CheckOffsetPanel);
            app.Tip1Image.Tooltip = {'If this value 0 you would NOT be allowed to continue. You can change it in Device FPGA window (don''t forget to find right value and write it in EEPROM).'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.OffsetHighRangeLabel = uilabel(app.CheckOffsetPanel);
            app.OffsetHighRangeLabel.Text = 'LDDN_OFFSET_HIGH_RANGE';
            app.DiodesLamp = uilamp(app.TestLaserDiodeUIFigure);
            app.ApplyButton = uibutton(app.TestLaserDiodeUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.DiodesSwitch = uiswitch(app.TestLaserDiodeUIFigure, 'slider');
            app.DiodesSwitch.Items = {'OFF', 'ON'};
            app.DiodesSwitch.ValueChangedFcn = createCallbackFcn(app, @DiodesSwitchValueChanged, true);
            app.DiodesSwitch.Value = 'OFF';
            % Show the figure after all components are created
            app.TestLaserDiodeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_LaserDiode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestLaserDiodeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestLaserDiodeUIFigure)
        end
    end
end