classdef appTestFPGA_LoadDriver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestLoadDriverUIFigure         matlab.ui.Figure
        RuntestButton                  matlab.ui.control.StateButton
        TestLDPanel                    matlab.ui.container.Panel
        Tip4Image                      matlab.ui.control.Image
        VoltageEditField               matlab.ui.control.NumericEditField
        VoltageEditFieldLabel          matlab.ui.control.Label
        LDDcurrentEditField            matlab.ui.control.EditField
        LDDcurrentEditFieldLabel       matlab.ui.control.Label
        mVLabel_2                      matlab.ui.control.Label
        IfthetestpassedpresssavebuttonLabel  matlab.ui.control.Label
        SaveandnextButton              matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.StateButton
        SettingsPanel                  matlab.ui.container.Panel
        SerialnumberEditField          matlab.ui.control.EditField
        SerialnumberEditFieldLabel     matlab.ui.control.Label
        Tip1Image                      matlab.ui.control.Image
        CommentEditField               matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditFieldLabel      matlab.ui.control.Label
        DesignationEditField           matlab.ui.control.EditField
        DesignationEditFieldLabel      matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditFieldLabel    matlab.ui.control.Label
        downloadSampleTableButton      matlab.ui.control.Button
        SelectsampletableforloaddriverTextArea  matlab.ui.control.TextArea
        Label_4                        matlab.ui.control.Label
        SelecttablewithallcomponentsTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        downloadComponentsTableButton  matlab.ui.control.Button
        TestFPGAloaddriverLabel        matlab.ui.control.Label
        FindOffsetPanel                matlab.ui.container.Panel
        OffsetHighRangeEditField       matlab.ui.control.NumericEditField
        Tip3Image                      matlab.ui.control.Image
        WritetoFPGAOffsetButton        matlab.ui.control.Button
        CurrentLabel                   matlab.ui.control.Label
        OffsetHighRangeLabel           matlab.ui.control.Label
        Tip2Image                      matlab.ui.control.Image
        Voltagecurrent1023EditField    matlab.ui.control.NumericEditField
        Voltagecurrent1023EditField_2Label  matlab.ui.control.Label
        ZoomInButton                   matlab.ui.control.Button
        CircuitImage                   matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        mVLabel                        matlab.ui.control.Label
        CurrentEditField               matlab.ui.control.EditField
        WritetoFPGAButton              matlab.ui.control.Button
        DecreaseButton                 matlab.ui.control.Button
        IncreaseButton                 matlab.ui.control.Button
    end

% 
%     properties (Access = public)
%         callingApp
%         folder
%         time
% 
%         dac
%         voltage
% 
%         fpga
%         tableComponent
%         tableLoadDriver
% 
%         LDnumber %string
%         DACnumber %num
%         %loadDriver
%     end
% 
%     methods (Access = private)
%         function dac = readTableForDAC(app)
%             numRows = readtable(app.tableLoadDriver,'Sheet','Sample',...
%                 'ReadVariableNames',false,'ReadRowNames',true);
%             dac = numRows{'Значение на ЦАП',:};
%         end
%         function app = writeDACvalue(app)
%             number = str2double(app.LDnumber);
%             valueNum = app.dac(app.DACnumber);
%             app.fpga = setValueToLddCurrent(app.fpga,number,valueNum);
%             app.LDDcurrentEditField.Value = app.fpga.lastRead;
%             output = ['Номер измерения ',...
%                 num2str(app.DACnumber)];
%             addStrOutput(app.callingApp,output);
%             output = ['Значение ЦАП ',...
%                 num2str(app.dac(app.DACnumber))];
%             addStrOutput(app.callingApp,output);
%         end
%     end
    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        FPGA
        mainObj
    end
    properties (Access = public)
        LDDnumber
    end
    methods (Access = public)
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appTestFPGA_LoadDriver');
            app.SelectComponentsTableTextArea.Value = appTestFPGA_LoadDriver.SelectComponentsTableTextArea;
            app.SelectSampleTableTextArea.Value = appTestFPGA_LoadDriver.SelectSampleTableTextArea;
            app.BoardnumberEditField.Value = appTestFPGA_LoadDriver.BoardnumberEditField;
            app.DesignationEditField.Value = appTestFPGA_LoadDriver.DesignationEditField;
            app.SerialnumberEditField.Value = appTestFPGA_LoadDriver.SerialnumberEditField;
            app.CommentEditField.Value = appTestFPGA_LoadDriver.CommentEditField;
            app.InspectornameEditField.Value = appTestFPGA_LoadDriver.InspectornameEditField;
        end
        function saveFromAppToMemFile(app)
            appTestFPGA_LoadDriver.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
            appTestFPGA_LoadDriver.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
            appTestFPGA_LoadDriver.BoardnumberEditField = app.BoardnumberEditField.Value;
            appTestFPGA_LoadDriver.DesignationEditField = app.DesignationEditField.Value;
            appTestFPGA_LoadDriver.SerialnumberEditField = app.SerialnumberEditField.Value;
            appTestFPGA_LoadDriver.CommentEditField = app.CommentEditField.Value;
            appTestFPGA_LoadDriver.InspectornameEditField = app.InspectornameEditField.Value;
            save('TheGreatUniterMemory.mat','appTestFPGA_LoadDriver','-append');
        end
        function checkFieldsNotEmpty(app)
            fieldsArray = {...
                app.SelectComponentsTableTextArea,...
                app.SelectSampleTableTextArea,...
                app.BoardnumberEditField,...
                app.DesignationEditField,...
                app.SerialnumberEditField,...
                app.CommentEditField,...
                app.InspectornameEditField,...
                };
            for i = 1:length(fieldsArray)
                if iscell(fieldsArray{i}.Value)
                    value = fieldsArray{i}.Value{1};
                else
                    value = fieldsArray{i}.Value;
                end
                if isempty(value)
                    fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
                    output = 'Fields must not be empty';
                    addStrOutput(app.callingApp,output)
                    error(output);
                else
                    fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                end
            end
        end
        function getLDDnumber(app)
            app.LDDnumberStr = app.DesignationEditField.Value(end);
            app.LDDnumberNum = str2double(app.LDDnumberStr);
            app.OffsetHighRangeLabel.Text = ['LDD',app.LDDnumberStr,'_OFFSET_HIGH_RANGE'];
            app.CurrentLabel.Text = ['LDD',app.LDDnumberStr,'_CURRENT'];
        end
        function createMainObj(app)
            appStruct = struct(...
                'time',now, ...
                'folder',app.folder, ...
                'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
                'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
                'boardNumber',app.BoardnumberEditField.Value, ...
                'designation',app.DesignationEditField.Value, ...
                'serialNumber',app.SerialnumberEditField.Value, ...
                'comment',app.CommentEditField.Value, ...
                'inspectorName',app.InspectornameEditField.Value);
            app.mainObj = FPGA_LoadDriver(appStruct);
        end
        function changeOffsetHighRange(app)
            name = app.OffsetHighRangeLabel.Text;
            mem = app.FPGA.flashMem;
            data = num2str(app.OffsetHighRangeEditField.Value);
            app.FPGA = writeAndReadData(app.FPGA,mem,name,data);
            output = ['Value ',data,' was write to FPGA address ',name];
            addStrOutput(app.callingApp,output);
        end
        function writeOffsetToEEPROM(app)
            name = app.OffsetHighRangeLabel.Text;
            mem = app.FPGA.eepromMem;
            data = app.OffsetHighRangeEditField.Value;
            app.mainObj = addOffsetHighRangeFromEEPROM(app.mainObj,data);
            offsetHighRangeStr = num2str(data);
            app.FPGA = writeAndReadData(app.FPGA,mem,name,offsetHighRangeStr);
        end
        function turnDiodesON(app)
            app.FPGA = setDiodes(app.FPGA,'1111');
            output = 'Diodes were turned ON';
            addStrOutput(app.callingApp,output);
        end
        function turnDiodesOFF(app)
            app.FPGA = setDiodes(app.FPGA,'0000');
            output = 'Diodes were turned OFF';
            addStrOutput(app.callingApp,output);
        end
        function checkAndSetDiodesON(app)
            if app.DiodesSwitch.Value == "OFF"
                app.DiodesSwitch.Value = "ON";
            end
        end
        function checkAndSetDiodesOFF(app)
            if app.DiodesSwitch.Value == "ON"
                app.DiodesSwitch.Value = "OFF";
            end
        end
        function setLDDcurrent(app,data)
            name = app.CurrentLabel.Text;
            mem = app.FPGA.flashMem;
            %data = app.CurrentEditField.Value;
            app.FPGA = writeAndReadData(app.FPGA,mem,name,data);
            output = ['Value ',data,' was write to FPGA address ',name];
            addStrOutput(app.callingApp,output);
        end
        function saveMatFile(app)
            loadDriver = app.mainObj;
            loadDriverName = app.mainObj.tableName;%createFilename(app.mainObj.time,'LD');
            save([app.folder loadDriverName '.mat'],'loadDriver');
        end
        function deleteMainObj(app)
            app.mainObj = [];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: downloadSampleTableButton
        function downloadSampleTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: WritetoFPGAButton
        function WritetoFPGAButtonPushed(app, event)
            data = app.CurrentEditField.Value;
            setLDDcurrent(app,data);
        end

        % Button pushed function: SaveandnextButton
        function SaveandnextButtonPushed(app, event)
        voltage_mV = app.VoltageEditField.Value;
        app.mainObj = addVoltageAndCalcCurrent(app.mainObj,voltage_mV);
        if app.mainObj.index == app.mainObj.maxIndex
            app.SaveandnextButton.Enable = 'off';
        else
            DAC = nextDAC(app.mainObj);
            DACstr = num2str(DAC);
            setLDDcurrent(app,DACstr);
            app.LDDcurrentEditField.Value = DACstr;
        end
        if app.mainObj.index == app.mainObj.maxIndex - 1
            app.SaveandnextButton.Text = 'Save';
        end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.mainObj = setCriterias(app.mainObj);
            saveTables(app.mainObj);
            saveMatFile(app);
            output = 'Files for load driver was saved';
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            imageview(app.CircuitImage.ImageSource);
        end

        % Value changed function: RuntestButton
        function RuntestButtonValueChanged(app, event)
            value = app.RuntestButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Run test';
                    app.FindOffsetPanel.Enable = 'on';
                    app.mainObj = clearTest(app.mainObj);
                case 1
                    app.ApplyButton.Text = 'Restart';
                    app.FindOffsetPanel.Enable = 'off';
            end
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Apply';
                    app.SettingsPanel.Enable = 'on';
                    app.FindOffsetPanel.Enable = 'off';
                    app.RuntestButton.Enable = 'off';
                    app.RuntestButton.Value = 0;
                    %app.TestLDPanel.Enable = 'off';
                    deleteMainObj(app);
                case 1
                    checkFieldsNotEmpty(app);
                    createMainObj(app);
                    checkAndSetDiodesON(app);
                    getLDDnumber(app);
                    app.ApplyButton.Text = 'Restart';
                    app.SettingsPanel.Enable = 'off';
                    app.FindOffsetPanel.Enable = 'on';
                    app.RuntestButton.Enable = 'on';
                    app.RuntestButton.Value = 0;
            end
        end

        % Close request function: TestLoadDriverUIFigure
        function TestLoadDriverUIFigureCloseRequest(app, event)
            checkAndSetDiodesOFF(app);
            fig = app.TestLoadDriverUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end

        % Button pushed function: WritetoFPGAOffsetButton
        function WritetoFPGAOffsetButtonPushed(app, event)
            changeOffsetHighRange(app);
        end

        % Value changed function: OffsetHighRangeEditField
        function OffsetHighRangeEditFieldValueChanged(app, event)
            changeOffsetHighRange(app);
        end

        % Button pushed function: DecreaseButton
        function DecreaseButtonPushed(app, event)
            app.OffsetHighRangeEditField.Value = app.OffsetHighRangeEditField.Value - 1;
            changeOffsetHighRange(app);
        end

        % Button pushed function: IncreaseButton
        function IncreaseButtonPushed(app, event)
            app.OffsetHighRangeEditField.Value = app.OffsetHighRangeEditField.Value + 1;
            changeOffsetHighRange(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestLoadDriverUIFigure = uifigure('Visible', 'off');
            app.TestLoadDriverUIFigure.Name = 'MATLAB App';
            app.TestLoadDriverUIFigure.CloseRequestFcn = createCallbackFcn(app, @TestLoadDriverUIFigureCloseRequest, true);
            app.FindOffsetPanel = uipanel(app.TestLoadDriverUIFigure);
            app.FindOffsetPanel.Enable = 'off';
            app.IncreaseButton = uibutton(app.FindOffsetPanel, 'push');
            app.IncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseButtonPushed, true);
            app.IncreaseButton.Text = '+';
            app.DecreaseButton = uibutton(app.FindOffsetPanel, 'push');
            app.DecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseButtonPushed, true);
            app.DecreaseButton.Text = '-';
            app.WritetoFPGAButton = uibutton(app.FindOffsetPanel, 'push');
            app.WritetoFPGAButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoFPGAButtonPushed, true);
            app.WritetoFPGAButton.Text = 'Write to FPGA';
            app.CurrentEditField = uieditfield(app.FindOffsetPanel, 'text');
            app.CurrentEditField.Value = '1023';
            app.mVLabel = uilabel(app.FindOffsetPanel);
            app.mVLabel.Text = 'mV';
            app.AssembleopticalcircuitLabel = uilabel(app.FindOffsetPanel);
            app.AssembleopticalcircuitLabel.Text = 'Assemble circuit, fill in fields';
            app.CircuitImage = uiimage(app.FindOffsetPanel);
            app.CircuitImage.ImageSource = 'FPGA_LoadDriver.png';
            app.ZoomInButton = uibutton(app.FindOffsetPanel, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Icon = 'gu_zoomin.png';
            app.ZoomInButton.Text = '';
            app.Voltagecurrent1023EditField_2Label = uilabel(app.FindOffsetPanel);
            app.Voltagecurrent1023EditField_2Label.Text = 'Voltage (current 1023)';
            app.Voltagecurrent1023EditField = uieditfield(app.FindOffsetPanel, 'numeric');
            app.Tip2Image = uiimage(app.FindOffsetPanel);
            app.Tip2Image.Tooltip = {''; 'Set LDD current 1024. Set different LDD offset until real Voltage become greater than Voltage (current 1023). Run test.'};
            app.Tip2Image.ImageSource = 'gu_help.png';
            app.OffsetHighRangeLabel = uilabel(app.FindOffsetPanel);
            app.OffsetHighRangeLabel.Text = 'LDDN_OFFSET_HIGH_RANGE';
            app.CurrentLabel = uilabel(app.FindOffsetPanel);
            app.CurrentLabel.Text = 'LDDN_CURRENT';
            app.WritetoFPGAOffsetButton = uibutton(app.FindOffsetPanel, 'push');
            app.WritetoFPGAOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoFPGAOffsetButtonPushed, true);
            app.WritetoFPGAOffsetButton.Text = 'Write to FPGA';
            app.Tip3Image = uiimage(app.FindOffsetPanel);
            app.Tip3Image.Tooltip = {'Write value to flash memory only'};
            app.Tip3Image.ImageSource = 'gu_help.png';
            app.OffsetHighRangeEditField = uieditfield(app.FindOffsetPanel, 'numeric');
            app.OffsetHighRangeEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetHighRangeEditFieldValueChanged, true);
            app.TestFPGAloaddriverLabel = uilabel(app.TestLoadDriverUIFigure);
            app.TestFPGAloaddriverLabel.Text = 'Test FPGA load driver';
            app.SettingsPanel = uipanel(app.TestLoadDriverUIFigure);
            app.downloadComponentsTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.SettingsPanel);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelecttablewithallcomponentsTextArea = uitextarea(app.SettingsPanel);
            app.SelecttablewithallcomponentsTextArea.Value = {'tableComponent.xlsx'};
            app.Label_4 = uilabel(app.SettingsPanel);
            app.Label_4.Text = 'Select sample table for load driver';
            app.SelectsampletableforloaddriverTextArea = uitextarea(app.SettingsPanel);
            app.SelectsampletableforloaddriverTextArea.Value = {'tableLoadDriver.xlsx'};
            app.downloadSampleTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadSampleTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadSampleTableButtonPushed, true);
            app.downloadSampleTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadSampleTableButton.Text = '';
            app.InspectornameEditFieldLabel = uilabel(app.SettingsPanel);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.SettingsPanel, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.DesignationEditFieldLabel = uilabel(app.SettingsPanel);
            app.DesignationEditFieldLabel.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.SettingsPanel, 'text');
            app.DesignationEditField.Value = 'Ld1';
            app.BoardnumberEditFieldLabel = uilabel(app.SettingsPanel);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditFieldLabel = uilabel(app.SettingsPanel);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.SettingsPanel, 'text');
            app.CommentEditField.Value = 'до заливки';
            app.Tip1Image = uiimage(app.SettingsPanel);
            app.Tip1Image.Tooltip = {'match diode designation. in the end must be LDD number'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.SerialnumberEditFieldLabel = uilabel(app.SettingsPanel);
            app.SerialnumberEditFieldLabel.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.SerialnumberEditField.Value = '-';
            app.ApplyButton = uibutton(app.TestLoadDriverUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.TestLDPanel = uipanel(app.TestLoadDriverUIFigure);
            app.SaveButton = uibutton(app.TestLDPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'gu_save.png';
            app.SaveButton.Text = '';
            app.SaveandnextButton = uibutton(app.TestLDPanel, 'push');
            app.SaveandnextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandnextButtonPushed, true);
            app.SaveandnextButton.Text = 'Save and next';
            app.IfthetestpassedpresssavebuttonLabel = uilabel(app.TestLDPanel);
            app.IfthetestpassedpresssavebuttonLabel.Text = 'If the test passed, press save button';
            app.mVLabel_2 = uilabel(app.TestLDPanel);
            app.mVLabel_2.Text = 'mV';
            app.LDDcurrentEditFieldLabel = uilabel(app.TestLDPanel);
            app.LDDcurrentEditFieldLabel.Text = 'LDD current';
            app.LDDcurrentEditField = uieditfield(app.TestLDPanel, 'text');
            app.LDDcurrentEditField.Editable = 'off';
            app.VoltageEditFieldLabel = uilabel(app.TestLDPanel);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.TestLDPanel, 'numeric');
            app.Tip4Image = uiimage(app.TestLDPanel);
            app.Tip4Image.Tooltip = {'DAC row from SampleTable'};
            app.Tip4Image.ImageSource = 'gu_help.png';
            app.RuntestButton = uibutton(app.TestLoadDriverUIFigure, 'state');
            app.RuntestButton.ValueChangedFcn = createCallbackFcn(app, @RuntestButtonValueChanged, true);
            app.RuntestButton.Text = 'Run test';
            % Show the figure after all components are created
            app.TestLoadDriverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_LoadDriver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestLoadDriverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestLoadDriverUIFigure)
        end
    end
end