classdef appTestFPGA_Parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestparametersUIFigure         matlab.ui.Figure
        ApplyButton                    matlab.ui.control.StateButton
        SettingsPanel                  matlab.ui.container.Panel
        SelectSampleTableTextArea      matlab.ui.control.TextArea
        Label_3                        matlab.ui.control.Label
        SelectComponentsTableTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        downloadSampleTableButton      matlab.ui.control.Button
        downloadComponentsTableButton  matlab.ui.control.Button
        CommentEditField               matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditFieldLabel      matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditFieldLabel    matlab.ui.control.Label
        TestFPGAparametersLabel        matlab.ui.control.Label
        TestPanel                      matlab.ui.container.Panel
        fillinFieldsLabel              matlab.ui.control.Label
        SaveButtonLabel                matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        TemperatureoutsideEditField    matlab.ui.control.NumericEditField
        TemperatureoutsideLabel        matlab.ui.control.Label
        CurrentEditField               matlab.ui.control.NumericEditField
        CurrentEditFieldLabel_2        matlab.ui.control.Label
        ALabel                         matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        VoltageEditField               matlab.ui.control.NumericEditField
        VoltageLabel                   matlab.ui.control.Label
        doneLabel                      matlab.ui.control.Label
        testboardparamButton           matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        FPGA
        mainObj
    end
    properties (Constant)
        DESIGNATION = 'param'
        SERIAL_NUMBER = 'none'
        MULTIPLE_FOR_CONVERT = 2.5/(2^12)
    end
    methods (Access = public)
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appTestFPGA_Parameter');
            app.SelectComponentsTableTextArea.Value = appTestFPGA_Parameter.SelectComponentsTableTextArea;
            app.SelectSampleTableTextArea.Value = appTestFPGA_Parameter.SelectSampleTableTextArea;
            app.BoardnumberEditField.Value = appTestFPGA_Parameter.BoardnumberEditField;
            app.CommentEditField.Value = appTestFPGA_Parameter.CommentEditField;
            app.InspectornameEditField.Value = appTestFPGA_Parameter.InspectornameEditField;
        end
        function saveFromAppToMemFile(app)
            appTestFPGA_Parameter.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
            appTestFPGA_Parameter.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
            appTestFPGA_Parameter.BoardnumberEditField = app.BoardnumberEditField.Value;
            appTestFPGA_Parameter.CommentEditField = app.CommentEditField.Value;
            appTestFPGA_Parameter.InspectornameEditField = app.InspectornameEditField.Value;
            save('TheGreatUniterMemory.mat','appTestFPGA_Parameter','-append');
        end
        function checkFieldsNotEmpty(app)
            fieldsArray = {...
                app.SelectComponentsTableTextArea,...
                app.SelectSampleTableTextArea,...
                app.BoardnumberEditField,...
                app.CommentEditField,...
                app.InspectornameEditField...
                };
            for i = 1:length(fieldsArray)
                if iscell(fieldsArray{i}.Value)
                    value = fieldsArray{i}.Value{1};
                else
                    value = fieldsArray{i}.Value;
                end
                if isempty(value)
                    fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
                    output = 'Fields must not be empty';
                    addStrOutput(app.callingApp,output)
                    error(output);
                else
                    fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                end
            end
        end
        function createMainObj(app)
            appStruct = struct(...
                'time',now, ...
                'folder',app.folder, ...
                'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
                'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
                'boardNumber',app.BoardnumberEditField.Value, ...
                'designation',app.DESIGNATION, ...
                'serialNumber',app.SERIAL_NUMBER, ...
                'comment',app.CommentEditField.Value, ...
                'inspectorName',app.InspectornameEditField.Value ...
                );
            app.mainObj = FPGA_Parameter(appStruct);
        end
        function saveMatFile(app)
            parameter = app.mainObj;
            attenuatorName = app.mainObj.tableName;
            save([app.folder attenuatorName '.mat'],'parameter');
        end
        function deleteMainObj(app)
            app.mainObj = [];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: downloadSampleTableButton
        function downloadSampleTableButtonPushed(app, event)
            output = selectTable(app.SelectSampleTableTextArea.Value{1});
            app.SelectSampleTableTextArea.Value = output;
        end

        % Button pushed function: testboardparamButton
        function testboardparamButtonPushed(app, event)
            mem = app.FPGA.FLASH_MEM;
            %             app.FPGA = readData(app.FPGA,mem,'TEMP_CS');
            %             tempAndElectricStruct.TEMP_CS = str2double(app.FPGA.lastRead)/10;
            tempAndElectricStruct.tempOut = app.TemperatureoutsideEditField.Value;
            app.FPGA = readData(app.FPGA,mem,'TEMPERATURE_FPGA');
            tempAndElectricStruct.tempFPGA = str2double(app.FPGA.lastRead)/100;
            app.FPGA = readData(app.FPGA,mem,'TEMPERATURE_LD');
            tempAndElectricStruct.tempLD = str2double(app.FPGA.lastRead)/100;
            tempAndElectricStruct.voltage = app.VoltageEditField.Value;
            tempAndElectricStruct.current = app.CurrentEditField.Value;
            criteriaCell = readcell(app.mainObj.tableSamplePath,...
                'Sheet','Criteria',...
                'NumHeaderLines',1);
            nameCell = criteriaCell(:,2);
            minCell = criteriaCell(:,3);
            maxCell = criteriaCell(:,4);
            len = size(criteriaCell,1);
            for i = 1:len
                name = nameCell{i};
                min = minCell{i};
                max = maxCell{i};
                app.FPGA = readData(app.FPGA,mem,name);
                valueHex = app.FPGA.lastRead;
                results{i,1} = valueHex;
                value = app.MULTIPLE_FOR_CONVERT * hex2dec(valueHex);
                results{i,2} = value;
                if min <= value && value <= max
                    results{i,3} = 1;
                else
                    results{i,3} = 0;
                end
                output = [name ' = ' num2str(value) ', ',...
                    num2str(results{i,3})];
                addStrOutput(app.callingApp,output);
            end
            criteriaCellArr = results(:,3);
            criteriaArr = cell2mat(criteriaCellArr);
            if ismember(0,criteriaArr)
                results = [{'нет' '' ''}; results];
            else
                results = [{'да' '' ''}; results];
            end
            params = results(:,1:3);
            app.mainObj = setParameters(app.mainObj,tempAndElectricStruct,params);
            app.doneLabel.Visible = 'on';
            app.SaveButton.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveTables(app.mainObj);
            saveMatFile(app);
            output = 'Files for parameters were saved';
            addStrOutput(app.callingApp,output);
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Apply';
                    app.SettingsPanel.Enable = 'on';
                    app.TestPanel.Enable = 'off';
                    deleteMainObj(app);
                case 1
                    app.ApplyButton.Text = 'Restart';
                    app.SettingsPanel.Enable = 'off';
                    app.TestPanel.Enable = 'on';
                    checkFieldsNotEmpty(app);
                    createMainObj(app);
            end
        end

        % Close request function: TestparametersUIFigure
        function TestparametersUIFigureCloseRequest(app, event)
            fig = app.TestparametersUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestparametersUIFigure = uifigure('Visible', 'off');
            app.TestparametersUIFigure.Name = 'Test parameters';
            app.TestparametersUIFigure.Icon = 'gu_action.png';
            app.TestparametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @TestparametersUIFigureCloseRequest, true);
            app.TestPanel = uipanel(app.TestparametersUIFigure);
            app.TestPanel.Enable = 'off';
            app.SaveButton = uibutton(app.TestPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'gu_save.png';
            app.SaveButton.Text = '';
            app.testboardparamButton = uibutton(app.TestPanel, 'push');
            app.testboardparamButton.ButtonPushedFcn = createCallbackFcn(app, @testboardparamButtonPushed, true);
            app.testboardparamButton.Text = 'test board param';
            app.doneLabel = uilabel(app.TestPanel);
            app.doneLabel.Visible = 'off';
            app.doneLabel.Text = 'done';
            app.VoltageLabel = uilabel(app.TestPanel);
            app.VoltageLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.TestPanel, 'numeric');
            app.VLabel = uilabel(app.TestPanel);
            app.VLabel.Text = 'V';
            app.ALabel = uilabel(app.TestPanel);
            app.ALabel.Text = 'A';
            app.CurrentEditFieldLabel_2 = uilabel(app.TestPanel);
            app.CurrentEditFieldLabel_2.Text = 'Current';
            app.CurrentEditField = uieditfield(app.TestPanel, 'numeric');
            app.TemperatureoutsideLabel = uilabel(app.TestPanel);
            app.TemperatureoutsideLabel.Text = 'Temperature outside';
            app.TemperatureoutsideEditField = uieditfield(app.TestPanel, 'numeric');
            app.CLabel = uilabel(app.TestPanel);
            app.CLabel.Text = '°C';
            app.SaveButtonLabel = uilabel(app.TestPanel);
            app.SaveButtonLabel.Text = 'If the test passed, press save button';
            app.fillinFieldsLabel = uilabel(app.TestPanel);
            app.fillinFieldsLabel.Text = 'Fill in fields below manually and press button to test parameters from table';
            app.TestFPGAparametersLabel = uilabel(app.TestparametersUIFigure);
            app.TestFPGAparametersLabel.Text = 'Test FPGA parameters';
            app.SettingsPanel = uipanel(app.TestparametersUIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.SettingsPanel);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.SettingsPanel, 'text');
            app.BoardnumberEditFieldLabel = uilabel(app.SettingsPanel);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.CommentEditFieldLabel = uilabel(app.SettingsPanel);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.SettingsPanel, 'text');
            app.downloadComponentsTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.downloadSampleTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadSampleTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadSampleTableButtonPushed, true);
            app.downloadSampleTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadSampleTableButton.Text = '';
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.SettingsPanel);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelectComponentsTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectComponentsTableTextArea.Value = {'tableComponent.xlsx'};
            app.Label_3 = uilabel(app.SettingsPanel);
            app.Label_3.Text = 'Select sample table for photodiode';
            app.SelectSampleTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectSampleTableTextArea.Value = {'tableOtherParameter.xlsx'};
            app.ApplyButton = uibutton(app.TestparametersUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.TestparametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_Parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestparametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestparametersUIFigure)
        end
    end
end