classdef appTestFPGA_Photodiode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestphotodiodesUIFigure        matlab.ui.Figure
        ApplyButton                    matlab.ui.control.StateButton
        SettingsPanel                  matlab.ui.container.Panel
        TestmodeDropDown               matlab.ui.control.DropDown
        TestmodeDropDownLabel          matlab.ui.control.Label
        SelectSampleTableTextArea      matlab.ui.control.TextArea
        Label_4                        matlab.ui.control.Label
        SelectComponentsTableTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        downloadSampleTableButton      matlab.ui.control.Button
        downloadComponentsTableButton  matlab.ui.control.Button
        CommentEditField               matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditFieldLabel      matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditFieldLabel    matlab.ui.control.Label
        TestphotodiodesLabel           matlab.ui.control.Label
        FunctionPanel                  matlab.ui.container.Panel
        Tip1Image_5                    matlab.ui.control.Image
        WriteoffsetstoEEPROMCheckBox   matlab.ui.control.CheckBox
        Tip1Image_4                    matlab.ui.control.Image
        Tip1Image_3                    matlab.ui.control.Image
        SaveButtonLabel                matlab.ui.control.Label
        Tip1Image_2                    matlab.ui.control.Image
        Tip1Image                      matlab.ui.control.Image
        ZoomInButton                   matlab.ui.control.Button
        CircuitImage                   matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        dBmLabel_2                     matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        Power1550EditField             matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        Power980EditField              matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        NumberformeanEditField         matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        SourcesEditField               matlab.ui.control.EditField
        OpticalsoursesstateLabel       matlab.ui.control.Label
        runButton                      matlab.ui.control.Button
    end


    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        OPM_980
        OPM_1550
        FPGA
        mainObjects
        tableArray
    end
    properties (Constant)
        TestmodeValueAutoRubin = 'auto (rubin)'
        TestmodeValueManual = 'manual'
    end
    methods (Access = public)
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appTestFPGA_Photodiode');
            app.SelectComponentsTableTextArea.Value = appTestFPGA_Photodiode.SelectComponentsTableTextArea;
            app.SelectSampleTableTextArea.Value = appTestFPGA_Photodiode.SelectSampleTableTextArea;
            app.BoardnumberEditField.Value = appTestFPGA_Photodiode.BoardnumberEditField;
            app.CommentEditField.Value = appTestFPGA_Photodiode.CommentEditField;
            app.InspectornameEditField.Value = appTestFPGA_Photodiode.InspectornameEditField;
            app.TestmodeDropDown.Value = appTestFPGA_Photodiode.TestmodeDropDown;
        end
        function saveFromAppToMemFile(app)
            appTestFPGA_Photodiode.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
            appTestFPGA_Photodiode.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
            appTestFPGA_Photodiode.BoardnumberEditField = app.BoardnumberEditField.Value;
            appTestFPGA_Photodiode.CommentEditField = app.CommentEditField.Value;
            appTestFPGA_Photodiode.InspectornameEditField = app.InspectornameEditField.Value;
            appTestFPGA_Photodiode.TestmodeDropDown = app.TestmodeDropDown.Value;
            save('TheGreatUniterMemory.mat','appTestFPGA_Photodiode','-append');
        end
        function checkFieldsNotEmpty(app)
            fieldsArray = {...
                app.SelectComponentsTableTextArea,...
                app.SelectSampleTableTextArea,...
                app.BoardnumberEditField,...
                app.CommentEditField,...
                app.InspectornameEditField...
                };
            for i = 1:length(fieldsArray)
                if iscell(fieldsArray{i}.Value)
                    value = fieldsArray{i}.Value{1};
                else
                    value = fieldsArray{i}.Value;
                end
                if isempty(value)
                    fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
                    output = 'Fields must not be empty';
                    addStrOutput(app.callingApp,output)
                    error(output);
                else
                    fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                end
            end
        end
%         function checkTestMode(app)
%             if app.TestmodeDropDown.Value == app.TestmodeValueAutoRubin
%                 
%             elseif app.TestmodeDropDown.Value == app.TestmodeValueManual
%                 
%             end
%         end
        function createMainObjects(app)
            app.tableArray = readtable(app.SelectSampleTableTextArea.Value{1},'Sheet','Array');
            app.mainObjects = cell(1,height(app.tableArray));
            for numberVD = 1:height(app.tableArray)
                infoString = app.tableArray(numberVD,:);
                appStruct = struct(...
                    'time',now, ...
                    'folder',app.folder, ...
                    'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
                    'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
                    'boardNumber',app.BoardnumberEditField.Value, ...
                    'designation',infoString{1,'Designition'},...
                    'serialNumber',infoString{1,'SerialNumber'},...
                    'comment',app.CommentEditField.Value, ...
                    'inspectorName',app.InspectornameEditField.Value, ...
                    'wavelength',infoString{1,'OperatingWavelength'},...
                    'fpgaName',infoString{1,'FPGAAddress'},...
                    'loss',infoString{1,'Loss'},...
                    'resistor',infoString{1,'Resistor'}...
                    );
                app.mainObjects{numberVD} = FPGA_Photodiode(appStruct);
            end           
        end
        function saveMatFiles(app)
            for numberVD = 1:height(app.tableArray)
                photodiode = app.mainObjects{numberVD};
                photodiodeName = photodiode.tableName;%createFilename(app.mainObj.time,'AT');
                save([app.folder photodiodeName '.mat'],'photodiode');
            end
        end
        function deleteMainObjects(app)
            app.mainObjects = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            app.TestmodeDropDown.Items = {app.TestmodeValueAutoRubin,...
                app.TestmodeValueManual};
            app.TestmodeDropDown.Value = app.TestmodeValueAutoRubin;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
                if isvalid(MainApp.AddDevice_OPMrubin)
                    if ~isempty(MainApp.AddDevice_OPMrubin)
                        app.OPM_980 = mainApp.struct_OPMrubin.Power_980;
                        app.OPM_1550 = mainApp.struct_OPMrubin.Power_1550;
                    end
                end
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: downloadSampleTableButton
        function downloadSampleTableButtonPushed(app, event)
            output = selectTable(app.SelectSampleTableTextArea.Value{1});
            app.SelectSampleTableTextArea.Value = output;
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            cycles = height(app.tableArray);
            repeats = str2double(app.NumberformeanEditField.Value);
            powerFPGA = zeros(cycles,repeats);
            power_980 = zeros(1,repeats);
            power_1550 = zeros(1,repeats);

            for i = 1:repeats
                if app.TestmodeDropDown.Value == app.TestmodeValueAutoRubin
                    app.OPM_980 = requestPower(app.OPM_980);
                    power_980(i) = app.OPM_980.powerdBm(end);
                    app.Power980EditField.Value = app.power980(i);
                    app.OPM_1550 = requestPower(app.OPM_1550);
                    power_1550(i) = app.OPM_1550.powerdBm(end);
                    app.Power1550EditField.Value = app.power1550(i);
                elseif app.TestmodeDropDown.Value == app.TestmodeValueManual
                    power_980(i) = app.Power980EditField.Value;
                    power_1550(i) = app.Power1550EditField.Value;
                end
                for numberVD = 1:cycles
                    mainObj = app.mainObjects{numberVD};
                    mem = app.FPGA.FLASH_MEM;
                    app.FPGA = readData(app.FPGA,mem,mainObj.fpgaName);
                    powerFPGA(numberVD,i) = str2double(app.FPGA.lastRead);
                end
            end
            meanPower980 = mean(power980);
            meanPower1550 = mean(power1550);
            for numberVD = 1:height(app.tableArray)
                mainObj = app.mainObjects{numberVD};
                if mainObj.wavelength == 980
                    fullPow = meanPower980;
                elseif mainObj.wavelength == 1550
                    fullPow = meanPower1550;
                end
                newMeasurement.meanPowerOPM = fullPow - mainObj.loss;
                newMeasurement.sourcesState = app.SourcesEditField.Value;
                newMeasurement.meanPowerFPGA = mean(powerFPGA(numberVD,:));
                photodiode = app.mainObjects{numberVD};
                app.mainObjects{numberVD} = addMeasurement(photodiode,newMeasurement);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            for numberVD = 1:height(app.tableArray)
                photodiode = app.mainObjects{numberVD};
                saveTable(photodiode);
            end
            saveMatFiles(app);
            output = 'Files for photodiodes were saved';
            addStrOutput(app.callingApp,output);
        end

        % Close request function: TestphotodiodesUIFigure
        function TestphotodiodesUIFigureCloseRequest(app, event)
            fig = app.TestphotodiodesUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Apply';
                    app.SettingsPanel.Enable = 'on';
                    app.FunctionPanel.Enable = 'off';
                    deleteMainObjects(app);
                case 1
                    app.ApplyButton.Text = 'Restart';
                    app.SettingsPanel.Enable = 'off';
                    app.FunctionPanel.Enable = 'on';
                    checkFieldsNotEmpty(app);
                    %checkTestMode(app);
                    createMainObjects(app);
            end
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            imageview(app.CircuitImage.ImageSource);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestphotodiodesUIFigure = uifigure('Visible', 'off');
            app.TestphotodiodesUIFigure.Name = 'Test photodiodes';
            app.TestphotodiodesUIFigure.Icon = 'gu_action.png';
            app.TestphotodiodesUIFigure.CloseRequestFcn = createCallbackFcn(app, @TestphotodiodesUIFigureCloseRequest, true);
            app.FunctionPanel = uipanel(app.TestphotodiodesUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.runButton = uibutton(app.FunctionPanel, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.OpticalsoursesstateLabel = uilabel(app.FunctionPanel);
            app.OpticalsoursesstateLabel.Text = 'Optical sourses state';
            app.SourcesEditField = uieditfield(app.FunctionPanel, 'text');
            app.SourcesEditField.Value = 'off/on';
            app.SaveButton = uibutton(app.FunctionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'gu_save.png';
            app.SaveButton.Text = '';
            app.Label_3 = uilabel(app.FunctionPanel);
            app.Label_3.Text = 'Number of measurements';
            app.NumberformeanEditField = uieditfield(app.FunctionPanel, 'text');
            app.NumberformeanEditField.Value = '5';
            app.Label_6 = uilabel(app.FunctionPanel);
            app.Label_6.Text = 'Power (980 nm)';
            app.Power980EditField = uieditfield(app.FunctionPanel, 'numeric');
            app.Label_7 = uilabel(app.FunctionPanel);
            app.Label_7.Text = 'Power (1550 nm)';
            app.Power1550EditField = uieditfield(app.FunctionPanel, 'numeric');
            app.dBmLabel = uilabel(app.FunctionPanel);
            app.dBmLabel.Text = 'dBm';
            app.dBmLabel_2 = uilabel(app.FunctionPanel);
            app.dBmLabel_2.Text = 'dBm';
            app.AssembleopticalcircuitLabel = uilabel(app.FunctionPanel);
            app.AssembleopticalcircuitLabel.Text = 'Assemble optical circuit, fill in fields.';
            app.CircuitImage = uiimage(app.FunctionPanel);
            app.CircuitImage.ImageSource = 'FPGA_Photodiodes.png';
            app.ZoomInButton = uibutton(app.FunctionPanel, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Icon = 'gu_zoomin.png';
            app.ZoomInButton.Text = '';
            app.Tip1Image = uiimage(app.FunctionPanel);
            app.Tip1Image.Tooltip = {'Number of measurements to calculate the average '};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.Tip1Image_2 = uiimage(app.FunctionPanel);
            app.Tip1Image_2.Tooltip = {'Make at least 2 measurements.'};
            app.Tip1Image_2.ImageSource = 'gu_help.png';
            app.SaveButtonLabel = uilabel(app.FunctionPanel);
            app.SaveButtonLabel.Text = 'If the test passed, press save button';
            app.Tip1Image_3 = uiimage(app.FunctionPanel);
            app.Tip1Image_3.Tooltip = {'It''s recommended to start with sourses OFF'};
            app.Tip1Image_3.ImageSource = 'gu_help.png';
            app.Tip1Image_4 = uiimage(app.FunctionPanel);
            app.Tip1Image_4.Tooltip = {'If there is no power fill in -60 dBm'};
            app.Tip1Image_4.ImageSource = 'gu_help.png';
            app.WriteoffsetstoEEPROMCheckBox = uicheckbox(app.FunctionPanel);
            app.WriteoffsetstoEEPROMCheckBox.Text = 'Write offsets to EEPROM';
            app.Tip1Image_5 = uiimage(app.FunctionPanel);
            app.Tip1Image_5.Tooltip = {'This function will find minimum, multiplied it by minus 1 and write values to EEPROM'};
            app.Tip1Image_5.ImageSource = 'gu_help.png';
            app.TestphotodiodesLabel = uilabel(app.TestphotodiodesUIFigure);
            app.TestphotodiodesLabel.Text = 'Test photodiodes';
            app.SettingsPanel = uipanel(app.TestphotodiodesUIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.SettingsPanel);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.SettingsPanel, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.BoardnumberEditFieldLabel = uilabel(app.SettingsPanel);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditFieldLabel = uilabel(app.SettingsPanel);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.SettingsPanel, 'text');
            app.CommentEditField.Value = 'T 22 C';
            app.downloadComponentsTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.downloadSampleTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadSampleTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadSampleTableButtonPushed, true);
            app.downloadSampleTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadSampleTableButton.Text = '';
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.SettingsPanel);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelectComponentsTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectComponentsTableTextArea.Value = {'tableComponent.xlsx'};
            app.Label_4 = uilabel(app.SettingsPanel);
            app.Label_4.Text = 'Select sample table for photodiode';
            app.SelectSampleTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectSampleTableTextArea.Value = {'tablePhotodiode.xlsx'};
            app.TestmodeDropDownLabel = uilabel(app.SettingsPanel);
            app.TestmodeDropDownLabel.Text = 'Test mode';
            app.TestmodeDropDown = uidropdown(app.SettingsPanel);
            app.TestmodeDropDown.Items = {'manual', 'auto (rubin)'};
            app.TestmodeDropDown.Value = 'auto (rubin)';
            app.ApplyButton = uibutton(app.TestphotodiodesUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.TestphotodiodesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_Photodiode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestphotodiodesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestphotodiodesUIFigure)
        end
    end
end