classdef appTestFPGA_Transceiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TesttransceiverUIFigure        matlab.ui.Figure
        ApplyButton                    matlab.ui.control.StateButton
        SettingsPanel                  matlab.ui.container.Panel
        SerialnumberEditField          matlab.ui.control.EditField
        SerialnumberEditFieldLabel     matlab.ui.control.Label
        DesignationEditField           matlab.ui.control.EditField
        DesignationEditFieldLabel      matlab.ui.control.Label
        SelectSampleTableTextArea      matlab.ui.control.TextArea
        Label_3                        matlab.ui.control.Label
        SelectComponentsTableTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        CommentEditField               matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        WavelengthEditField            matlab.ui.control.EditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        downloadSampleTableButton      matlab.ui.control.Button
        downloadComponentsTableButton  matlab.ui.control.Button
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditFieldLabel      matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditFieldLabel    matlab.ui.control.Label
        TestFPGAtransceiverLabel       matlab.ui.control.Label
        FunctionPanel                  matlab.ui.container.Panel
        Tip1Image_2                    matlab.ui.control.Image
        Tip1Image                      matlab.ui.control.Image
        AssembleopticalcircuitLabel_2  matlab.ui.control.Label
        SaveButtonLabel                matlab.ui.control.Label
        ZoomInButton                   matlab.ui.control.Button
        CircuitImage                   matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        statusBERLabel                 matlab.ui.control.Label
        startcheckinglinkButton        matlab.ui.control.StateButton
        TimefortestEditField           matlab.ui.control.NumericEditField
        TimefortestEditFieldLabel      matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        testBERButton                  matlab.ui.control.Button
        Inputpowerlink01EditField      matlab.ui.control.NumericEditField
        Inputpowerlink01Label          matlab.ui.control.Label
        Inputpowerlink10EditField      matlab.ui.control.NumericEditField
        Inputpowerlink10Label          matlab.ui.control.Label
        PeriodEditField                matlab.ui.control.NumericEditField
        PeriodEditFieldLabel           matlab.ui.control.Label
        dBmLabel_4                     matlab.ui.control.Label
        dBmLabel_3                     matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        checklinkonceButton            matlab.ui.control.Button
        LinkLabel                      matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        SaveButton                     matlab.ui.control.Button
        OutputpowerEditField           matlab.ui.control.NumericEditField
        OutputpowerEditFieldLabel      matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
    end


    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        FPGA
        mainObj
        transceiverNumberStr
        transceiverNumberNum
        currentLink 
    end
    methods (Access = public)
        function getTransceiverNumber(app)
            app.transceiverNumberStr = app.DesignationEditField.Value(end) - 4; %%%
            app.transceiverNumberNum = str2double(app.transceiverNumberStr);
            output = ['Transceiver number is ', app.transceiverNumberStr];
            addStrOutput(app.callingApp,output);
        end
        function enableOneTransceiver(app)
            binInput = '0000';
            binInput(app.transceiverNumberNum) = '1';
            app.FPGA = setTransceiversEn(app.FPGA,binInput);
        end
        function disableAllTransceivers(app)
            binInput = '0000';
            app.FPGA = setTransceiversEn(app.FPGA,binInput);
        end
        function checkLinkFPGA(app)
            app.FPGA = checkLink(app.FPGA);
            currentLinks = app.FPGA.currentLinks;
            app.currentLink = currentLinks(app.transceiverNumberNum) == '1';
        end
        function showLink(app)
            if app.currentLink
                app.Lamp.Color = [1.00,0.00,0.00];
                addStrOutput(app.callingApp,'LINK 1');
            else
                app.Lamp.Color = [1,1,1];
                addStrOutput(app.callingApp,'LINK 0');
            end
        end
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appTestFPGA_Transceiver');
            app.SelectComponentsTableTextArea.Value = appTestFPGA_Transceiver.SelectComponentsTableTextArea;
            app.SelectSampleTableTextArea.Value = appTestFPGA_Transceiver.SelectSampleTableTextArea;
            app.BoardnumberEditField.Value = appTestFPGA_Transceiver.BoardnumberEditField;
            app.DesignationEditField.Value = appTestFPGA_Transceiver.DesignationEditField;
            app.SerialnumberEditField.Value = appTestFPGA_Transceiver.SerialnumberEditField;
            app.CommentEditField.Value = appTestFPGA_Transceiver.CommentEditField;
            app.InspectornameEditField.Value = appTestFPGA_Transceiver.InspectornameEditField;
            app.WavelengthEditField.Value = appTestFPGA_Transceiver.WavelengthEditField;
        end
        function saveFromAppToMemFile(app)
            appTestFPGA_Transceiver.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
            appTestFPGA_Transceiver.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
            appTestFPGA_Transceiver.BoardnumberEditField = app.BoardnumberEditField.Value;
            appTestFPGA_Transceiver.DesignationEditField = app.DesignationEditField.Value;
            appTestFPGA_Transceiver.SerialnumberEditField = app.SerialnumberEditField.Value;
            appTestFPGA_Transceiver.CommentEditField = app.CommentEditField.Value;
            appTestFPGA_Transceiver.InspectornameEditField = app.InspectornameEditField.Value;
            appTestFPGA_Transceiver.WavelengthEditField = app.WavelengthEditField.Value;
            save('TheGreatUniterMemory.mat','appTestFPGA_Transceiver','-append');
        end
        function checkFieldsNotEmpty(app)
            fieldsArray = {...
                app.SelectComponentsTableTextArea,...
                app.SelectSampleTableTextArea,...
                app.BoardnumberEditField,...
                app.DesignationEditField,...
                app.SerialnumberEditField,...
                app.CommentEditField,...
                app.InspectornameEditField,...
                app.WavelengthEditField...
                };
            for i = 1:length(fieldsArray)
                if iscell(fieldsArray{i}.Value)
                    value = fieldsArray{i}.Value{1};
                else
                    value = fieldsArray{i}.Value;
                end
                if isempty(value)
                    fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
                    output = 'Fields must not be empty';
                    addStrOutput(app.callingApp,output)
                    error(output);
                else
                    fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                end
            end
        end
        function createMainObj(app)
            appStruct = struct(...
                'time',now, ...
                'folder',app.folder, ...
                'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
                'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
                'boardNumber',app.BoardnumberEditField.Value, ...
                'designation',app.DesignationEditField.Value, ...
                'serialNumber',app.SerialnumberEditField.Value, ...
                'comment',app.CommentEditField.Value, ...
                'inspectorName',app.InspectornameEditField.Value, ...
                'wavelength',app.WavelengthEditField.Value ...
                );
            app.mainObj = FPGA_Transceiver(appStruct);
        end
        function saveMatFile(app)
            transceiver = app.mainObj;
            transceiverName = app.mainObj.tableName;%createFilename(app.mainObj.time,'AT');
            save([app.folder transceiverName '.mat'],'transceiver');
        end
        function deleteMainObj(app)
            app.mainObj = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            output = selectTable(app.SelectComponentsTableTextArea.Value{1});
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: downloadSampleTableButton
        function downloadSampleTableButtonPushed(app, event)
            output = selectTable(app.SelectSampleTableTextArea.Value{1});
            app.SelectSampleTableTextArea.Value = output;
        end

        % Button pushed function: checklinkonceButton
        function checklinkonceButtonPushed(app, event)
            checkLinkFPGA(app);
            showLink(app);
        end

        % Value changed function: startcheckinglinkButton
        function startcheckinglinkButtonValueChanged(app, event)
%             value = app.startcheckinglinkButton.Value;
%             while value
%                 app.link = checkForLink(app.fpga,app.transNumber);
%                 app = showLink(app);
%                 pause(app.PeriodEditField.Value);
%                 app.startcheckinglinkButton.Text = 'checking link...';
%             end
%             if ~value
%                 app.startcheckinglinkButton.Text = 'start checking link';
%             end
        end

        % Button pushed function: testBERButton
        function testBERButtonPushed(app, event)
%             app.statusBERLabel.Visible = 'on';
%             app.statusBERLabel.Text = 'process...';
% 
%             resultStruct = checkBERT(app.fpga,app.transNumber);
% 
%             app.ber = resultStruct.BERT_BER;
%             addStrOutput(app.callingApp,['BER = ',...
%                 num2strForPrint(app.ber)]);
%             app.link = resultStruct.BERT_LOCKED;
% 
%             app.statusBERLabel.Text = 'done';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            powerStruct.outputPower = app.OutputpowerEditField.Value;
            powerStruct.risingPower = app.Inputpowerlink01EditField.Value;
            powerStruct.fallingPower = app.Inputpowerlink10EditField.Value;
            app.mainObj = setPowers(app.mainObj, powerStruct);
            saveTables(app.mainObj);
            saveMatFile(app);
            output = 'Files for transceiver was saved';
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            imageview(app.CircuitOneImage.ImageSource);
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Apply';
                    app.SettingsPanel.Enable = 'on';
                    app.FunctionPanel.Enable = 'off';
                    disableAllTransceivers(app);
                    deleteMainObj(app);
                case 1
                    app.ApplyButton.Text = 'Restart';
                    app.SettingsPanel.Enable = 'off';
                    app.FunctionPanel.Enable = 'on';
                    checkFieldsNotEmpty(app);
                    getTransceiverNumber(app);
                    enableOneTransceiver(app);
                    createMainObj(app);
            end
        end

        % Close request function: TesttransceiverUIFigure
        function TesttransceiverUIFigureCloseRequest(app, event)
            disableAllTransceivers(app);
            fig = app.TesttransceiverUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TesttransceiverUIFigure = uifigure('Visible', 'off');
            app.TesttransceiverUIFigure.Name = 'Test transceiver';
            app.TesttransceiverUIFigure.Icon = 'gu_action.png';
            app.TesttransceiverUIFigure.CloseRequestFcn = createCallbackFcn(app, @TesttransceiverUIFigureCloseRequest, true);
            app.FunctionPanel = uipanel(app.TesttransceiverUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.dBmLabel = uilabel(app.FunctionPanel);
            app.dBmLabel.Text = 'dBm';
            app.OutputpowerEditFieldLabel = uilabel(app.FunctionPanel);
            app.OutputpowerEditFieldLabel.Text = 'Output power';
            app.OutputpowerEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.SaveButton = uibutton(app.FunctionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'gu_save.png';
            app.SaveButton.Text = '';
            app.Lamp = uilamp(app.FunctionPanel);
            app.LinkLabel = uilabel(app.FunctionPanel);
            app.LinkLabel.Text = 'Link';
            app.checklinkonceButton = uibutton(app.FunctionPanel, 'push');
            app.checklinkonceButton.ButtonPushedFcn = createCallbackFcn(app, @checklinkonceButtonPushed, true);
            app.checklinkonceButton.Text = 'check link once';
            app.sLabel = uilabel(app.FunctionPanel);
            app.sLabel.Text = 's';
            app.dBmLabel_3 = uilabel(app.FunctionPanel);
            app.dBmLabel_3.Text = 'dBm';
            app.dBmLabel_4 = uilabel(app.FunctionPanel);
            app.dBmLabel_4.Text = 'dBm';
            app.PeriodEditFieldLabel = uilabel(app.FunctionPanel);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.PeriodEditField.Value = 1;
            app.Inputpowerlink10Label = uilabel(app.FunctionPanel);
            app.Inputpowerlink10Label.Text = 'Input power (link 1->0)';
            app.Inputpowerlink10EditField = uieditfield(app.FunctionPanel, 'numeric');
            app.Inputpowerlink01Label = uilabel(app.FunctionPanel);
            app.Inputpowerlink01Label.Text = 'Input power (link 0->1)';
            app.Inputpowerlink01EditField = uieditfield(app.FunctionPanel, 'numeric');
            app.testBERButton = uibutton(app.FunctionPanel, 'push');
            app.testBERButton.ButtonPushedFcn = createCallbackFcn(app, @testBERButtonPushed, true);
            app.testBERButton.Visible = 'off';
            app.testBERButton.Text = 'test BER';
            app.sLabel_2 = uilabel(app.FunctionPanel);
            app.sLabel_2.Visible = 'off';
            app.sLabel_2.Text = 's';
            app.TimefortestEditFieldLabel = uilabel(app.FunctionPanel);
            app.TimefortestEditFieldLabel.Visible = 'off';
            app.TimefortestEditFieldLabel.Text = 'Time for test';
            app.TimefortestEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.TimefortestEditField.Visible = 'off';
            app.TimefortestEditField.Value = 60;
            app.startcheckinglinkButton = uibutton(app.FunctionPanel, 'state');
            app.startcheckinglinkButton.ValueChangedFcn = createCallbackFcn(app, @startcheckinglinkButtonValueChanged, true);
            app.startcheckinglinkButton.Enable = 'off';
            app.startcheckinglinkButton.Text = 'start checking link';
            app.statusBERLabel = uilabel(app.FunctionPanel);
            app.statusBERLabel.Visible = 'off';
            app.statusBERLabel.Text = 'done';
            app.AssembleopticalcircuitLabel = uilabel(app.FunctionPanel);
            app.AssembleopticalcircuitLabel.Text = {'Assemble optical circuit at picture 1, fill in output power.'; 'Assemble optical circuit at picture 2'};
            app.CircuitImage = uiimage(app.FunctionPanel);
            app.CircuitImage.ImageSource = 'FPGA_Transceiver.png';
            app.ZoomInButton = uibutton(app.FunctionPanel, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Icon = 'gu_zoomin.png';
            app.ZoomInButton.Text = '';
            app.SaveButtonLabel = uilabel(app.FunctionPanel);
            app.SaveButtonLabel.Text = 'If the test passed, press save button';
            app.AssembleopticalcircuitLabel_2 = uilabel(app.FunctionPanel);
            app.AssembleopticalcircuitLabel_2.Visible = 'off';
            app.AssembleopticalcircuitLabel_2.Text = 'Set total attenuation minus 36 dB, test BER';
            app.Tip1Image = uiimage(app.FunctionPanel);
            app.Tip1Image.Tooltip = {'Increase attenuation and check link at every step.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.Tip1Image_2 = uiimage(app.FunctionPanel);
            app.Tip1Image_2.Tooltip = {'Decrease attenuation and check link at every step.'};
            app.Tip1Image_2.ImageSource = 'gu_help.png';
            app.TestFPGAtransceiverLabel = uilabel(app.TesttransceiverUIFigure);
            app.TestFPGAtransceiverLabel.Text = 'Test FPGA transceiver';
            app.SettingsPanel = uipanel(app.TesttransceiverUIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.SettingsPanel);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.SettingsPanel, 'text');
            app.BoardnumberEditFieldLabel = uilabel(app.SettingsPanel);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.downloadComponentsTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.downloadSampleTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadSampleTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadSampleTableButtonPushed, true);
            app.downloadSampleTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadSampleTableButton.Text = '';
            app.WavelengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.SettingsPanel, 'text');
            app.CommentEditFieldLabel = uilabel(app.SettingsPanel);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.SettingsPanel, 'text');
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.SettingsPanel);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelectComponentsTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectComponentsTableTextArea.Value = {'tableComponent.xlsx'};
            app.Label_3 = uilabel(app.SettingsPanel);
            app.Label_3.Text = 'Select sample table for transceiver';
            app.SelectSampleTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectSampleTableTextArea.Value = {'tableTransceiver.xlsx'};
            app.DesignationEditFieldLabel = uilabel(app.SettingsPanel);
            app.DesignationEditFieldLabel.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.SettingsPanel, 'text');
            app.SerialnumberEditFieldLabel = uilabel(app.SettingsPanel);
            app.SerialnumberEditFieldLabel.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.ApplyButton = uibutton(app.TesttransceiverUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.TesttransceiverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_Transceiver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TesttransceiverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TesttransceiverUIFigure)
        end
    end
end